classdef Licenta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CombustionDARTUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IntroduceredatemotorTab         matlab.ui.container.Tab
        AlegererapidaDropDown           matlab.ui.control.DropDown
        AlegererapidaDropDownLabel      matlab.ui.control.Label
        DateprocesinjectiePanel         matlab.ui.container.Panel
        ParamcorectatLabel              matlab.ui.control.Label
        EditField_28                    matlab.ui.control.NumericEditField
        PasulRACLabel                   matlab.ui.control.Label
        PinjbarLabel                    matlab.ui.control.Label
        CoefdebitLabel                  matlab.ui.control.Label
        DiamorificiummLabel             matlab.ui.control.Label
        NrorificiiLabel                 matlab.ui.control.Label
        EditField_27                    matlab.ui.control.NumericEditField
        EditField_26                    matlab.ui.control.NumericEditField
        EditField_25                    matlab.ui.control.NumericEditField
        EditField_24                    matlab.ui.control.NumericEditField
        EditField_23                    matlab.ui.control.NumericEditField
        InjectiaprincipalaPanel_2       matlab.ui.container.Panel
        EditField_32                    matlab.ui.control.NumericEditField
        EditField_31                    matlab.ui.control.NumericEditField
        EditField_30                    matlab.ui.control.NumericEditField
        DurpanteiRACLabel               matlab.ui.control.Label
        DurrampeiRACLabel               matlab.ui.control.Label
        DurataRACLabel                  matlab.ui.control.Label
        InceputulinjRACLabel            matlab.ui.control.Label
        MasainjmgLabel                  matlab.ui.control.Label
        EditField_29                    matlab.ui.control.NumericEditField
        DcmEditField_3                  matlab.ui.control.NumericEditField
        AltedateutilizateincalculePanel  matlab.ui.container.Panel
        CifraSwirlLabel                 matlab.ui.control.Label
        RJkgKLabel                      matlab.ui.control.Label
        EditField_21                    matlab.ui.control.NumericEditField
        EditField_20                    matlab.ui.control.NumericEditField
        DatefluidmotorproaspatPanel     matlab.ui.container.Panel
        PgalerieevacPaLabel             matlab.ui.control.Label
        EditField_113                   matlab.ui.control.NumericEditField
        AfrLabel                        matlab.ui.control.Label
        EditField_109                   matlab.ui.control.NumericEditField
        uLabel_2                        matlab.ui.control.Label
        EditField_22                    matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.NumericEditField
        EditField_12                    matlab.ui.control.NumericEditField
        PaeratmosfericPaLabel           matlab.ui.control.Label
        TaerKLabel                      matlab.ui.control.Label
        HikJkgLabel                     matlab.ui.control.Label
        combkgm3Label                   matlab.ui.control.Label
        EditField_11                    matlab.ui.control.NumericEditField
        DcmEditField_2                  matlab.ui.control.NumericEditField
        Label_10                        matlab.ui.control.Label
        DateregimfunctionaremotorPanel  matlab.ui.container.Panel
        CoefsarcinaLabel                matlab.ui.control.Label
        EditField_114                   matlab.ui.control.NumericEditField
        EditField_10                    matlab.ui.control.NumericEditField
        ISERACLabel                     matlab.ui.control.Label
        EditField2                      matlab.ui.control.NumericEditField
        DSARACLabel                     matlab.ui.control.Label
        EditField_112                   matlab.ui.control.NumericEditField
        DSERACLabel                     matlab.ui.control.Label
        ISARACLabel                     matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        nrotminLabel                    matlab.ui.control.Label
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        DimensiunimotorPanel            matlab.ui.container.Panel
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        emmLabel                        matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        LbcmLabel                       matlab.ui.control.Label
        ScmLabel                        matlab.ui.control.Label
        DcmLabel                        matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        DcmEditField                    matlab.ui.control.NumericEditField
        Postinjectie2Panel              matlab.ui.container.Panel
        DurataRACLabel_5                matlab.ui.control.Label
        InceputulinjRACLabel_5          matlab.ui.control.Label
        EditField_44                    matlab.ui.control.NumericEditField
        EditField_43                    matlab.ui.control.NumericEditField
        MasainjmgLabel_5                matlab.ui.control.Label
        EditField_42                    matlab.ui.control.NumericEditField
        Postinjectie1Panel_2            matlab.ui.container.Panel
        DurataRACLabel_4                matlab.ui.control.Label
        InceputulinjRACLabel_4          matlab.ui.control.Label
        EditField_41                    matlab.ui.control.NumericEditField
        EditField_40                    matlab.ui.control.NumericEditField
        MasainjmgLabel_4                matlab.ui.control.Label
        EditField_39                    matlab.ui.control.NumericEditField
        Preinjectie2Panel               matlab.ui.container.Panel
        DurataRACLabel_3                matlab.ui.control.Label
        InceputulinjRACLabel_3          matlab.ui.control.Label
        EditField_38                    matlab.ui.control.NumericEditField
        EditField_37                    matlab.ui.control.NumericEditField
        MasainjmgLabel_3                matlab.ui.control.Label
        EditField_36                    matlab.ui.control.NumericEditField
        Preinjectie1Panel               matlab.ui.container.Panel
        DurataRACLabel_2                matlab.ui.control.Label
        InceputulinjRACLabel_2          matlab.ui.control.Label
        EditField_35                    matlab.ui.control.NumericEditField
        EditField_34                    matlab.ui.control.NumericEditField
        MasainjmgLabel_2                matlab.ui.control.Label
        EditField_33                    matlab.ui.control.NumericEditField
        AriasuprafetelorschimbdecalduraPanel  matlab.ui.container.Panel
        Achiulasamm2Label               matlab.ui.control.Label
        AcilPMSmm2Label                 matlab.ui.control.Label
        Apistonmm2Label                 matlab.ui.control.Label
        EditField_16                    matlab.ui.control.NumericEditField
        EditField_15                    matlab.ui.control.NumericEditField
        EditField_14                    matlab.ui.control.NumericEditField
        CoeficientiitemperaturiipieselorPanel  matlab.ui.container.Panel
        CotempcilLabel                  matlab.ui.control.Label
        CotempchiulasaLabel             matlab.ui.control.Label
        CotemppistLabel                 matlab.ui.control.Label
        EditField_19                    matlab.ui.control.NumericEditField
        EditField_18                    matlab.ui.control.NumericEditField
        EditField_17                    matlab.ui.control.NumericEditField
        AnalizaprincipiulITab           matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        FiltrareCurba                   matlab.ui.container.Tab
        ImportaredatePanel              matlab.ui.container.Panel
        ImportarecurbapresiunemotorantrenatButton  matlab.ui.control.Button
        ImportarecurbapresiunemotorinfunctionareButton  matlab.ui.control.Button
        RACLabel_3                      matlab.ui.control.Label
        Spinner                         matlab.ui.control.Spinner
        AvansareaintarziereacurbeidepresiuneLabel  matlab.ui.control.Label
        FiltraredatePanel               matlab.ui.container.Panel
        DefinireaparametrilorfiltruluitrecejosLabel  matlab.ui.control.Label
        CoeficientulSteepnessLabel      matlab.ui.control.Label
        AtenuareabenziideoprireLabel    matlab.ui.control.Label
        FrecventanormalizatabandadetrecereLabel  matlab.ui.control.Label
        EditField_85                    matlab.ui.control.NumericEditField
        EditField3                      matlab.ui.control.NumericEditField
        EditField_84                    matlab.ui.control.NumericEditField
        FiltrareButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        CalculparametriiTab             matlab.ui.container.Tab
        ImportarepresiunemodelWiebeButton  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        CalculparametriiButtonDan       matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        ExpuneregraficarezultateTab     matlab.ui.container.Tab
        ExpuneredateExcelButton         matlab.ui.control.Button
        AfisaredurataprocesuluideardereButton  matlab.ui.control.Button
        AfisarevalorimaximeButton       matlab.ui.control.Button
        RACLabel_4                      matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        EditField_87                    matlab.ui.control.NumericEditField
        EditField_86                    matlab.ui.control.NumericEditField
        AfisarerezultateinintervalulButton  matlab.ui.control.Button
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        AnalizamodelardereTab           matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        WiebeDateTab                    matlab.ui.container.Tab
        Wmarimi                         matlab.ui.control.CheckBox
        DatearderePanel                 matlab.ui.container.Panel
        SfarsitularderiiRACLabel        matlab.ui.control.Label
        EditField_106                   matlab.ui.control.NumericEditField
        EditField_90                    matlab.ui.control.NumericEditField
        DurataarderiiRACLabel           matlab.ui.control.Label
        InceputularderiiRACLabel        matlab.ui.control.Label
        EditField_88                    matlab.ui.control.NumericEditField
        CalculmodelardereButton         matlab.ui.control.Button
        ParametriifunctieiWiebePanel    matlab.ui.container.Panel
        Switch                          matlab.ui.control.Switch
        m2Label                         matlab.ui.control.Label
        RACLabel_6                      matlab.ui.control.Label
        o2RACLabel                      matlab.ui.control.Label
        a2Label                         matlab.ui.control.Label
        m1Label                         matlab.ui.control.Label
        RACLabel_5                      matlab.ui.control.Label
        o1RACLabel                      matlab.ui.control.Label
        a1Label                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        RACEditFieldLabel_2             matlab.ui.control.Label
        a2EditField                     matlab.ui.control.NumericEditField
        m2EditField                     matlab.ui.control.NumericEditField
        RACEditField_2                  matlab.ui.control.NumericEditField
        o2RACEditField                  matlab.ui.control.NumericEditField
        m1EditField                     matlab.ui.control.NumericEditField
        RACEditField                    matlab.ui.control.NumericEditField
        o1RACEditField                  matlab.ui.control.NumericEditField
        a1EditField                     matlab.ui.control.NumericEditField
        EditField_91                    matlab.ui.control.NumericEditField
        DatecalculatearderePanel        matlab.ui.container.Panel
        Lim1Label                       matlab.ui.control.Label
        Lim2Label                       matlab.ui.control.Label
        EditField_107                   matlab.ui.control.NumericEditField
        EditField_108                   matlab.ui.control.NumericEditField
        UITable2                        matlab.ui.control.Table
        EditField_105                   matlab.ui.control.NumericEditField
        RaportulcaldurilorspecificeLabel  matlab.ui.control.Label
        ExportdateExcelButton           matlab.ui.control.Button
        NormalizarevitezeButton         matlab.ui.control.Button
        CalcultransfertermicPanel       matlab.ui.container.Panel
        EditField_110                   matlab.ui.control.NumericEditField
        CoeficientAnnandLabel           matlab.ui.control.Label
        Transfer                        matlab.ui.control.DropDown
        MetodadecalculpentrucoeficientuldetransfertermicLabel  matlab.ui.control.Label
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes13                        matlab.ui.control.UIAxes
        UIAxes14                        matlab.ui.control.UIAxes
        UIAxes10                        matlab.ui.control.UIAxes
        WiebeGraficeTab                 matlab.ui.container.Tab
        UIAxes20                        matlab.ui.control.UIAxes
        UIAxes19                        matlab.ui.control.UIAxes
        UIAxes18                        matlab.ui.control.UIAxes
        UIAxes17                        matlab.ui.control.UIAxes
        ErikssonMASanaliticTab          matlab.ui.container.Tab
        ExpuneregraficaPanel            matlab.ui.container.Panel
        PresiuneacalculataCheckBox      matlab.ui.control.CheckBox
        TemperaturaCheckBox             matlab.ui.control.CheckBox
        ExpansionasymptoteCheckBox      matlab.ui.control.CheckBox
        CompressionasymptoteCheckBox    matlab.ui.control.CheckBox
        PresiuneamasurataCheckBox       matlab.ui.control.CheckBox
        ExportdateExcelButton_2         matlab.ui.control.Button
        CalculmodelanaliticButton       matlab.ui.control.Button
        ParametriidereglajPanel         matlab.ui.container.Panel
        MFB50optimRACdupPMSLabel        matlab.ui.control.Label
        EditField_111                   matlab.ui.control.NumericEditField
        EditField_98                    matlab.ui.control.NumericEditField
        FractiuneagazelorrezidualeLabel  matlab.ui.control.Label
        EditField_103                   matlab.ui.control.NumericEditField
        TemperaturagazelorrezidualeKLabel  matlab.ui.control.Label
        EditField_102                   matlab.ui.control.NumericEditField
        CoeficientpolitropicdestindereLabel  matlab.ui.control.Label
        EditField_100                   matlab.ui.control.NumericEditField
        CoeficientpolitropiccomprimareLabel  matlab.ui.control.Label
        EditField_99                    matlab.ui.control.NumericEditField
        CpJkgKLabel                     matlab.ui.control.Label
        DatedeintrarePanel_2            matlab.ui.container.Panel
        EditField_96                    matlab.ui.control.NumericEditField
        oRACLabel                       matlab.ui.control.Label
        bRACLabel                       matlab.ui.control.Label
        EditField_95                    matlab.ui.control.NumericEditField
        dRACLabel                       matlab.ui.control.Label
        EditField_94                    matlab.ui.control.NumericEditField
        UIAxes12                        matlab.ui.control.UIAxes
        UtilitarParametriiinjectieiTab  matlab.ui.container.Tab
        AfisarerezultateButton          matlab.ui.control.Button
        CalculparametriiinjectieiButton  matlab.ui.control.Button
        Postinjectie2Panel_3            matlab.ui.container.Panel
        DuratatotalaRACLabel_5          matlab.ui.control.Label
        InceputulprocesuluiRAC0PMSLabel_5  matlab.ui.control.Label
        MasadecombustibilinjectatamgLabel_5  matlab.ui.control.Label
        EditField_76                    matlab.ui.control.NumericEditField
        EditField_75                    matlab.ui.control.NumericEditField
        EditField_74                    matlab.ui.control.NumericEditField
        Postinjectie1Panel              matlab.ui.container.Panel
        DuratatotalaRACLabel_4          matlab.ui.control.Label
        InceputulprocesuluiRAC0PMSLabel_4  matlab.ui.control.Label
        MasadecombustibilinjectatamgLabel_4  matlab.ui.control.Label
        EditField_73                    matlab.ui.control.NumericEditField
        EditField_72                    matlab.ui.control.NumericEditField
        EditField_71                    matlab.ui.control.NumericEditField
        Preinjectie2Panel_2             matlab.ui.container.Panel
        DuratatotalaRACLabel_3          matlab.ui.control.Label
        InceputulprocesuluiRAC0PMSLabel_3  matlab.ui.control.Label
        MasadecombustibilinjectatamgLabel_3  matlab.ui.control.Label
        EditField_70                    matlab.ui.control.NumericEditField
        EditField_69                    matlab.ui.control.NumericEditField
        EditField_68                    matlab.ui.control.NumericEditField
        Preinjectie1Panel_2             matlab.ui.container.Panel
        DuratatotalaRACLabel_2          matlab.ui.control.Label
        InceputulprocesuluiRAC0PMSLabel_2  matlab.ui.control.Label
        MasadecombustibilinjectatamgLabel_2  matlab.ui.control.Label
        EditField_67                    matlab.ui.control.NumericEditField
        EditField_66                    matlab.ui.control.NumericEditField
        EditField_65                    matlab.ui.control.NumericEditField
        InjectiaprincipalaPanel_3       matlab.ui.container.Panel
        EditField_64                    matlab.ui.control.NumericEditField
        EditField_63                    matlab.ui.control.NumericEditField
        EditField_62                    matlab.ui.control.NumericEditField
        EditField_61                    matlab.ui.control.NumericEditField
        DuratapanteiRACLabel            matlab.ui.control.Label
        DuratarampeiRACLabel            matlab.ui.control.Label
        DuratatotalaRACLabel            matlab.ui.control.Label
        InceputulprocesuluiRAC0PMSLabel  matlab.ui.control.Label
        MasadecombustibilinjectatamgLabel  matlab.ui.control.Label
        EditField_56                    matlab.ui.control.NumericEditField
        DatedeintrarePanel              matlab.ui.container.Panel
        PresiuneadincilindrubarLabel    matlab.ui.control.Label
        Parametruldecorectat0viteza1durataLabel  matlab.ui.control.Label
        PasulRACLabel_2                 matlab.ui.control.Label
        PresuneadeinjecitebarLabel      matlab.ui.control.Label
        CoeficientdedebitLabel          matlab.ui.control.Label
        DiametrulunuiorificiummLabel    matlab.ui.control.Label
        NumaruldeorificiialeinjectoruluiLabel  matlab.ui.control.Label
        Densitateacombustibiluluikgm3Label  matlab.ui.control.Label
        TuratiarotminLabel              matlab.ui.control.Label
        EditField_55                    matlab.ui.control.NumericEditField
        EditField_54                    matlab.ui.control.NumericEditField
        EditField_53                    matlab.ui.control.NumericEditField
        EditField_52                    matlab.ui.control.NumericEditField
        EditField_51                    matlab.ui.control.NumericEditField
        EditField_50                    matlab.ui.control.NumericEditField
        EditField_49                    matlab.ui.control.NumericEditField
        EditField_48                    matlab.ui.control.NumericEditField
        EditField_47                    matlab.ui.control.NumericEditField
        UIAxes9                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        crank_angle % Unghiul arborelui cotit
        pressure_fired % Presiunea in cilindru motor in sarcina
        filtered_pressure %presiunea filtrata
        pressure_motored
        backup_filtered
        backup_motored
        
        exp
        crankshaft_rad
        lambda_raport
        V_s
        vol_ca
        cycle_air_mass_flow
        cyl_mass
        piston_temp
        cyl_head_temp
        cyl_wall_temp_TDC
        cyl_wall_temp_BDC
        piston_vel
        piston_circ_vel
        IVC_pressure
        IVC_temp
        IVC_vol
        rand_ardere
        combustion_start_angle
        combustion_end_angle
        combustion_duration
        A_inj_2
        w_inj_max_2
        Vol_inj_2
        m_inj_max_2
        m_inj_deg_2
        m_inj_norm_2
        vitnet_validare
        vol_phi
        dP
        temp
        cum_gross_heat_release
        vit_bruta
        vit_neta
        logvolum
        logp
        Woschni_coef
        piston_heat_transfer
        cyl_heat_transfer
        cyl_head_heat_transfer
        flux_termic
        inj_vector_plot
        
        A_inj
        w_inj_max
        Vol_inj
        m_inj_max
        m_inj_deg
        m_inj_norm
        
        theta
        MFB
        Wiebe_dan
        Wiebe_dan_test
        Xb1
        Xb2
        soc
        eoc
        %parametrii functiei wiebe
        Wlambda
        Wa1
        Wdelta_theta01
        Wm1
        Wa2
        theta02
        Wdelta_theta02
        Wm2
        
        ahhr
        Wcum_gross_heat_release
        ahhrW
        viteza_neta
        durata_arderii
        dMFB
        rand_ardereW
        mf_qlhv
        DQn
        Qn
        Wpress
        Wtemp
        viteza_bruta
        ivc_pres
        ivc_temp
        ivc_vol
        DQbrut
        Qbrut
        dXb1
        dXb2
        DQbrut1
        DQbrut2
        Qbrut1
        Qbrut2
        dqnet
        dqnet1
        dqnet2
        logvvmax
        validare_caracteristica
        heat_trans_coef %coef transfer termic
        Wiebe_piston_heat_transfer %pierderi piston
        Wiebe_cyl_heat_transfer %pierderi cilindru
        Wiebe_cyl_head_heat_transfer %pierderi chiulasa
        flux_termic_Wiebe %Fluxul termic
        presiune_validare
        gradient_validare
        Wpress_gradient
        TVW
        PVW
        GPVW
        temp_validare
        crank_angle_inj
        m_inj_2
        p
        t
        vit_cresterepresiune
        p_c
        p_e
        
        press_test
        umplere
        inBetwen
        Wpress_motored
        Wlogv_vmax
        Wlogp
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %startup / pornire
            app.crank_angle = 0:0.1:719; %initializare unghi arbore cotit


            app.EditField_106.Value=app.EditField_88.Value+app.EditField_90.Value; %sfarsitul arderii
            
            app.o1RACEditField.Value=app.EditField_88.Value;%initializare unghi incepere ardere
        end

        % Button pushed function: 
        % ImportarecurbapresiunemotorinfunctionareButton
        function ImportarecurbapresiunemotorinfunctionareButtonPushed(app, event)
            %Incarcarea datelor din fisier
            app.filtered_pressure=zeros;
            app.pressure_motored=zeros;
            [file,path] = uigetfile('C:\Users\ClaW\Desktop\Facultate\An IV\Licenta\Matlab\Eu\Design\D_2000rpm_load_100_pphi.txt');
            select_file = fullfile(path,file);
            
            raw_fired_cycle_data = importdata(select_file);
            imported_crank_angle = raw_fired_cycle_data.data(:,1);
            imported_pressure_fired = raw_fired_cycle_data.data(:,2);
            imported_crank_angle = round(imported_crank_angle,5);
            
            % RAC 0
            for i = length(imported_crank_angle)
                
                if imported_crank_angle(1) ~= 0
                    imported_crank_angle = imported_crank_angle - imported_crank_angle(1);
                end
            end
            app.crank_angle = 0:0.1:max(imported_crank_angle);
            app.pressure_fired = interp1(imported_crank_angle,imported_pressure_fired,app.crank_angle);


            %Afisare axa mare
            cla(app.UIAxes)
            plot(app.UIAxes,imported_crank_angle,imported_pressure_fired,"Color",[0, 0.4470, 0.7410])
            xlabel(app.UIAxes,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes,'Presiunea, [bar]')
            title(app.UIAxes,'Presiunea in functie de unghiul de rotatie al arborelui cotit - semnal nefiltrat')
            xlim(app.UIAxes,[min(app.crank_angle) max(app.crank_angle)])
            xline(app.UIAxes,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            xticks(app.UIAxes,0:90:max(app.crank_angle))
            
            
        end

        % Button pushed function: ImportarecurbapresiunemotorantrenatButton
        function ImportarecurbapresiunemotorantrenatButtonPushed(app, event)
            %Incarcare date fisier
            
            [file,path] = uigetfile('C:\Users\ClaW\Desktop\Facultate\An IV\Licenta\Matlab\Eu\Design\2000_motored_p_phi.txt');
            select_file = fullfile(path,file);
            
            
            raw_motored_cycle_data=importdata(select_file);
            imported_pressure_motored = raw_motored_cycle_data.data(:,2);
            
            imported_crank_angle_motored = raw_motored_cycle_data.data(:,1);
            imported_pressure_motored = raw_motored_cycle_data.data(:,2);
            
            for i = length(imported_crank_angle_motored)
                if imported_crank_angle_motored(1) ~= 0
                    imported_crank_angle_motored = imported_crank_angle_motored - imported_crank_angle_motored(1);
                end
            end
            app.pressure_motored = interp1(imported_crank_angle_motored,imported_pressure_motored,app.crank_angle);
            
            app.backup_motored = app.pressure_motored;
            
            %Afisare axa mare
            hold(app.UIAxes, "on")
            plot(app.UIAxes,app.crank_angle,app.pressure_fired,'Color',[0, 0.4470, 0.7410])
            plot(app.UIAxes,app.crank_angle,app.pressure_motored,'k')
            xlabel(app.UIAxes,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes,'Presiunea, [bar]')
            title(app.UIAxes,'Presiunea in functie de unghiul de rotatie al arborelui cotit - semnal nefiltrat si cu motorul antrenat')
            xlim(app.UIAxes,[min(app.crank_angle) max(app.crank_angle)])
            xline(app.UIAxes,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            xticks(app.UIAxes,0:90:max(app.crank_angle))
            hold (app.UIAxes,'off')
        end

        % Button pushed function: FiltrareButton
        function FiltrareButtonPushed(app, event)
            
            %filtrarea curbei de presiune
            
            passband = app.EditField_84.Value;
            stopband = app.EditField_85.Value;
            steepness = app.EditField3.Value;
            
            if isnan(passband)
                passband = 0.0001;
            end
            
            if isnan(stopband)
                stopband = 100;
            end
            
            if isnan(steepness)
                steepness = 0.87;
            end
            
            app.filtered_pressure = lowpass(app.pressure_fired,passband,"StopbandAttenuation",...
                stopband,"ImpulseResponse",'auto', "Steepness",steepness);
            app.backup_filtered = app.filtered_pressure;
            
            
            cla(app.UIAxes)
            plot(app.UIAxes,app.crank_angle,app.pressure_fired,'Color',[0, 0.4470, 0.7410],'LineWidth',1)
            hold (app.UIAxes,'on')
            plot(app.UIAxes,app.crank_angle,app.filtered_pressure,'r','LineWidth',1)
            if(app.pressure_motored~=0)
                plot(app.UIAxes,app.crank_angle,app.pressure_motored,'k','lineWidth',0.75)
            end
            
            title(app.UIAxes,'Presiunea in functie de unghiul de rotatie al arborelui cotit - semnal nefiltrat si filtrat')
            xlabel(app.UIAxes,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes,'Presiunea, [bar]')
            xlim (app.UIAxes,[340 420])
            xline(app.UIAxes,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            if(app.pressure_motored~=0)
                legend(app.UIAxes,{'semnal nefiltrat','semnal filtrat','motor antrenat'})
            else
                legend(app.UIAxes,{'semnal nefiltrat','semnal filtrat'})
            end
            hold (app.UIAxes,'off')
            
            
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = app.Spinner.Value;
            
            shift=value*10;
            
            if app.filtered_pressure ~= app.backup_filtered
                app.filtered_pressure = app.backup_filtered;
                app.pressure_motored = app.backup_motored;
            end
            
            for i =length(app.crank_angle)
                if value ~= 0
                    app.filtered_pressure = circshift(app.filtered_pressure,[1 round(shift,1)]);
                    app.pressure_motored = circshift(app.pressure_motored,[1 round(shift,1)]);
                end
            end
            
            cla(app.UIAxes)
            
            plot(app.UIAxes,app.crank_angle,app.pressure_fired,'Color',[0, 0.4470, 0.7410],'LineWidth',1)
            hold (app.UIAxes,'on')
            plot(app.UIAxes,app.crank_angle,app.filtered_pressure,'r','LineWidth',1)
            if(app.pressure_motored~=0)
                plot(app.UIAxes,app.crank_angle,app.pressure_motored,'k','lineWidth',0.75)
            end
            
            
            title(app.UIAxes,'Presiunea in functie de unghiul de rotatie al arborelui cotit - semnal nefiltrat si filtrat')
            xlabel(app.UIAxes,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes,'Presiunea, [bar]')
            xlim (app.UIAxes,[340 420])
            xline(app.UIAxes,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            if(app.pressure_motored~=0)
                legend(app.UIAxes,{'semnal nefiltrat','semnal filtrat','motor antrenat'})
            else
                legend(app.UIAxes,{'semnal nefiltrat','semnal filtrat'})
            end


        end

        % Button pushed function: CalculparametriiButtonDan
        function CalculparametriiButtonDanPushed(app, event)
            D = app.DcmEditField.Value;
            S = app.EditField_3.Value;
            conrod_length = app.EditField_4.Value;
            eps = app.EditField_5.Value;
            n = app.EditField_7.Value;
            lambda = app.EditField_8.Value;
            IVC = app.EditField_9.Value;
            EVO = app.EditField_10.Value;
            LHV = app.EditField_11.Value;
            atm_air_temp = app.EditField_12.Value;
            atm_air_pressure = app.EditField_13.Value;
            volumetric_efficiency = app.EditField_22.Value;
            piston_head_surface = app.EditField_14.Value;
            cyl_wall_surface_TDC = app.EditField_15.Value;
            cyl_head_surface = app.EditField_16.Value;
            R = app.EditField_20.Value;
            Coef_Swirl = app.EditField_21.Value;
            m_f_2 = app.DcmEditField_3.Value;
            soi_2 = app.EditField_29.Value;
            t_total_2 = app.EditField_30.Value;
            t_up_2 = app.EditField_31.Value;
            t_down_2 = app.EditField_32.Value;
            pre_1_m_f_2 = app.EditField_33.Value;
            pre_2_m_f_2 = app.EditField_36.Value;
            post_1_m_f_2 = app.EditField_39.Value;
            post_2_m_f_2 = app.EditField_42.Value;
            pre_1_soi_2 = app.EditField_34.Value;
            pre_2_soi_2 = app.EditField_37.Value;
            post_1_soi_2 = app.EditField_40.Value;
            post_2_soi_2 = app.EditField_43.Value;
            pre_1_t_total_2 = app.EditField_35.Value;
            pre_2_t_total_2 = app.EditField_38.Value;
            post_1_t_total_2 = app.EditField_41.Value;
            post_2_t_total_2 = app.EditField_44.Value;
            rho_2 = app.DcmEditField_2.Value;
            z_2 = app.EditField_23.Value;
            d_hole_2 = app.EditField_24.Value;
            Cd_2 = app.EditField_25.Value;
            p_inj_2 = app.EditField_26.Value;
            step_inj_2 = app.EditField_27.Value;
            corrected_param_2 = app.EditField_28.Value;
            piston_offset = app.EditField_6.Value;
            piston_temp_coef = app.EditField_17.Value;
            cyl_head_temp_coef = app.EditField_18.Value;
            cyl_temp_coef = app.EditField_19.Value;
            
            if isnan(D)
                D = 8.5;
            end
            
            if isnan(S)
                S = 9;
            end
            
            if isnan(conrod_length)
                conrod_length = 13.8;
            end
            
            if isnan(eps)
                eps = 17.1;
            end
            
            if isnan(n)
                n = 2000;
            end
            
            if isnan(lambda)
                lambda = 1.4;
            end
            
            if isnan(IVC)
                IVC = 226.5;
            end
            
            if isnan(EVO)
                EVO = 488.5;
            end
            
            if isnan(piston_offset)
                piston_offset = 0.55;
            end
            
            if isnan(LHV)
                LHV = 43000;
            end
            
            if isnan(atm_air_temp)
                atm_air_temp = 301.15;
            end
            
            if isnan(atm_air_pressure)
                atm_air_pressure = 98100;
            end
            
            if isnan(volumetric_efficiency)
                volumetric_efficiency = 0.93;
            end
            
            if isnan(piston_head_surface)
                piston_head_surface = 7600;
            end
            
            if isnan(cyl_wall_surface_TDC)
                cyl_wall_surface_TDC = 242;
            end
            
            if isnan(cyl_head_surface)
                cyl_head_surface = 5675;
            end
            
            if isnan(R)
                R = 287.12;
            end
            
            if isnan(Coef_Swirl)
                Coef_Swirl = 1.68;
            end
            
            if isnan(m_f_2)
                m_f_2 = 27.4;
            end
            
            if isnan(soi_2)
                soi_2 = -11.6;
            end
            
            if isnan(t_total_2)
                t_total_2 = 11.6;
            end
            
            if isnan(t_up_2)
                t_up_2 = 2;
            end
            
            if isnan(t_down_2)
                t_down_2 = 2;
            end
            
            if isnan(pre_1_m_f_2)
                pre_1_m_f_2 = 0;
            end
            
            if isnan(pre_1_soi_2)
                pre_1_soi_2 = 0;
            end
            
            if isnan(pre_1_t_total_2)
                pre_1_t_total_2 = 0;
            end
            
            if isnan(pre_2_m_f_2)
                pre_2_m_f_2 = 0;
            end
            
            if isnan(pre_2_soi_2)
                pre_2_soi_2 = 0;
            end
            
            if isnan(pre_2_t_total_2)
                pre_2_t_total_2 = 0;
            end
            
            if isnan(post_1_m_f_2)
                post_1_m_f_2 = 0;
            end
            
            if isnan(post_1_soi_2)
                post_1_soi_2 = 0;
            end
            
            if isnan(post_1_t_total_2)
                post_1_t_total_2 = 0;
            end
            
            if isnan(post_2_m_f_2)
                post_2_m_f_2 = 0;
            end
            
            if isnan(post_2_soi_2)
                post_2_soi_2 = 0;
            end
            
            if isnan(pre_1_t_total_2)
                post_2_t_total_2 = 0;
            end
            
            if isnan(z_2)
                z_2 = 8;
            end
            
            if isnan(d_hole_2)
                d_hole_2 = 0.12;
            end
            
            if isnan(Cd_2)
                Cd_2 = 0.9;
            end
            
            if isnan(p_inj_2)
                p_inj_2 = 700;
            end
            
            if isnan(step_inj_2)
                step_inj_2 = 0.001;
            end
            
            if isnan(corrected_param_2)
                corrected_param_2 = 1;
            end
            
            if isnan(rho_2)
                rho_2 = 826;
            end
            
            if isnan(piston_temp_coef)
                piston_temp_coef = 1;
            end
            
            if isnan(cyl_head_temp_coef)
                cyl_head_temp_coef = 1;
            end
            
            if isnan(cyl_temp_coef)
                cyl_temp_coef = 1;
            end
            
            neg_numb = length(app.crank_angle(app.crank_angle<0));
            
            app.crankshaft_rad = S/2;
            app.lambda_raport = app.crankshaft_rad/conrod_length;
            app.V_s = pi*D^2/4*S  ;
            app.vol_ca = app.V_s/(eps -1);
            
            if piston_offset == 0
                s_phi = app.crankshaft_rad.*(1-cosd(app.crank_angle)+...
                    (app.lambda_raport/4.*(1-cosd(2.*app.crank_angle))));
                app.vol_phi = s_phi*((pi*(D^2))/4)+app.vol_ca;
            end
            
            if piston_offset ~= 0
                piston_offset = piston_offset * (10^-1);
                beta = asind(piston_offset/(app.crankshaft_rad + conrod_length));
                s_phi = sqrt((app.crankshaft_rad+conrod_length)^2 - (piston_offset^2)) - ...
                    sqrt((conrod_length^2) - ((piston_offset + app.crankshaft_rad .* ...
                    sind(app.crank_angle - beta)).^2)) - app.crankshaft_rad .* ...
                    cosd(app.crank_angle - beta);
                app.vol_phi = s_phi*((pi*(D^2))/4)+app.vol_ca;
            end
            
            
            app.cycle_air_mass_flow = volumetric_efficiency*(max(app.vol_phi))/(10^6)*...
                atm_air_pressure/(atm_air_temp*R);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %--------------  Calculul injectiei  --------------%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            format short
            
            d_hole_2 = d_hole_2 * 10^-3 ;
            p_inj_2 = p_inj_2 * 10^5 ;
            p_cil_2 = mean(app.filtered_pressure) ;
            p_cil_2 = p_cil_2 * 10^5 ;
            m_f_2 = m_f_2 * 10^-6 ;
            
            % ----- Injection rate -----
            app.A_inj_2 = pi*d_hole_2^2/4*z_2     ;                            % Total flow area, [m^2]
            app.w_inj_max_2 = Cd_2*sqrt(2*(p_inj_2 - p_cil_2)/rho_2) ;         % Injection velocity, [m/s]
            app.Vol_inj_2 = app.w_inj_max_2*app.A_inj_2    ;                           % Injection rate (volume), [m^3/s]
            app.m_inj_max_2 = app.Vol_inj_2*rho_2   ;                              % Injection rate (mass), [kg/s]
            app.m_inj_deg_2 = app.m_inj_max_2/(6*n)   ;                            % Injection rate (mass/crank angle), [kg/degCA]
            app.m_inj_norm_2 = app.m_inj_deg_2/m_f_2  ;                            % Injection rate (normalized), [1/degCA]
            
            % ----- Correction of maintain at max rate duration -----
            t_maintain_2 = t_total_2 - t_up_2 - t_down_2   ;              % Duration of injection at max rate, [degCA]
            t_total_corr_2 = round((2/app.m_inj_norm_2 + t_up_2 + t_down_2)/2,ceil(log10(1/step_inj_2)));
            if t_total_corr_2 > t_total_2
                if corrected_param_2 == 0
                    app.m_inj_norm_2 = 2/(2*t_total_2 - t_up_2 - t_down_2);
                    t_total_corr_2 = round((2/app.m_inj_norm_2 + t_up_2 + t_down_2)/2,ceil(log10(1/step_inj_2)));
                end
            end
            if (t_total_corr_2 - t_up_2 - t_down_2) < 0
                t_maintain_corr_2 = 0;
            else
                t_maintain_corr_2 = t_total_corr_2 - t_up_2 - t_down_2;
            end
            
            
            % ----- Injection rate calculation steps -----
            phi_inj_2 = transpose(0:step_inj_2:(round(t_total_corr_2 + 1)));
            app.m_inj_2 = zeros([length(phi_inj_2) 1]);
            idx_2 = 1;
            
            
            % ----- Injection rate ramp up -----
            m_inj_ramp_up_2 = 0;
            
            for i = 1:find(round(phi_inj_2,ceil(log10(1/step_inj_2))) == t_up_2)
                if (phi_inj_2(i) <= t_up_2) && (m_inj_ramp_up_2 <= m_f_2)
                    idx_2 = i;
                    app.m_inj_2(i) = phi_inj_2(i)/t_up_2*app.m_inj_norm_2*m_f_2;
                    m_inj_ramp_up_2 = m_inj_ramp_up_2 + app.m_inj_2(i)*step_inj_2;
                end
            end
            
            m_inj_ramp_up_2;
            
            % ----- Injection rate maintain max -----
            m_inj_maintain_max_2 = 0;
            if t_maintain_corr_2 ~= 0
                for i = find(round(phi_inj_2,ceil(log10(1/step_inj_2))) == t_up_2):find(round(phi_inj_2,ceil(log10(1/step_inj_2))) == (t_up_2 + t_maintain_corr_2))
                    if (phi_inj_2(i) <= (t_up_2 + t_maintain_corr_2)) && (m_inj_ramp_up_2 + m_inj_maintain_max_2 <= m_f_2)
                        idx_2 = i;
                        app.m_inj_2(i) = app.m_inj_norm_2*m_f_2;
                        m_inj_maintain_max_2 = m_inj_maintain_max_2 + app.m_inj_2(i)*step_inj_2;
                    end
                end
            end
            
            m_inj_maintain_max_2;
            
            % ----- Injection rate ramp down -----
            m_inj_ramp_down_2 = 0;
            if t_down_2 ~= (m_f_2 - m_inj_ramp_up_2 - m_inj_maintain_max_2)*2/max(app.m_inj_2)
                t_down_2 = (m_f_2 - m_inj_ramp_up_2 - m_inj_maintain_max_2)*2/max(app.m_inj_2);
                t_total_corr_2 = t_up_2 + t_maintain_corr_2 + t_down_2;
            end
            for i = find(round(phi_inj_2,ceil(log10(1/step_inj_2))) == (t_up_2 + t_maintain_corr_2)):length(phi_inj_2)
                if (phi_inj_2(i) <= t_total_corr_2)
                    idx_2 = i;
                    app.m_inj_2(i) = (t_total_corr_2 - phi_inj_2(i))/t_down_2*app.m_inj_norm_2*m_f_2;
                    m_inj_ramp_down_2 = m_inj_ramp_down_2 + app.m_inj_2(i)*step_inj_2;
                end
            end
            
            m_inj_ramp_down_2;
            
            m_inj_total_2 = m_inj_ramp_up_2 + m_inj_maintain_max_2 + m_inj_ramp_down_2;
            
            % ----- Pre-injection 1 ----- %
            
            if pre_1_m_f_2 ~= 0
                
                pre_1_m_f_2 = pre_1_m_f_2 * 10^-6;
                
                % ----- Injection rate -----
                pre_1_w_inj_max_2 = Cd_2*sqrt(2*(p_inj_2 - p_cil_2)/rho_2) ;     % Injection velocity, [m/s]
                pre_1_Vol_inj_2 = pre_1_w_inj_max_2*app.A_inj_2   ;                  % Injection rate (volume), [m^3/s]
                pre_1_m_inj_max_2 = pre_1_Vol_inj_2*rho_2   ;                    % Injection rate (mass), [kg/s]
                pre_1_m_inj_deg_2 = pre_1_m_inj_max_2/(6*n)  ;                   % Injection rate (mass/crank angle), [kg/degCA]
                pre_1_m_inj_norm_2 = pre_1_m_inj_deg_2/pre_1_m_f_2    ;          % Injection rate (normalized), [1/degCA]
                
                % ----- Correction of maintain at max rate duration -----
                
                pre_1_t_maintain_2 = pre_1_t_total_2 - t_up_2 - t_down_2    ;    % Duration of injection at max rate, [degCA]
                pre_1_t_total_corr_2 = round((2/pre_1_m_inj_norm_2 + t_up_2 + t_down_2)/2,ceil(log10(1/step_inj_2)));
                if pre_1_t_total_corr_2 > pre_1_t_total_2
                    if corrected_param_2 == 0
                        pre_1_m_inj_norm_2 = 2/(2*pre_1_t_total_2 - t_up_2 - t_down_2);
                        pre_1_t_total_corr_2 = round((2/pre_1_m_inj_norm_2 + t_up_2 + t_down_2)/2,ceil(log10(1/step_inj_2)));
                    end
                end
                if (pre_1_t_total_corr_2 - t_up_2 - t_down_2) < 0
                    pre_1_t_maintain_corr_2 = 0;
                else
                    pre_1_t_maintain_corr_2 = pre_1_t_total_corr_2 - t_up_2 - t_down_2;
                end
                
                
                % ----- Injection rate calculation steps -----
                
                pre_1_phi_inj_2 = transpose(0:step_inj_2:(round(pre_1_t_total_corr_2 + 1)));
                pre_1_m_inj_2 = zeros([length(pre_1_phi_inj_2) 1]);
                idx_2 = 1;
                
                
                % ----- Injection rate ramp up -----
                pre_1_m_inj_ramp_up_2 = 0;
                
                for i = 1:find(round(pre_1_phi_inj_2,ceil(log10(1/step_inj_2))) == t_up_2)
                    if (pre_1_phi_inj_2(i) <= t_up_2) && (pre_1_m_inj_ramp_up_2 <= pre_1_m_f_2)
                        idx_2 = i;
                        pre_1_m_inj_2(i) = pre_1_phi_inj_2(i)/t_up_2*pre_1_m_inj_norm_2*pre_1_m_f_2;
                        pre_1_m_inj_ramp_up_2 = pre_1_m_inj_ramp_up_2 + pre_1_m_inj_2(i)*step_inj_2;
                    end
                end
                
                pre_1_m_inj_ramp_up_2;
                
                % ----- Injection rate maintain max -----
                
                pre_1_m_inj_maintain_max_2 = 0;
                if pre_1_t_maintain_corr_2 ~= 0
                    for i = find(round(pre_1_phi_inj_2,ceil(log10(1/step_inj_2))) == t_up_2):find(round(pre_1_phi_inj_2,ceil(log10(1/step_inj_2))) == (t_up_2 + pre_1_t_maintain_corr_2))
                        if (pre_1_phi_inj_2(i) <= (t_up_2 + pre_1_t_maintain_corr_2)) && (pre_1_m_inj_ramp_up_2 + pre_1_m_inj_maintain_max_2 <= pre_1_m_f_2)
                            idx_2 = i;
                            pre_1_m_inj_2(i) = pre_1_m_inj_norm_2*pre_1_m_f_2;
                            pre_1_m_inj_maintain_max_2 = pre_1_m_inj_maintain_max_2 + pre_1_m_inj_2(i)*step_inj_2;
                        end
                    end
                end
                
                pre_1_m_inj_maintain_max_2;
                
                % ----- Injection rate ramp down -----
                
                pre_1_m_inj_ramp_down_2 = 0;
                if t_down_2 ~= (pre_1_m_f_2 - pre_1_m_inj_ramp_up_2 - pre_1_m_inj_maintain_max_2)*2/max(pre_1_m_inj_2)
                    t_down_2 = (pre_1_m_f_2 - pre_1_m_inj_ramp_up_2 - pre_1_m_inj_maintain_max_2)*2/max(pre_1_m_inj_2);
                    pre_1_t_total_corr_2 = t_up_2 + pre_1_t_maintain_corr_2 + t_down_2;
                end
                for i = find(round(pre_1_phi_inj_2,ceil(log10(1/step_inj_2))) == (t_up_2 + pre_1_t_maintain_corr_2)):length(pre_1_phi_inj_2)
                    if (pre_1_phi_inj_2(i) <= pre_1_t_total_corr_2)
                        idx_2 = i;
                        pre_1_m_inj_2(i) = (pre_1_t_total_corr_2 - pre_1_phi_inj_2(i))/t_down_2*pre_1_m_inj_norm_2*pre_1_m_f_2;
                        pre_1_m_inj_ramp_down_2 = pre_1_m_inj_ramp_down_2 + pre_1_m_inj_2(i)*step_inj_2;
                    end
                end
                
                pre_1_m_inj_ramp_down_2;
                
                pre_1_m_inj_total_2 = pre_1_m_inj_ramp_up_2 + pre_1_m_inj_maintain_max_2 + pre_1_m_inj_ramp_down_2;
                
                pre_1_phi_inj_2 = pre_1_phi_inj_2 + pre_1_soi_2;
                
            end
            
            % ----- Pre-injection 2 ----- %
            
            if pre_2_m_f_2 ~= 0
                pre_2_m_f_2 = pre_2_m_f_2 * 10^-6;
                
                % ----- Injection rate -----
                pre_2_w_inj_max_2 = Cd_2*sqrt(2*(p_inj_2 - p_cil_2)/rho_2)  ;    % Injection velocity, [m/s]
                pre_2_Vol_inj_2 = pre_2_w_inj_max_2*app.A_inj_2   ;                  % Injection rate (volume), [m^3/s]
                pre_2_m_inj_max_2 = pre_2_Vol_inj_2*rho_2     ;                  % Injection rate (mass), [kg/s]
                pre_2_m_inj_deg_2 = pre_2_m_inj_max_2/(6*n)    ;                 % Injection rate (mass/crank angle), [kg/degCA]
                pre_2_m_inj_norm_2 = pre_2_m_inj_deg_2/pre_2_m_f_2    ;          % Injection rate (normalized), [1/degCA]
                
                % ----- Correction of maintain at max rate duration -----
                
                pre_2_t_maintain_2 = pre_2_t_total_2 - t_up_2 - t_down_2    ;    % Duration of injection at max rate, [degCA]
                pre_2_t_total_corr_2 = round((2/pre_2_m_inj_norm_2 + t_up_2 + t_down_2)/2,ceil(log10(1/step_inj_2)));
                if pre_2_t_total_corr_2 > pre_2_t_total_2
                    if corrected_param_2 == 0
                        pre_2_m_inj_norm_2 = 2/(2*pre_2_t_total_2 - t_up_2 - t_down_2);
                        pre_2_t_total_corr_2 = round((2/pre_2_m_inj_norm_2 + t_up_2 + t_down_2)/2,ceil(log10(1/step_inj_2)));
                    end
                end
                if (pre_2_t_total_corr_2 - t_up_2 - t_down_2) < 0
                    pre_2_t_maintain_corr_2 = 0
                else
                    pre_2_t_maintain_corr_2 = pre_2_t_total_corr_2 - t_up_2 - t_down_2
                end
                
                
                % ----- Injection rate calculation steps -----
                
                pre_2_phi_inj_2 = transpose(0:step_inj_2:(round(pre_2_t_total_corr_2 + 1)));
                pre_2_m_inj_2 = zeros([length(pre_2_phi_inj_2) 1]);
                idx_2 = 1;
                
                
                % ----- Injection rate ramp up -----
                pre_2_m_inj_ramp_up_2 = 0;
                
                for i = 1:find(round(pre_2_phi_inj_2,ceil(log10(1/step_inj_2))) == t_up_2)
                    if (pre_2_phi_inj_2(i) <= t_up_2) && (pre_2_m_inj_ramp_up_2 <= pre_2_m_f_2)
                        idx_2 = i;
                        pre_2_m_inj_2(i) = pre_2_phi_inj_2(i)/t_up_2*pre_2_m_inj_norm_2*pre_2_m_f_2;
                        pre_2_m_inj_ramp_up_2 = pre_2_m_inj_ramp_up_2 + pre_2_m_inj_2(i)*step_inj_2;
                    end
                end
                
                pre_2_m_inj_ramp_up_2;
                
                % ----- Injection rate maintain max -----
                
                pre_2_m_inj_maintain_max_2 = 0;
                if pre_2_t_maintain_corr_2 ~= 0
                    for i = find(round(pre_2_phi_inj_2,ceil(log10(1/step_inj_2))) == t_up_2):find(round(pre_2_phi_inj_2,ceil(log10(1/step_inj_2))) == (t_up_2 + pre_2_t_maintain_corr_2))
                        if (pre_2_phi_inj_2(i) <= (t_up_2 + pre_2_t_maintain_corr_2)) && (pre_2_m_inj_ramp_up_2 + pre_2_m_inj_maintain_max_2 <= pre_2_m_f_2)
                            idx_2 = i;
                            pre_2_m_inj_2(i) = pre_2_m_inj_norm_2*pre_2_m_f_2;
                            pre_2_m_inj_maintain_max_2 = pre_2_m_inj_maintain_max_2 + pre_2_m_inj_2(i)*step_inj_2;
                        end
                    end
                end
                
                pre_2_m_inj_maintain_max_2;
                
                % ----- Injection rate ramp down -----
                
                pre_2_m_inj_ramp_down_2 = 0;
                if t_down_2 ~= (pre_2_m_f_2 - pre_2_m_inj_ramp_up_2 - pre_2_m_inj_maintain_max_2)*2/max(pre_2_m_inj_2)
                    t_down_2 = (pre_2_m_f_2 - pre_2_m_inj_ramp_up_2 - pre_2_m_inj_maintain_max_2)*2/max(pre_2_m_inj_2);
                    pre_2_t_total_corr_2 = t_up_2 + pre_2_t_maintain_corr_2 + t_down_2;
                end
                for i = find(round(pre_2_phi_inj_2,ceil(log10(1/step_inj_2))) == (t_up_2 + pre_2_t_maintain_corr_2)):length(pre_2_phi_inj_2)
                    if (pre_2_phi_inj_2(i) <= pre_2_t_total_corr_2)
                        idx_2 = i;
                        pre_2_m_inj_2(i) = (pre_2_t_total_corr_2 - pre_2_phi_inj_2(i))/t_down_2*pre_2_m_inj_norm_2*pre_2_m_f_2;
                        pre_2_m_inj_ramp_down_2 = pre_2_m_inj_ramp_down_2 + pre_2_m_inj_2(i)*step_inj_2;
                    end
                end
                
                pre_2_m_inj_ramp_down_2;
                
                pre_2_m_inj_total_2 = pre_2_m_inj_ramp_up_2 + pre_2_m_inj_maintain_max_2 + pre_2_m_inj_ramp_down_2;
                
                pre_2_phi_inj_2 = pre_2_phi_inj_2 + pre_2_soi_2;
                
            end
            
            % ----- Post-injection 1 ----- %
            
            if post_1_m_f_2 ~= 0
                post_1_m_f_2 = post_1_m_f_2 * 10^-6;
                
                % ----- Injection rate -----
                post_1_w_inj_max_2 = Cd_2*sqrt(2*(p_inj_2 - p_cil_2)/rho_2)  ;     % Injection velocity, [m/s]
                post_1_Vol_inj_2 = post_1_w_inj_max_2*app.A_inj_2       ;              % Injection rate (volume), [m^3/s]
                post_1_m_inj_max_2 = post_1_Vol_inj_2*rho_2      ;                 % Injection rate (mass), [kg/s]
                post_1_m_inj_deg_2 = post_1_m_inj_max_2/(6*n)          ;           % Injection rate (mass/crank angle), [kg/degCA]
                post_1_m_inj_norm_2 = post_1_m_inj_deg_2/post_1_m_f_2   ;          % Injection rate (normalized), [1/degCA]
                
                % ----- Correction of maintain at max rate duration -----
                
                post_1_t_maintain_2 = post_1_t_total_2 - t_up_2 - t_down_2   ;     % Duration of injection at max rate, [degCA]
                post_1_t_total_corr_2 = round((2/post_1_m_inj_norm_2 + t_up_2 + t_down_2)/2,ceil(log10(1/step_inj_2)));
                if post_1_t_total_corr_2 > post_1_t_total_2
                    if corrected_param_2 == 0
                        post_1_m_inj_norm_2 = 2/(2*post_1_t_total_2 - t_up_2 - t_down_2);
                        post_1_t_total_corr_2 = round((2/post_1_m_inj_norm_2 + t_up_2 + t_down_2)/2,ceil(log10(1/step_inj_2)));
                    end
                end
                if (post_1_t_total_corr_2 - t_up_2 - t_down_2) < 0
                    post_1_t_maintain_corr_2 = 0;
                else
                    post_1_t_maintain_corr_2 = post_1_t_total_corr_2 - t_up_2 - t_down_2;
                end
                
                
                % ----- Injection rate calculation steps -----
                
                post_1_phi_inj_2 = transpose(0:step_inj_2:(round(post_1_t_total_corr_2 + 1)));
                post_1_m_inj_2 = zeros([length(post_1_phi_inj_2) 1]);
                idx_2 = 1;
                
                
                % ----- Injection rate ramp up -----
                post_1_m_inj_ramp_up_2 = 0;
                
                for i = 1:find(round(post_1_phi_inj_2,ceil(log10(1/step_inj_2))) == t_up_2)
                    if (post_1_phi_inj_2(i) <= t_up_2) && (post_1_m_inj_ramp_up_2 <= post_1_m_f_2)
                        idx_2 = i;
                        post_1_m_inj_2(i) = post_1_phi_inj_2(i)/t_up_2*post_1_m_inj_norm_2*post_1_m_f_2;
                        post_1_m_inj_ramp_up_2 = post_1_m_inj_ramp_up_2 + post_1_m_inj_2(i)*step_inj_2;
                    end
                end
                
                post_1_m_inj_ramp_up_2;
                
                % ----- Injection rate maintain max -----
                
                post_1_m_inj_maintain_max_2 = 0;
                if post_1_t_maintain_corr_2 ~= 0
                    for i = find(round(post_1_phi_inj_2,ceil(log10(1/step_inj_2))) == t_up_2):find(round(post_1_phi_inj_2,ceil(log10(1/step_inj_2))) == (t_up_2 + post_1_t_maintain_corr_2))
                        if (post_1_phi_inj_2(i) <= (t_up_2 + post_1_t_maintain_corr_2)) && (post_1_m_inj_ramp_up_2 + post_1_m_inj_maintain_max_2 <= post_1_m_f_2)
                            idx_2 = i;
                            post_1_m_inj_2(i) = post_1_m_inj_norm_2*post_1_m_f_2;
                            post_1_m_inj_maintain_max_2 = post_1_m_inj_maintain_max_2 + post_1_m_inj_2(i)*step_inj_2;
                        end
                    end
                end
                
                post_1_m_inj_maintain_max_2;
                
                % ----- Injection rate ramp down -----
                
                post_1_m_inj_ramp_down_2 = 0;
                if t_down_2 ~= (post_1_m_f_2 - post_1_m_inj_ramp_up_2 - post_1_m_inj_maintain_max_2)*2/max(post_1_m_inj_2)
                    t_down_2 = (post_1_m_f_2 - post_1_m_inj_ramp_up_2 - post_1_m_inj_maintain_max_2)*2/max(post_1_m_inj_2);
                    post_1_t_total_corr_2 = t_up_2 + post_1_t_maintain_corr_2 + t_down_2;
                end
                for i = find(round(post_1_phi_inj_2,ceil(log10(1/step_inj_2))) == (t_up_2 + post_1_t_maintain_corr_2)):length(post_1_phi_inj_2)
                    if (post_1_phi_inj_2(i) <= post_1_t_total_corr_2)
                        idx_2 = i;
                        post_1_m_inj_2(i) = (post_1_t_total_corr_2 - post_1_phi_inj_2(i))/t_down_2*post_1_m_inj_norm_2*post_1_m_f_2;
                        post_1_m_inj_ramp_down_2 = post_1_m_inj_ramp_down_2 + post_1_m_inj_2(i)*step_inj_2;
                    end
                end
                
                post_1_m_inj_ramp_down_2;
                
                post_1_m_inj_total_2 = post_1_m_inj_ramp_up_2 + post_1_m_inj_maintain_max_2 + post_1_m_inj_ramp_down_2;
                
                post_1_phi_inj_2 = post_1_phi_inj_2 + post_1_soi_2;
                
            end
            
            % ----- Post-injection 2 ----- %
            
            if post_2_m_f_2 ~= 0
                post_2_m_f_2 = post_2_m_f_2 * 10^-6;
                
                % ----- Injection rate -----
                post_2_w_inj_max_2 = Cd_2*sqrt(2*(p_inj_2 - p_cil_2)/rho_2)     ;  % Injection velocity, [m/s]
                post_2_Vol_inj_2 = post_2_w_inj_max_2*app.A_inj_2               ;      % Injection rate (volume), [m^3/s]
                post_2_m_inj_max_2 = post_2_Vol_inj_2*rho_2               ;        % Injection rate (mass), [kg/s]
                post_2_m_inj_deg_2 = post_2_m_inj_max_2/(6*n)             ;        % Injection rate (mass/crank angle), [kg/degCA]
                post_2_m_inj_norm_2 = post_2_m_inj_deg_2/post_2_m_f_2    ;         % Injection rate (normalized), [1/degCA]
                
                % ----- Correction of maintain at max rate duration -----
                
                post_2_t_maintain_2 = post_2_t_total_2 - t_up_2 - t_down_2      ;  % Duration of injection at max rate, [degCA]
                post_2_t_total_corr_2 = round((2/post_2_m_inj_norm_2 + t_up_2 + t_down_2)/2,ceil(log10(1/step_inj_2)));
                if post_2_t_total_corr_2 > post_2_t_total_2
                    if corrected_param_2 == 0
                        post_2_m_inj_norm_2 = 2/(2*post_2_t_total_2 - t_up_2 - t_down_2);
                        post_2_t_total_corr_2 = round((2/post_2_m_inj_norm_2 + t_up_2 + t_down_2)/2,ceil(log10(1/step_inj_2)));
                    end
                end
                if (post_2_t_total_corr_2 - t_up_2 - t_down_2) < 0
                    post_2_t_maintain_corr_2 = 0;
                else
                    post_2_t_maintain_corr_2 = post_2_t_total_corr_2 - t_up_2 - t_down_2;
                end
                
                
                % ----- Injection rate calculation steps -----
                
                post_2_phi_inj_2 = transpose(0:step_inj_2:(round(post_2_t_total_corr_2 + 1)));
                post_2_m_inj_2 = zeros([length(post_2_phi_inj_2) 1]);
                idx_2 = 1;
                
                
                % ----- Injection rate ramp up -----
                post_2_m_inj_ramp_up_2 = 0;
                
                for i = 1:find(round(post_2_phi_inj_2,ceil(log10(1/step_inj_2))) == t_up_2)
                    if (post_2_phi_inj_2(i) <= t_up_2) && (post_2_m_inj_ramp_up_2 <= post_2_m_f_2)
                        idx_2 = i;
                        post_2_m_inj_2(i) = post_2_phi_inj_2(i)/t_up_2*post_2_m_inj_norm_2*post_2_m_f_2;
                        post_2_m_inj_ramp_up_2 = post_2_m_inj_ramp_up_2 + post_2_m_inj_2(i)*step_inj_2;
                    end
                end
                
                post_2_m_inj_ramp_up_2;
                
                % ----- Injection rate maintain max -----
                
                post_2_m_inj_maintain_max_2 = 0;
                if post_2_t_maintain_corr_2 ~= 0
                    for i = find(round(post_2_phi_inj_2,ceil(log10(1/step_inj_2))) == t_up_2):find(round(post_2_phi_inj_2,ceil(log10(1/step_inj_2))) == (t_up_2 + post_2_t_maintain_corr_2))
                        if (post_2_phi_inj_2(i) <= (t_up_2 + post_2_t_maintain_corr_2)) && (post_2_m_inj_ramp_up_2 + post_2_m_inj_maintain_max_2 <= post_2_m_f_2)
                            idx_2 = i;
                            post_2_m_inj_2(i) = post_2_m_inj_norm_2*post_2_m_f_2;
                            post_2_m_inj_maintain_max_2 = post_2_m_inj_maintain_max_2 + post_2_m_inj_2(i)*step_inj_2;
                        end
                    end
                end
                
                post_2_m_inj_maintain_max_2;
                
                % ----- Injection rate ramp down -----
                
                post_2_m_inj_ramp_down_2 = 0;
                if t_down_2 ~= (post_2_m_f_2 - post_2_m_inj_ramp_up_2 - post_2_m_inj_maintain_max_2)*2/max(post_2_m_inj_2)
                    t_down_2 = (post_2_m_f_2 - post_2_m_inj_ramp_up_2 - post_2_m_inj_maintain_max_2)*2/max(post_2_m_inj_2);
                    post_2_t_total_corr_2 = t_up_2 + post_2_t_maintain_corr_2 + t_down_2;
                end
                for i = find(round(post_2_phi_inj_2,ceil(log10(1/step_inj_2))) == (t_up_2 + post_2_t_maintain_corr_2)):length(post_2_phi_inj_2)
                    if (post_2_phi_inj_2(i) <= post_2_t_total_corr_2)
                        idx_2 = i;
                        post_2_m_inj_2(i) = (post_2_t_total_corr_2 - post_2_phi_inj_2(i))/t_down_2*post_2_m_inj_norm_2*post_2_m_f_2;
                        post_2_m_inj_ramp_down_2 = post_2_m_inj_ramp_down_2 + post_2_m_inj_2(i)*step_inj_2;
                    end
                end
                
                post_2_m_inj_ramp_down_2;
                
                post_2_m_inj_total_2 = post_2_m_inj_ramp_up_2 + post_2_m_inj_maintain_max_2 + post_2_m_inj_ramp_down_2;
                
                post_2_phi_inj_2 = post_2_phi_inj_2 + post_2_soi_2;
                
            end
            
            phi_inj_2 = phi_inj_2 + soi_2 + 360;
            app.m_inj_2 = app.m_inj_2 * 10^6;
            
            if pre_1_m_f_2 ~= 0
                pre_1_m_inj_2 = pre_1_m_inj_2 * 10^6;
                pre_1_m_inj_2 = abs(pre_1_m_inj_2);
                pre_1_phi_inj_2 = pre_1_phi_inj_2 + 360;
            end
            
            if pre_2_m_f_2 ~= 0
                pre_2_m_inj_2 = pre_2_m_inj_2 * 10^6;
                pre_2_m_inj_2 = abs(pre_2_m_inj_2);
                pre_2_phi_inj_2 = pre_2_phi_inj_2 + 360;
            end
            
            if post_1_m_f_2 ~= 0
                post_1_m_inj_2 = post_1_m_inj_2 * 10^6;
                post_1_m_inj_2 = abs(post_1_m_inj_2);
                post_1_phi_inj_2 = post_1_phi_inj_2 + 360;
            end
            
            if post_2_m_f_2 ~= 0
                post_2_m_inj_2 = post_2_m_inj_2 * 10^6;
                post_2_m_inj_2 = abs(post_2_m_inj_2);
                post_2_phi_inj_2 = post_2_phi_inj_2 + 360;
            end


            inj_vector_2 = app.m_inj_2;
            crank_angle_vector_2 = phi_inj_2;
            
            if pre_1_m_f_2 ~= 0
                inj_vector_2 = vertcat(pre_1_m_inj_2,inj_vector_2);
                crank_angle_vector_2 = vertcat(pre_1_phi_inj_2,crank_angle_vector_2);
            else
                inj_vector_2 = inj_vector_2;
                crank_angle_vector_2 = crank_angle_vector_2;
            end
            
            if pre_2_m_f_2 ~= 0
                inj_vector_2 = vertcat(pre_2_m_inj_2,inj_vector_2);
                crank_angle_vector_2 = vertcat(pre_2_phi_inj_2,crank_angle_vector_2);
            else
                inj_vector_2 = inj_vector_2;
                crank_angle_vector_2 = crank_angle_vector_2;
            end
            
            if post_1_m_f_2 ~= 0
                inj_vector_2 = vertcat(inj_vector_2,post_1_m_inj_2);
                crank_angle_vector_2 = vertcat(crank_angle_vector_2,post_1_phi_inj_2);
            else
                inj_vector_2 = inj_vector_2;
                crank_angle_vector_2 = crank_angle_vector_2;
            end
            
            if post_2_m_f_2 ~= 0
                inj_vector_2 = vertcat(inj_vector_2,post_2_m_inj_2);
                crank_angle_vector_2 = vertcat(crank_angle_vector_2,post_2_phi_inj_2);
            else
                inj_vector_2 = inj_vector_2;
                crank_angle_vector_2 = crank_angle_vector_2;
            end
            
            
            if pre_1_m_f_2 ~= 0
                pre_1_m_inj_2 = abs(pre_1_m_inj_2);
            end
            
            if pre_2_m_f_2 ~= 0
                pre_2_m_inj_2 = abs(pre_2_m_inj_2);
            end
            
            if post_1_m_f_2 ~= 0
                post_1_m_inj_2 = abs(post_1_m_inj_2);
            end
            
            if post_2_m_f_2 ~= 0
                post_2_m_inj_2 = abs(post_2_m_inj_2);
            end
            
            [val,pos] = intersect(crank_angle_vector_2,app.crank_angle);
            [vall,poss] = intersect(app.crank_angle,crank_angle_vector_2);
            
            app.inj_vector_plot(poss) = inj_vector_2(pos);
            inj_vector_temp(poss) = inj_vector_2(pos);
            
            app.inj_vector_plot = zeros([length(app.crank_angle) 1]);
            inj_vector_temp = zeros([length(app.crank_angle) 1]);
            
            length_inj = length(app.inj_vector_plot(min(poss):max(poss)));
            app.inj_vector_plot(min(poss):max(poss)) = resample(inj_vector_2,length_inj,length(inj_vector_2));
            
            % Calculul temperaturii
            
            inj_vector_temp = inj_vector_temp * 10^-6;
            app.cyl_mass = cumtrapz(app.crank_angle,app.inj_vector_plot*10^-6);
            app.cyl_mass = app.cyl_mass + app.cycle_air_mass_flow;
            app.cyl_mass = app.cyl_mass';
            
            
            app.temp = max(atm_air_temp + 10, app.filtered_pressure.*app.vol_phi...
                ./(10 .* app.cyl_mass .* R));
            
            piston_temp_degC = (200 + 0.045*n) * piston_temp_coef;
            cyl_head_temp_degC = (200 + 0.045*n - 40) * cyl_head_temp_coef;
            cyl_wall_temp_TDC_degC = (200 + 0.045*n - 80) * cyl_temp_coef;
            cyl_wall_temp_BDC_degC = (200 + 0.045*n - 160) * cyl_temp_coef;
            
            app.piston_temp = 273.15 + piston_temp_degC                ;
            app.cyl_head_temp = 273.15 + cyl_head_temp_degC                 ;
            app.cyl_wall_temp_TDC = 273.15 + cyl_wall_temp_TDC_degC        ;
            app.cyl_wall_temp_BDC = 273.15 + cyl_wall_temp_BDC_degC    ;
            
            % Calculul vitezelor pistonului
            
            app.piston_vel = 2*(S/100)*n/60               ; %mean piston speed
            app.piston_circ_vel = ((D/100/2)*Coef_Swirl*n)/60 ;
            
            % Interpolarea presiunii, temperaturii si a volumului in momentul
            % inchiderii supapelor de admisie
            
            app.IVC_pressure = interp1(app.crank_angle, app.filtered_pressure, IVC);
            app.IVC_temp = interp1(app.crank_angle, app.temp, IVC);
            app.IVC_vol = interp1(app.crank_angle, app.vol_phi, IVC) ;
            
            % Calculul variatiei presiunii si a volumului functie de arborele cotit
            
            for i = length(app.crank_angle)
                app.dP = zeros ([1 length(app.crank_angle)]);
                app.dP(2:end) = diff(app.filtered_pressure)./diff(app.crank_angle);
                dV = zeros ([1 length(app.crank_angle)]);
                dV(2:end) = diff(app.vol_phi)./diff(app.crank_angle);
            end


            % Calculul logaritmului presiunii si volumului si al exponentului
            % politropic
            
            app.logp = log10(app.filtered_pressure);
            app.logvolum = log10(app.vol_phi);
            app.logvvmax=log10(app.vol_phi/max(app.vol_phi));


            % Calculul exponentului politropic
            
            % exp = 1.392 - 8.13 * (10^-5) .* temp
            % exp = 1.375 - 6.99 * (10^-5) .* temp
            % exp = 1.350 - 6 * (10^-5) .* temp + 1 * (10^-8) .* (temp.^2)
            
            app.exp = 1.338 - 6 * (10^-5) .* app.temp + 1 * (10^-8) .* (app.temp.^2);
            
            % Calculul vitezei nete de eliberare a caldurii, [J/ grade RAC]
            
            app.vit_neta = (1 ./ (app.exp-1)) .* (app.exp .* app.filtered_pressure.*dV +...
                app.vol_phi .* app.dP) .* (10^-1);
            
            for i = length(app.crank_angle)
                app.vit_neta(app.crank_angle < IVC) = 0;
            end
            
            for i = length(app.crank_angle)
                app.vit_neta(app.crank_angle > EVO) = 0;
            end


            % Calculul coeficientului de transfer termic Woschni 1978, [W/m^2*K]
            % Coeficienti utilizati in calculul coeficientului de transfer termic
            % Woschni
            
            C1_Woschni = 2.28 + 0.308 * (app.piston_circ_vel / app.piston_vel) ;
            C2_Woschni = 0.00324;
            C3_Woschni = 6.35;
            
            % Coeficientul de transfer termic Woschni 1978 (rupt in 2 termeni)
            
            termen1_Woschni = 130 * ((D/100)^(-0.2)) .* (app.filtered_pressure .^ 0.8) .* ...
                (app.temp .^ (-0.53));
            termen2_Woschni = (C1_Woschni * app.piston_vel + C2_Woschni .* ((max(app.vol_phi)...
                * app.IVC_temp) / (app.IVC_pressure * app.IVC_vol) .* (app.filtered_pressure ...
                - app.pressure_motored))) .^ 0.8;
            
            app.Woschni_coef = termen1_Woschni .* termen2_Woschni;
            
            % Calculul schimbului de caldura cu peretii
            % Pierderile de caldura prin piston, [W]
            
            app.piston_heat_transfer = -(piston_head_surface / 10^6) .* app.Woschni_coef .* ...
                (app.temp - app.piston_temp);
            
            % Pierderile de caldura prin cilindru, [W]
            
            app.cyl_heat_transfer = -(cyl_wall_surface_TDC + pi * D / 100 .* ...
                (app.crankshaft_rad + conrod_length - app.crankshaft_rad ...
                .* cosd(app.crank_angle) - conrod_length * sqrt ...
                ( 1 - (app.crankshaft_rad / conrod_length .* ...
                sind(app.crank_angle)) .^ 2) /  10^6 .*...
                app.Woschni_coef .* (app.temp - (app.cyl_wall_temp_TDC +...
                app.cyl_wall_temp_BDC)/2)));
            
            % Pierderile de caldura prin chiulasa, [W]
            
            app.cyl_head_heat_transfer = -(cyl_head_surface)/10^6*app.Woschni_coef...
                .*(app.temp - app.cyl_head_temp);
            
            % Fluxul termic prin peretii cilindrului, [kW]
            
            app.flux_termic = -(app.piston_heat_transfer + app.cyl_heat_transfer + ...
                app.cyl_head_heat_transfer)/1000;
            
            
            % Calculul vitezei brute de eliberare a caldurii, [J/ grade RAC]
            
            app.vit_bruta = (app.flux_termic .* 1000)/(6*n) + app.vit_neta;
            
            for i = length(app.crank_angle)
                app.vit_bruta(app.crank_angle < IVC) = 0;
            end
            
            for i = length(app.crank_angle)
                app.vit_bruta(app.crank_angle > EVO) = 0;
            end
            
            
            % Calculul caracteristicii de degajare a caldurii
            
            app.cum_gross_heat_release = cumtrapz(app.crank_angle,app.vit_bruta);


            %Calculul randamentului arderii
            
            app.rand_ardere = max(app.cum_gross_heat_release) / ((max(app.cyl_mass) - app.cycle_air_mass_flow) * LHV * 10^3);
            disp(app.rand_ardere)
            
            % Combustion start, end and duration
            
            %             [app.cum_gross_heat_release, index] = unique(app.cum_gross_heat_release);
            %             combustion_start_value = 0.05 * max(app.cum_gross_heat_release);
            %             combustion_end_value = 0.95 * max(app.cum_gross_heat_release);
            %             app.combustion_start_angle = interp1(app.cum_gross_heat_release, app.crank_angle(index), combustion_start_value);
            %             app.combustion_end_angle = interp1(app.cum_gross_heat_release, app.crank_angle(index), combustion_end_value);
            %             app.combustion_duration = app.combustion_end_angle - app.combustion_start_angle;
            %             app.cum_gross_heat_release = cumtrapz(app.crank_angle,app.vit_bruta);
            
            
            app.crank_angle_inj = app.crank_angle;
            
            
            cla(app.UIAxes2)
            yyaxis(app.UIAxes2,'left')
            plot(app.UIAxes2,app.crank_angle_inj,app.inj_vector_plot,'DisplayName','Debitul masic injectat','lineWidth',1);
            title(app.UIAxes2,'Presiunea si debitul masic injectat')
            xlabel(app.UIAxes2,'Unghiul de rotatie al arborelui cotit [°RAC]')
            ylabel(app.UIAxes2,'Debitul masic injectat [mg/°RAC]')
            hold(app.UIAxes2, 'on')
            ylim(app.UIAxes2,[0 3*max(app.m_inj_2)])
            xline(app.UIAxes2,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9,'Displayname','PMS')
            yyaxis(app.UIAxes2, 'right')
            if(app.mf_qlhv~=0)
                
                plot(app.UIAxes2,app.crank_angle,app.filtered_pressure,'r','DisplayName','Presiunea - calculata model Wiebe','lineWidth',0.75)
            else
                plot(app.UIAxes2,app.crank_angle,app.filtered_pressure,'r','DisplayName','Presiunea - motor in functionare','lineWidth',0.75)
            end
            plot(app.UIAxes2,app.crank_angle,app.pressure_motored,'-k','DisplayName','Presiunea - motor antrenat','lineWidth',0.75)
            ylabel(app.UIAxes2,'Presiunea, [bar]')
            xlim (app.UIAxes2,[340 420])
            xticks(app.UIAxes2,0:10:max(app.crank_angle))
            hold (app.UIAxes2,'off')
            legend(app.UIAxes2)
            % lgd = legend;
            
            %Grafic p - V
            
            % axes(handles.axes4);
            plot(app.UIAxes3,app.vol_phi,app.filtered_pressure)
            xlabel(app.UIAxes3,'Volumul cilindrului, [cm^3]')
            ylabel(app.UIAxes3,'Presiunea, [bar]')
            
            % Grafic dP - fi
            
            % axes(handles.axes10);
            plot(app.UIAxes4,app.crank_angle,app.dP)
            xlim(app.UIAxes4,[0 720])
            xlabel(app.UIAxes4,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes4,'Viteza de crestere a presiunii, [bar/°RAC]')
            xlim (app.UIAxes4,[340 420])
            xline(app.UIAxes4,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            xticks(app.UIAxes4,0:10:max(app.crank_angle))
            
            % Grafic temp - fi
            
            % axes(handles.axes11);
            plot(app.UIAxes5,app.crank_angle,app.temp)
            xlabel(app.UIAxes5,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes5,'Temperatura, [K]')
            xlim (app.UIAxes5,[340 420])
            xline(app.UIAxes5,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            xticks(app.UIAxes5,0:10:max(app.crank_angle))
            
            % Grafic dQnet - fi
            
            % axes(handles.axes14);
            plot(app.UIAxes6,app.crank_angle,app.vit_neta)
            xlabel(app.UIAxes6,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes6,'Viteza neta de degajare a caldurii, [J/°RAC]')
            xlim (app.UIAxes6,[340 420])
            xline(app.UIAxes6,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            xticks(app.UIAxes6,0:10:max(app.crank_angle))
            
            % Grafic dQbrut - fi
            
            % axes(handles.axes13);
            plot(app.UIAxes7,app.crank_angle,app.vit_bruta)
            xlabel(app.UIAxes7,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes7,'Viteza bruta de degajare a caldurii, [J/°RAC]')
            xlim (app.UIAxes7,[340 420])
            xline(app.UIAxes7,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            xticks(app.UIAxes7,0:10:max(app.crank_angle))
            
            % Grafic Qcum - fi
            
            % axes(handles.axes12);
            plot(app.UIAxes8,app.crank_angle,app.cum_gross_heat_release)
            xlabel(app.UIAxes8,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes8,'Caracteristica de degajare a caldurii, [J]')
            xlim (app.UIAxes8,[340 420])
            ylim(app.UIAxes8,[min(app.cum_gross_heat_release) - 20 max(app.cum_gross_heat_release) + 20])
            xline(app.UIAxes8,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            xticks(app.UIAxes8,0:10:max(app.crank_angle))
            % guidata(hObject, handles);
            
            
            %afisare in tabel
            
            dat =  {'Raza fus maneton [cm]', app.crankshaft_rad;...
                'Raport raza fus maneton/lungimea bielei [-]', app.lambda_raport;...
                'Cilindree unitara [cm^3]', app.V_s;...
                'Volumul camerei de ardere [cm^3]', app.vol_ca;...
                'Masa de aer admisa in cilindru per ciclu [kg/ciclu]', app.cycle_air_mass_flow;...
                'Masa de amestec admisa in cilindru per ciclu [kg/ciclu]', max(app.cyl_mass);...
                'Temperatura pistonului [K]', app.piston_temp;...
                'Temperatura chiulasei [K]', app.cyl_head_temp;...
                'Temperatura peretelui cilindrului cand pistonul se afla in PMS [K]', app.cyl_wall_temp_TDC;...
                'Temperatura peretelui cilindrului cand pistonul se afla in PMI [K]', app.cyl_wall_temp_BDC;...
                'Viteza medie a pistonului [m/s]', app.piston_vel;...
                'Viteza pe circumferinta [m/s]', app.piston_circ_vel;...
                'Presiunea in momentul inchiderii supapelor de admisie [bar]', app.IVC_pressure;...
                'Temperatura in momentul inchiderii supapelor de admisie [K]', app.IVC_temp;...
                'Volumul in momentul inchiderii supapelor de admisie [cm^3]', app.IVC_vol;...
                'Randamenul procesului de ardere [-]', app.rand_ardere;...
                'Inceputul procesului de ardere [°RAC]', app.combustion_start_angle;...
                'Finalul procesului de ardere [°RAC]', app.combustion_end_angle;...
                'Durata procesului de ardere [°RAC]', app.combustion_duration;...
                'Aria de injectie [m^2]', app.A_inj_2;...
                'Viteza de injectie [m/s]', app.w_inj_max_2;...
                'Debitul volumic injectat [m^3/s]', app.Vol_inj_2;...
                'Debitul masic injectat [kg/s]', app.m_inj_max_2;
                'Debitul masic injectat [kg/°RAC]', app.m_inj_deg_2;
                'Debitul normalizat [1/°RAC]', app.m_inj_norm_2};
            app.UITable.Data=dat;
        end

        % Callback function
        function AfisaredateButtonPushed(app, event)
            %               f = figure('Name','Date rezultate in urma calculelor','NumberTitle','off')
            %             set(f,'Position',[300 150 416 553]);
            dat =  {'Raza fus maneton [cm]', app.crankshaft_rad;...
                'Raport raza fus maneton/lungimea bielei [-]', app.lambda_raport;...
                'Cilindree unitara [cm^3]', app.V_s;...
                'Volumul camerei de ardere [cm^3]', app.vol_ca;...
                'Masa de aer admisa in cilindru per ciclu [kg/ciclu]', app.cycle_air_mass_flow;...
                'Masa de amestec admisa in cilindru per ciclu [kg/ciclu]', max(app.cyl_mass);...
                'Temperatura pistonului [K]', app.piston_temp;...
                'Temperatura chiulasei [K]', app.cyl_head_temp;...
                'Temperatura peretelui cilindrului cand pistonul se afla in PMS [K]', app.cyl_wall_temp_TDC;...
                'Temperatura peretelui cilindrului cand pistonul se afla in PMI [K]', app.cyl_wall_temp_BDC;...
                'Viteza medie a pistonului [m/s]', app.piston_vel;...
                'Viteza pe circumferinta [m/s]', app.piston_circ_vel;...
                'Presiunea in momentul inchiderii supapelor de admisie [bar]', app.IVC_pressure;...
                'Temperatura in momentul inchiderii supapelor de admisie [K]', app.IVC_temp;...
                'Volumul in momentul inchiderii supapelor de admisie [cm^3]', app.IVC_vol;...
                'Randamenul procesului de ardere [-]', app.rand_ardere;...
                'Inceputul procesului de ardere [°RAC]', app.combustion_start_angle;...
                'Finalul procesului de ardere [°RAC]', app.combustion_end_angle;...
                'Durata procesului de ardere [°RAC]', app.combustion_duration;...
                'Aria de injectie [m^2]', app.A_inj_2;...
                'Viteza de injectie [m/s]', app.w_inj_max_2;...
                'Debitul volumic injectat [m^3/s]', app.Vol_inj_2;...
                'Debitul masic injectat [kg/s]', app.m_inj_max_2;
                'Debitul masic injectat [kg/°RAC]', app.m_inj_deg_2;
                'Debitul normalizat [1/°RAC]', app.m_inj_norm_2};
            %             columnname = {'Parametru', 'Valoare'};
            %             columnformat = {'char', 'numeric'};
            %             t = uitable('Units','normalized','Position',...
            %                         [0 0.14 12 0.87], 'Data', dat,...
            %                         'ColumnName', columnname,...
            %                         'ColumnFormat', columnformat,...
            %                         'RowName',[],'ColumnWidth',...
            %                         {316,100})
            
            app.UITable.Data=dat;
        end

        % Button pushed function: AfisarerezultateinintervalulButton
        function AfisarerezultateinintervalulButtonPushed(app, event)
            lim1 = app.EditField_86.Value;
            lim2 = app.EditField_87.Value;
            
            if isnan(lim1)
                lim1 = 340;
            end
            
            if isnan(lim2)
                lim2 = 420;
            end
            
            
            plot(app.UIAxes3,app.vol_phi, app.filtered_pressure)
            xlim(app.UIAxes3,[0 600]);
            xlabel(app.UIAxes3,'Volumul cilindrului, [cm^3]')
            ylabel(app.UIAxes3,'Presiunea, [bar]')
            
            
            plot(app.UIAxes4,app.crank_angle, app.dP)
            xlim(app.UIAxes4,[lim1 lim2]);
            xlabel(app.UIAxes4,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes4,'Viteza de crestere a presiunii, [bar/°RAC]')
            xline(app.UIAxes4,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            
            if lim2 - lim1 == 80
                
                xticks(app.UIAxes4,0:10:max(app.crank_angle))
            end
            
            
            plot(app.UIAxes5,app.crank_angle, app.temp)
            xlim(app.UIAxes5,[lim1 lim2]);
            xlabel(app.UIAxes5,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes5,'Temperatura, [K]')
            xline(app.UIAxes5,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            
            if lim2 - lim1 == 80
                
                xticks(app.UIAxes5,0:10:max(app.crank_angle))
            end
            
            
            plot(app.UIAxes8,app.crank_angle, app.cum_gross_heat_release)
            xlim(app.UIAxes8,[lim1 lim2]);
            ylim(app.UIAxes8,[min(app.cum_gross_heat_release) - 20 max(app.cum_gross_heat_release) + 20])
            xlabel(app.UIAxes8,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes8,'Caracteristica de degajare a caldurii, [J]')
            xline(app.UIAxes8,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            
            if lim2 - lim1 == 80
                
                xticks(app.UIAxes8,0:10:max(app.crank_angle))
            end
            
            
            plot(app.UIAxes7,app.crank_angle, app.vit_bruta)
            xlim(app.UIAxes7,[lim1 lim2]);
            xlabel(app.UIAxes7,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes7,'Viteza bruta de degajare a caldurii, [J/°RAC]')
            xline(app.UIAxes7,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            
            if lim2 - lim1 == 80
                
                xticks(app.UIAxes7,0:10:max(app.crank_angle))
            end
            
            
            plot(app.UIAxes6,app.crank_angle, app.vit_neta)
            xlim(app.UIAxes6,[lim1 lim2]);
            xlabel(app.UIAxes6,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes6,'Viteza neta de degajare a caldurii, [J/°RAC]')
            xline(app.UIAxes6,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            
            if lim2 - lim1 == 80
                
                xticks(app.UIAxes6,0:10:max(app.crank_angle))
            end
        end

        % Button pushed function: AfisarevalorimaximeButton
        function AfisarevalorimaximeButtonPushed(app, event)
            lim1 = app.EditField_86.Value;
            lim2 = app.EditField_87.Value;
            
            if isnan(lim1)
                lim1 = 340;
            end
            
            if isnan(lim2)
                lim2 = 420;
            end
            
            %Grafic p - V
            
            [maxP,idx] = max(app.filtered_pressure);
            
            plot(app.UIAxes3,app.vol_phi,app.filtered_pressure,app.vol_phi(idx),app.filtered_pressure(idx),'pr')
            str = ['p_m_a_x: ' strcat(num2str(round(maxP,2))) ' bar' newline 'Unghi: ' strcat(num2str(app.crank_angle(idx))) '°RAC'];
            text(app.UIAxes3,120,90,str,'Units', 'points',"FontSize",16)
            xlabel(app.UIAxes3,'Volumul cilindrului, [cm^3]')
            ylabel(app.UIAxes3,'Presiunea, [bar]')
            
            % Grafic dP - fi
            
            [maxdP,idx] = max(app.dP);
            plot(app.UIAxes4,app.crank_angle,app.dP,app.crank_angle(idx),app.dP(idx),'pr')
            str = ['dp/dphi_m_a_x: ' strcat(num2str(round(maxdP,2))) ' bar/°RAC' newline 'Unghi: ' strcat(num2str(app.crank_angle(idx))) '°RAC'];
            text(app.UIAxes4,120,90,str,'Units', 'points',"FontSize",16)
            xlim (app.UIAxes4,[lim1 lim2])
            xlabel(app.UIAxes4,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes4,'Viteza de crestere a presiunii, [bar/°RAC]')
            xline(app.UIAxes4,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            xticks(app.UIAxes4,0:30:max(app.crank_angle))
            
            if lim2 - lim1 == 80
                
                xticks(app.UIAxes4,0:10:max(app.crank_angle))
            end
            
            % Grafic temp - fi
            
            [maxtemp,idx] = max(app.temp);
            
            plot(app.UIAxes5,app.crank_angle,app.temp,app.crank_angle(idx),app.temp(idx),'pr')
            str = ['T_m_a_x: ' strcat(num2str(round(maxtemp,1))) ' K' newline 'Unghi: ' strcat(num2str(app.crank_angle(idx))) '°RAC'];
            text(app.UIAxes5,120,90,str,'Units', 'points',"FontSize",16)
            xlim (app.UIAxes5,[lim1 lim2])
            xlabel(app.UIAxes5,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes5,'Temperatura, [K]')
            xline(app.UIAxes5,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            xticks(app.UIAxes5,0:30:max(app.crank_angle))
            
            if lim2 - lim1 == 80
                
                xticks(app.UIAxes5,0:10:max(app.crank_angle))
            end
            
            % Grafic dQnet - fi
            
            [maxvit_neta,idx] = max(app.vit_neta);
            
            plot(app.UIAxes6,app.crank_angle,app.vit_neta,app.crank_angle(idx),app.vit_neta(idx),'pr')
            str = ['dQ_n_e_t/dphi_m_a_x: ' strcat(num2str(round(maxvit_neta,1))) ' J/°RAC' newline 'Unghi: ' strcat(num2str(app.crank_angle(idx))) '°RAC'];
            text(app.UIAxes6,120,90,str,'Units', 'points',"FontSize",16)
            xlim (app.UIAxes6,[lim1 lim2])
            xlabel(app.UIAxes6,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes6,'Viteza neta de degajare a caldurii, [J/°RAC]')
            xline(app.UIAxes6,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            xticks(app.UIAxes6,0:30:max(app.crank_angle))
            
            if lim2 - lim1 == 80
                
                xticks(app.UIAxes6,0:10:max(app.crank_angle))
            end
            
            % Grafic dQbrut - fi
            
            [maxvit_bruta,idx] = max(app.vit_bruta);
            
            plot(app.UIAxes7,app.crank_angle,app.vit_bruta,app.crank_angle(idx),app.vit_bruta(idx),'pr')
            str = ['dQ_b_r_u_t/dphi_m_a_x: ' strcat(num2str(round(maxvit_bruta,1))) ' J/°RAC' newline 'Unghi: ' strcat(num2str(app.crank_angle(idx))) '°RAC'];
            text(app.UIAxes7,120,90,str,'Units', 'points',"FontSize",16)
            xlim (app.UIAxes7,[lim1 lim2])
            xlabel(app.UIAxes7,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes7,'Viteza bruta de degajare a caldurii, [J/°RAC]')
            xline(app.UIAxes7,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            xticks(app.UIAxes7,0:30:max(app.crank_angle))
            
            if lim2 - lim1 == 80
                
                xticks(app.UIAxes7,0:10:max(app.crank_angle))
            end
            
            % Grafic Qcum - fi
            
            [maxcum_gross,idx] = max(app.cum_gross_heat_release);
            
            plot(app.UIAxes8,app.crank_angle,app.cum_gross_heat_release,app.crank_angle(idx),app.cum_gross_heat_release(idx),'pr')
            str = ['Q_d_e_g_a_j_a_t: ' strcat(num2str(round(maxcum_gross,1))) ' J' newline 'Unghi: ' strcat(num2str(app.crank_angle(idx))) '°RAC'];
            text(app.UIAxes8,120,90,str,'Units', 'points',"FontSize",16)
            xlim (app.UIAxes8,[lim1 lim2])
            ylim(app.UIAxes8,[min(app.cum_gross_heat_release) - 20 max(app.cum_gross_heat_release) + 20])
            xlabel(app.UIAxes8,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes8,'Caracteristica de degajare a caldurii, [J]')
            xline(app.UIAxes8,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9)
            xticks(app.UIAxes8,0:30:max(app.crank_angle))
            
            if lim2 - lim1 == 80
                
                xticks(app.UIAxes8,0:10:max(app.crank_angle))
            end
            
        end

        % Button pushed function: AfisaredurataprocesuluideardereButton
        function AfisaredurataprocesuluideardereButtonPushed(app, event)
            
            plot(app.UIAxes8,app.crank_angle,app.cum_gross_heat_release)
            xlabel(app.UIAxes8,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes8,'Caracteristica de degajare a caldurii, [J]')
            xlim (app.UIAxes8,[app.combustion_start_angle - 25 app.combustion_end_angle + 25])
            ylim(app.UIAxes8,[min(app.cum_gross_heat_release) - 20 max(app.cum_gross_heat_release) + 40])
            display_start = round(app.combustion_start_angle,1);
            display_end = round(app.combustion_end_angle,1);
            xline(app.UIAxes8,app.combustion_start_angle,'--','labelVerticalAlignment',...
                'middle','labelHorizontalAlignment','left','Color',[0 0 0], 'lineWidth', 0.75)
            xline(app.UIAxes8,app.combustion_end_angle,'--','labelOrientation','horizontal'...
                ,'labelVerticalAlignment','middle','labelHorizontalAlignment','right','Color',[0 0 0], 'lineWidth', 0.75)
            start_angle = ['5% MFB' newline 'Unghi: ' strcat(num2str(display_start))];
            text(app.UIAxes8,15,90,start_angle,'Units','points',"FontSize",16)
            end_angle = ['95% MFB' newline 'Unghi: ' strcat(num2str(display_end))];
            text(app.UIAxes8,180,90,end_angle,'Units','points',"FontSize",16)
            dur = {['Durata: ', num2str(round(app.combustion_duration,1)), '[°RAC]']};
            text(app.UIAxes8,5,165,dur,'Units', 'points',"FontSize",16)
            
        end

        % Button pushed function: ExpuneredateExcelButton
        function ExpuneredateExcelButtonPushed(app, event)
            col_header = {'Unghiul de rotatie al arborelui cotit [°RAC]','Volumul cilindrului [cm^3]','logV [cm^3]','logP [bar]'...
                ,'Presiunea pe ciclul cu ardere [bar]','Presiunea pe ciclul fara ardere [bar]','Presiunea filtrata [bar]'...
                ,'Viteza de crestere a presiunii [bar/°RAC]','Temperatura din cilindru [K]','Viteza neta de eliberare a caldurii [J/°RAC]',...
                'Viteza bruta de eliberare a caldurii [J/°RAC]','Caracteristica de degajare a c?ldurii [J]',...
                'Coeficientul de transfer termic Woschni [W/m^2/K]','Pierderile de caldura prin piston [W]',...
                'Pierderile de caldura prin cilindru [W]','Pierderile de caldura prin chiulasa [W]',...
                'Fluxul termic pierdut prin peretii cilindrului [kW]','Debitul masic injectat [mg/°RAC]','Masa din cilindru, [mg]'};
            
            app.crank_angle = app.crank_angle';
            app.vol_phi = app.vol_phi';
            app.logvolum = app.logvolum';
            app.logp = app.logp';
            app.pressure_fired = app.pressure_fired';
            app.pressure_motored = app.pressure_motored';
            app.filtered_pressure = app.filtered_pressure';
            app.dP = app.dP';
            app.temp = app.temp';
            app.vit_neta = app.vit_neta';
            app.vit_bruta = app.vit_bruta';
            app.cum_gross_heat_release = app.cum_gross_heat_release';
            app.Woschni_coef = app.Woschni_coef';
            app.piston_heat_transfer = app.piston_heat_transfer';
            app.cyl_heat_transfer = app.cyl_heat_transfer';
            app.cyl_head_heat_transfer = app.cyl_head_heat_transfer';
            app.flux_termic = app.flux_termic';
            % handles.inj_vector_temp = handles.inj_vector_temp'
            app.cyl_mass = app.cyl_mass';
            
            col_data = [app.crank_angle, app.vol_phi,app.logvolum,app.logp,app.pressure_fired,...
                app.pressure_motored,app.filtered_pressure,app.dP,app.temp,app.vit_neta,...
                app.vit_bruta,app.cum_gross_heat_release,app.Woschni_coef,app.piston_heat_transfer,...
                app.cyl_heat_transfer,app.cyl_head_heat_transfer,app.flux_termic,app.inj_vector_plot...
                ];
            
            xlswrite('Dezvoltarea unui program de analiza a procesului de ardere.xlsx',col_header,'Sheet1','A1');
            xlswrite('Dezvoltarea unui program de analiza a procesului de ardere.xlsx',col_data,'Sheet1','A2');
            winopen('Dezvoltarea unui program de analiza a procesului de ardere.xlsx');
        end

        % Button pushed function: CalculparametriiinjectieiButton
        function CalculparametriiinjectieiButtonPushed(app, event)
            n_inj = app.EditField_47.Value;
            rho = app.EditField_48.Value;
            z = app.EditField_49.Value;
            d_hole = app.EditField_50.Value;
            Cd = app.EditField_51.Value;
            p_inj = app.EditField_52.Value;
            step_inj = app.EditField_53.Value;
            corrected_param = app.EditField_54.Value;
            p_cil = app.EditField_55.Value;
            m_f = app.EditField_56.Value;
            soi = app.EditField_61.Value;
            t_total = app.EditField_62.Value;
            t_up = app.EditField_63.Value;
            t_down = app.EditField_64.Value;
            pre_1_m_f = app.EditField_65.Value;
            pre_1_soi = app.EditField_66.Value;
            pre_1_t_total = app.EditField_67.Value;
            pre_2_m_f = app.EditField_68.Value;
            pre_2_soi = app.EditField_69.Value;
            pre_2_t_total = app.EditField_70.Value;
            post_1_m_f = app.EditField_71.Value;
            post_1_soi = app.EditField_72.Value;
            post_1_t_total = app.EditField_73.Value;
            post_2_m_f = app.EditField_74.Value;
            post_2_soi = app.EditField_75.Value;
            post_2_t_total = app.EditField_76.Value;
            
            if isnan(n_inj)
                n_inj = 4000;
            end
            
            if isnan(rho)
                rho = 826;
            end
            
            if isnan(z)
                z = 8;
            end
            
            if isnan(d_hole)
                d_hole = 0.12;
            end
            
            if isnan(Cd)
                Cd = 0.9;
            end
            
            if isnan(p_inj)
                p_inj = 700;
            end
            
            if isnan(step_inj)
                step_inj = 0.001;
            end
            
            if isnan(corrected_param)
                corrected_param = 1;
            end
            
            if isnan(p_cil)
                p_cil = 50;
            end
            
            if isnan(m_f)
                m_f = 20;
            end
            
            if isnan(t_total)
                t_total = 10;
            end
            
            if isnan(t_up)
                t_up = 2;
            end
            
            if isnan(t_down)
                t_down = 3;
            end
            
            if isnan(soi)
                soi = -20;
            end
            
            if isnan(pre_1_m_f)
                pre_1_m_f = 0;
            end
            
            if isnan(pre_1_soi)
                pre_1_soi = 0;
            end
            
            if isnan(pre_1_t_total)
                pre_1_t_total = 0;
            end
            
            if isnan(pre_2_m_f)
                pre_2_m_f = 0;
            end
            
            if isnan(pre_2_soi)
                pre_2_soi = 0;
            end
            
            if isnan(pre_2_t_total)
                pre_2_t_total = 0;
            end
            
            if isnan(post_1_m_f)
                post_1_m_f = 0;
            end
            
            if isnan(post_1_soi)
                post_1_soi = 0;
            end
            
            if isnan(post_1_t_total)
                post_1_t_total = 0;
            end
            
            if isnan(post_2_m_f)
                post_2_m_f = 0;
            end
            
            if isnan(post_2_soi)
                post_2_soi = 0;
            end
            
            if isnan(post_2_t_total)
                post_2_t_total = 0;
            end
            
            format short
            
            d_hole = d_hole * 10^-3;
            p_inj = p_inj * 10^5;
            p_cil = p_cil * 10^5;
            m_f = m_f * 10^-6;
            
            % ----- Injection rate -----
            app.A_inj = pi*d_hole^2/4*z;                             % Total flow area, [m^2]
            app.w_inj_max = Cd*sqrt(2*(p_inj - p_cil)/rho) ;         % Injection velocity, [m/s]
            app.Vol_inj = app.w_inj_max*app.A_inj    ;                       % Injection rate (volume), [m^3/s]
            app.m_inj_max = app.Vol_inj*rho    ;                         % Injection rate (mass), [kg/s]
            app.m_inj_deg = app.m_inj_max/(6*n_inj)  ;                   % Injection rate (mass/crank angle), [kg/degCA]
            app.m_inj_norm = app.m_inj_deg/m_f   ;                       % Injection rate (normalized), [1/degCA]
            
            % ----- Correction of maintain at max rate duration -----
            t_maintain = t_total - t_up - t_down  ;              % Duration of injection at max rate, [degCA]
            t_total_corr = round((2/app.m_inj_norm + t_up + t_down)/2,ceil(log10(1/step_inj)));
            if t_total_corr > t_total
                if corrected_param == 0
                    app.m_inj_norm = 2/(2*t_total - t_up - t_down);
                    t_total_corr = round((2/app.m_inj_norm + t_up + t_down)/2,ceil(log10(1/step_inj)));
                end
            end
            if (t_total_corr - t_up - t_down) < 0
                t_maintain_corr = 0;
            else
                t_maintain_corr = t_total_corr - t_up - t_down;
            end
            
            
            % ----- Injection rate calculation steps -----
            phi_inj = transpose(0:step_inj:(round(t_total_corr + 1)));
            m_inj = zeros([length(phi_inj) 1]);
            idx = 1;
            
            
            % ----- Injection rate ramp up -----
            m_inj_ramp_up = 0;
            
            for i = 1:find(round(phi_inj,ceil(log10(1/step_inj))) == t_up)
                if (phi_inj(i) <= t_up) && (m_inj_ramp_up <= m_f)
                    idx = i;
                    m_inj(i) = phi_inj(i)/t_up*app.m_inj_norm*m_f;
                    m_inj_ramp_up = m_inj_ramp_up + m_inj(i)*step_inj;
                end
            end
            
            m_inj_ramp_up;
            
            % ----- Injection rate maintain max -----
            m_inj_maintain_max = 0;
            if t_maintain_corr ~= 0
                for i = find(round(phi_inj,ceil(log10(1/step_inj))) == t_up):find(round(phi_inj,ceil(log10(1/step_inj))) == (t_up + t_maintain_corr))
                    if (phi_inj(i) <= (t_up + t_maintain_corr)) && (m_inj_ramp_up + m_inj_maintain_max <= m_f)
                        idx = i;
                        m_inj(i) = app.m_inj_norm*m_f;
                        m_inj_maintain_max = m_inj_maintain_max + m_inj(i)*step_inj;
                    end
                end
            end
            
            m_inj_maintain_max;
            
            % ----- Injection rate ramp down -----
            m_inj_ramp_down = 0;
            if t_down ~= (m_f - m_inj_ramp_up - m_inj_maintain_max)*2/max(m_inj)
                t_down = (m_f - m_inj_ramp_up - m_inj_maintain_max)*2/max(m_inj);
                t_total_corr = t_up + t_maintain_corr + t_down;
            end
            for i = find(round(phi_inj,ceil(log10(1/step_inj))) == (t_up + t_maintain_corr)):length(phi_inj)
                if (phi_inj(i) <= t_total_corr)
                    idx = i;
                    m_inj(i) = (t_total_corr - phi_inj(i))/t_down*app.m_inj_norm*m_f;
                    m_inj_ramp_down = m_inj_ramp_down + m_inj(i)*step_inj;
                end
            end
            
            m_inj_ramp_down;
            
            m_inj_total = m_inj_ramp_up + m_inj_maintain_max + m_inj_ramp_down;
            
            % ----- Pre-injection 1 ----- %
            
            if pre_1_m_f ~= 0
                
                pre_1_m_f = pre_1_m_f * 10^-6;
                
                % ----- Injection rate -----
                pre_1_w_inj_max = Cd*sqrt(2*(p_inj - p_cil)/rho)  ;        % Injection velocity, [m/s]
                pre_1_Vol_inj = pre_1_w_inj_max*app.A_inj  ;                   % Injection rate (volume), [m^3/s]
                pre_1_m_inj_max = pre_1_Vol_inj*rho       ;                % Injection rate (mass), [kg/s]
                pre_1_m_inj_deg = pre_1_m_inj_max/(6*n_inj)  ;             % Injection rate (mass/crank angle), [kg/degCA]
                pre_1_m_inj_norm = pre_1_m_inj_deg/pre_1_m_f  ;            % Injection rate (normalized), [1/degCA]
                
                % ----- Correction of maintain at max rate duration -----
                
                pre_1_t_maintain = pre_1_t_total - t_up - t_down;                % Duration of injection at max rate, [degCA]
                pre_1_t_total_corr = round((2/pre_1_m_inj_norm + t_up + t_down)/2,ceil(log10(1/step_inj)));
                if pre_1_t_total_corr > pre_1_t_total
                    if corrected_param == 0
                        pre_1_m_inj_norm = 2/(2*pre_1_t_total - t_up - t_down);
                        pre_1_t_total_corr = round((2/pre_1_m_inj_norm + t_up + t_down)/2,ceil(log10(1/step_inj)));
                    end
                end
                if (pre_1_t_total_corr - t_up - t_down) < 0
                    pre_1_t_maintain_corr = 0;
                else
                    pre_1_t_maintain_corr = pre_1_t_total_corr - t_up - t_down;
                end
                
                
                % ----- Injection rate calculation steps -----
                
                pre_1_phi_inj = transpose(0:step_inj:(round(pre_1_t_total_corr + 1)));
                pre_1_m_inj = zeros([length(pre_1_phi_inj) 1]);
                idx = 1;
                
                
                % ----- Injection rate ramp up -----
                pre_1_m_inj_ramp_up = 0;
                
                for i = 1:find(round(pre_1_phi_inj,ceil(log10(1/step_inj))) == t_up)
                    if (pre_1_phi_inj(i) <= t_up) && (pre_1_m_inj_ramp_up <= pre_1_m_f)
                        idx = i;
                        pre_1_m_inj(i) = pre_1_phi_inj(i)/t_up*pre_1_m_inj_norm*pre_1_m_f;
                        pre_1_m_inj_ramp_up = pre_1_m_inj_ramp_up + pre_1_m_inj(i)*step_inj;
                    end
                end
                
                pre_1_m_inj_ramp_up;
                
                % ----- Injection rate maintain max -----
                
                pre_1_m_inj_maintain_max = 0;
                if pre_1_t_maintain_corr ~= 0
                    for i = find(round(pre_1_phi_inj,ceil(log10(1/step_inj))) == t_up):find(round(pre_1_phi_inj,ceil(log10(1/step_inj))) == (t_up + pre_1_t_maintain_corr))
                        if (pre_1_phi_inj(i) <= (t_up + pre_1_t_maintain_corr)) && (pre_1_m_inj_ramp_up + pre_1_m_inj_maintain_max <= pre_1_m_f)
                            idx = i;
                            pre_1_m_inj(i) = pre_1_m_inj_norm*pre_1_m_f;
                            pre_1_m_inj_maintain_max = pre_1_m_inj_maintain_max + pre_1_m_inj(i)*step_inj;
                        end
                    end
                end
                
                pre_1_m_inj_maintain_max;
                
                % ----- Injection rate ramp down -----
                
                pre_1_m_inj_ramp_down = 0;
                if t_down ~= (pre_1_m_f - pre_1_m_inj_ramp_up - pre_1_m_inj_maintain_max)*2/max(pre_1_m_inj)
                    t_down = (pre_1_m_f - pre_1_m_inj_ramp_up - pre_1_m_inj_maintain_max)*2/max(pre_1_m_inj);
                    pre_1_t_total_corr = t_up + pre_1_t_maintain_corr + t_down;
                end
                for i = find(round(pre_1_phi_inj,ceil(log10(1/step_inj))) == (t_up + pre_1_t_maintain_corr)):length(pre_1_phi_inj)
                    if (pre_1_phi_inj(i) <= pre_1_t_total_corr)
                        idx = i;
                        pre_1_m_inj(i) = (pre_1_t_total_corr - pre_1_phi_inj(i))/t_down*pre_1_m_inj_norm*pre_1_m_f;
                        pre_1_m_inj_ramp_down = pre_1_m_inj_ramp_down + pre_1_m_inj(i)*step_inj;
                    end
                end
                
                pre_1_m_inj_ramp_down;
                
                pre_1_m_inj_total = pre_1_m_inj_ramp_up + pre_1_m_inj_maintain_max + pre_1_m_inj_ramp_down;
                
                pre_1_phi_inj = pre_1_phi_inj + pre_1_soi;
                
            end
            
            % ----- Pre-injection 2 ----- %
            
            if pre_2_m_f ~= 0
                pre_2_m_f = pre_2_m_f * 10^-6;
                
                % ----- Injection rate -----
                pre_2_w_inj_max = Cd*sqrt(2*(p_inj - p_cil)/rho);          % Injection velocity, [m/s]
                pre_2_Vol_inj = pre_2_w_inj_max*app.A_inj;                     % Injection rate (volume), [m^3/s]
                pre_2_m_inj_max = pre_2_Vol_inj*rho;                       % Injection rate (mass), [kg/s]
                pre_2_m_inj_deg = pre_2_m_inj_max/(6*n_inj);               % Injection rate (mass/crank angle), [kg/degCA]
                pre_2_m_inj_norm = pre_2_m_inj_deg/pre_2_m_f;              % Injection rate (normalized), [1/degCA]
                
                % ----- Correction of maintain at max rate duration -----
                
                pre_2_t_maintain = pre_2_t_total - t_up - t_down;                % Duration of injection at max rate, [degCA]
                pre_2_t_total_corr = round((2/pre_2_m_inj_norm + t_up + t_down)/2,ceil(log10(1/step_inj)));
                if pre_2_t_total_corr > pre_2_t_total
                    if corrected_param == 0
                        pre_2_m_inj_norm = 2/(2*pre_2_t_total - t_up - t_down);
                        pre_2_t_total_corr = round((2/pre_2_m_inj_norm + t_up + t_down)/2,ceil(log10(1/step_inj)));
                    end
                end
                if (pre_2_t_total_corr - t_up - t_down) < 0
                    pre_2_t_maintain_corr = 0;
                else
                    pre_2_t_maintain_corr = pre_2_t_total_corr - t_up - t_down;
                end
                
                
                % ----- Injection rate calculation steps -----
                
                pre_2_phi_inj = transpose(0:step_inj:(round(pre_2_t_total_corr + 1)));
                pre_2_m_inj = zeros([length(pre_2_phi_inj) 1]);
                idx = 1;
                
                
                % ----- Injection rate ramp up -----
                pre_2_m_inj_ramp_up = 0;
                
                for i = 1:find(round(pre_2_phi_inj,ceil(log10(1/step_inj))) == t_up)
                    if (pre_2_phi_inj(i) <= t_up) && (pre_2_m_inj_ramp_up <= pre_2_m_f)
                        idx = i;
                        pre_2_m_inj(i) = pre_2_phi_inj(i)/t_up*pre_2_m_inj_norm*pre_2_m_f;
                        pre_2_m_inj_ramp_up = pre_2_m_inj_ramp_up + pre_2_m_inj(i)*step_inj;
                    end
                end
                
                pre_2_m_inj_ramp_up;
                
                % ----- Injection rate maintain max -----
                
                pre_2_m_inj_maintain_max = 0;
                if pre_2_t_maintain_corr ~= 0
                    for i = find(round(pre_2_phi_inj,ceil(log10(1/step_inj))) == t_up):find(round(pre_2_phi_inj,ceil(log10(1/step_inj))) == (t_up + pre_2_t_maintain_corr))
                        if (pre_2_phi_inj(i) <= (t_up + pre_2_t_maintain_corr)) && (pre_2_m_inj_ramp_up + pre_2_m_inj_maintain_max <= pre_2_m_f)
                            idx = i;
                            pre_2_m_inj(i) = pre_2_m_inj_norm*pre_2_m_f;
                            pre_2_m_inj_maintain_max = pre_2_m_inj_maintain_max + pre_2_m_inj(i)*step_inj;
                        end
                    end
                end
                
                pre_2_m_inj_maintain_max;
                
                % ----- Injection rate ramp down -----
                
                pre_2_m_inj_ramp_down = 0;
                if t_down ~= (pre_2_m_f - pre_2_m_inj_ramp_up - pre_2_m_inj_maintain_max)*2/max(pre_2_m_inj)
                    t_down = (pre_2_m_f - pre_2_m_inj_ramp_up - pre_2_m_inj_maintain_max)*2/max(pre_2_m_inj)
                    pre_2_t_total_corr = t_up + pre_2_t_maintain_corr + t_down
                end
                for i = find(round(pre_2_phi_inj,ceil(log10(1/step_inj))) == (t_up + pre_2_t_maintain_corr)):length(pre_2_phi_inj)
                    if (pre_2_phi_inj(i) <= pre_2_t_total_corr)
                        idx = i;
                        pre_2_m_inj(i) = (pre_2_t_total_corr - pre_2_phi_inj(i))/t_down*pre_2_m_inj_norm*pre_2_m_f;
                        pre_2_m_inj_ramp_down = pre_2_m_inj_ramp_down + pre_2_m_inj(i)*step_inj;
                    end
                end
                
                pre_2_m_inj_ramp_down;
                
                pre_2_m_inj_total = pre_2_m_inj_ramp_up + pre_2_m_inj_maintain_max + pre_2_m_inj_ramp_down;
                
                pre_2_phi_inj = pre_2_phi_inj + pre_2_soi;
                
            end
            
            % ----- Post-injection 1 ----- %
            
            if post_1_m_f ~= 0
                post_1_m_f = post_1_m_f * 10^-6;
                
                % ----- Injection rate -----
                post_1_w_inj_max = Cd*sqrt(2*(p_inj - p_cil)/rho);          % Injection velocity, [m/s]
                post_1_Vol_inj = post_1_w_inj_max*app.A_inj ;                    % Injection rate (volume), [m^3/s]
                post_1_m_inj_max = post_1_Vol_inj*rho   ;                    % Injection rate (mass), [kg/s]
                post_1_m_inj_deg = post_1_m_inj_max/(6*n_inj)    ;           % Injection rate (mass/crank angle), [kg/degCA]
                post_1_m_inj_norm = post_1_m_inj_deg/post_1_m_f  ;            % Injection rate (normalized), [1/degCA]
                
                % ----- Correction of maintain at max rate duration -----
                
                post_1_t_maintain = post_1_t_total - t_up - t_down;                % Duration of injection at max rate, [degCA]
                post_1_t_total_corr = round((2/post_1_m_inj_norm + t_up + t_down)/2,ceil(log10(1/step_inj)));
                if post_1_t_total_corr > post_1_t_total
                    if corrected_param == 0
                        post_1_m_inj_norm = 2/(2*post_1_t_total - t_up - t_down);
                        post_1_t_total_corr = round((2/post_1_m_inj_norm + t_up + t_down)/2,ceil(log10(1/step_inj)));
                    end
                end
                if (post_1_t_total_corr - t_up - t_down) < 0
                    post_1_t_maintain_corr = 0;
                else
                    post_1_t_maintain_corr = post_1_t_total_corr - t_up - t_down;
                end
                
                
                % ----- Injection rate calculation steps -----
                
                post_1_phi_inj = transpose(0:step_inj:(round(post_1_t_total_corr + 1)));
                post_1_m_inj = zeros([length(post_1_phi_inj) 1]);
                idx = 1;
                
                
                % ----- Injection rate ramp up -----
                post_1_m_inj_ramp_up = 0;
                
                for i = 1:find(round(post_1_phi_inj,ceil(log10(1/step_inj))) == t_up)
                    if (post_1_phi_inj(i) <= t_up) && (post_1_m_inj_ramp_up <= post_1_m_f)
                        idx = i;
                        post_1_m_inj(i) = post_1_phi_inj(i)/t_up*post_1_m_inj_norm*post_1_m_f;
                        post_1_m_inj_ramp_up = post_1_m_inj_ramp_up + post_1_m_inj(i)*step_inj;
                    end
                end
                
                post_1_m_inj_ramp_up;
                
                % ----- Injection rate maintain max -----
                
                post_1_m_inj_maintain_max = 0;
                if post_1_t_maintain_corr ~= 0
                    for i = find(round(post_1_phi_inj,ceil(log10(1/step_inj))) == t_up):find(round(post_1_phi_inj,ceil(log10(1/step_inj))) == (t_up + post_1_t_maintain_corr))
                        if (post_1_phi_inj(i) <= (t_up + post_1_t_maintain_corr)) && (post_1_m_inj_ramp_up + post_1_m_inj_maintain_max <= post_1_m_f)
                            idx = i;
                            post_1_m_inj(i) = post_1_m_inj_norm*post_1_m_f;
                            post_1_m_inj_maintain_max = post_1_m_inj_maintain_max + post_1_m_inj(i)*step_inj;
                        end
                    end
                end
                
                post_1_m_inj_maintain_max;
                
                % ----- Injection rate ramp down -----
                
                post_1_m_inj_ramp_down = 0;
                if t_down ~= (post_1_m_f - post_1_m_inj_ramp_up - post_1_m_inj_maintain_max)*2/max(post_1_m_inj)
                    t_down = (post_1_m_f - post_1_m_inj_ramp_up - post_1_m_inj_maintain_max)*2/max(post_1_m_inj);
                    post_1_t_total_corr = t_up + post_1_t_maintain_corr + t_down;
                end
                for i = find(round(post_1_phi_inj,ceil(log10(1/step_inj))) == (t_up + post_1_t_maintain_corr)):length(post_1_phi_inj)
                    if (post_1_phi_inj(i) <= post_1_t_total_corr)
                        idx = i;
                        post_1_m_inj(i) = (post_1_t_total_corr - post_1_phi_inj(i))/t_down*post_1_m_inj_norm*post_1_m_f;
                        post_1_m_inj_ramp_down = post_1_m_inj_ramp_down + post_1_m_inj(i)*step_inj;
                    end
                end
                
                post_1_m_inj_ramp_down;
                
                post_1_m_inj_total = post_1_m_inj_ramp_up + post_1_m_inj_maintain_max + post_1_m_inj_ramp_down;
                
                post_1_phi_inj = post_1_phi_inj + post_1_soi;
                
            end
            
            % ----- Post-injection 2 ----- %
            
            if post_2_m_f ~= 0
                post_2_m_f = post_2_m_f * 10^-6;
                
                % ----- Injection rate -----
                post_2_w_inj_max = Cd*sqrt(2*(p_inj - p_cil)/rho);          % Injection velocity, [m/s]
                post_2_Vol_inj = post_2_w_inj_max*app.A_inj;                     % Injection rate (volume), [m^3/s]
                post_2_m_inj_max = post_2_Vol_inj*rho;                       % Injection rate (mass), [kg/s]
                post_2_m_inj_deg = post_2_m_inj_max/(6*n_inj);               % Injection rate (mass/crank angle), [kg/degCA]
                post_2_m_inj_norm = post_2_m_inj_deg/post_2_m_f;              % Injection rate (normalized), [1/degCA]
                
                % ----- Correction of maintain at max rate duration -----
                
                post_2_t_maintain = post_2_t_total - t_up - t_down;                % Duration of injection at max rate, [degCA]
                post_2_t_total_corr = round((2/post_2_m_inj_norm + t_up + t_down)/2,ceil(log10(1/step_inj)));
                if post_2_t_total_corr > post_2_t_total
                    if corrected_param == 0
                        post_2_m_inj_norm = 2/(2*post_2_t_total - t_up - t_down);
                        post_2_t_total_corr = round((2/post_2_m_inj_norm + t_up + t_down)/2,ceil(log10(1/step_inj)));
                    end
                end
                if (post_2_t_total_corr - t_up - t_down) < 0
                    post_2_t_maintain_corr = 0;
                else
                    post_2_t_maintain_corr = post_2_t_total_corr - t_up - t_down;
                end
                
                
                % ----- Injection rate calculation steps -----
                
                post_2_phi_inj = transpose(0:step_inj:(round(post_2_t_total_corr + 1)));
                post_2_m_inj = zeros([length(post_2_phi_inj) 1]);
                idx = 1;
                
                
                % ----- Injection rate ramp up -----
                post_2_m_inj_ramp_up = 0;
                
                for i = 1:find(round(post_2_phi_inj,ceil(log10(1/step_inj))) == t_up)
                    if (post_2_phi_inj(i) <= t_up) && (post_2_m_inj_ramp_up <= post_2_m_f)
                        idx = i;
                        post_2_m_inj(i) = post_2_phi_inj(i)/t_up*post_2_m_inj_norm*post_2_m_f;
                        post_2_m_inj_ramp_up = post_2_m_inj_ramp_up + post_2_m_inj(i)*step_inj;
                    end
                end
                
                post_2_m_inj_ramp_up;
                
                % ----- Injection rate maintain max -----
                
                post_2_m_inj_maintain_max = 0;
                if post_2_t_maintain_corr ~= 0
                    for i = find(round(post_2_phi_inj,ceil(log10(1/step_inj))) == t_up):find(round(post_2_phi_inj,ceil(log10(1/step_inj))) == (t_up + post_2_t_maintain_corr))
                        if (post_2_phi_inj(i) <= (t_up + post_2_t_maintain_corr)) && (post_2_m_inj_ramp_up + post_2_m_inj_maintain_max <= post_2_m_f)
                            idx = i;
                            post_2_m_inj(i) = post_2_m_inj_norm*post_2_m_f;
                            post_2_m_inj_maintain_max = post_2_m_inj_maintain_max + post_2_m_inj(i)*step_inj;
                        end
                    end
                end
                
                post_2_m_inj_maintain_max;
                
                % ----- Injection rate ramp down -----
                
                post_2_m_inj_ramp_down = 0;
                if t_down ~= (post_2_m_f - post_2_m_inj_ramp_up - post_2_m_inj_maintain_max)*2/max(post_2_m_inj)
                    t_down = (post_2_m_f - post_2_m_inj_ramp_up - post_2_m_inj_maintain_max)*2/max(post_2_m_inj);
                    post_2_t_total_corr = t_up + post_2_t_maintain_corr + t_down;
                end
                for i = find(round(post_2_phi_inj,ceil(log10(1/step_inj))) == (t_up + post_2_t_maintain_corr)):length(post_2_phi_inj)
                    if (post_2_phi_inj(i) <= post_2_t_total_corr)
                        idx = i;
                        post_2_m_inj(i) = (post_2_t_total_corr - post_2_phi_inj(i))/t_down*post_2_m_inj_norm*post_2_m_f;
                        post_2_m_inj_ramp_down = post_2_m_inj_ramp_down + post_2_m_inj(i)*step_inj;
                    end
                end
                
                post_2_m_inj_ramp_down;
                
                post_2_m_inj_total = post_2_m_inj_ramp_up + post_2_m_inj_maintain_max + post_2_m_inj_ramp_down;
                
                post_2_phi_inj = post_2_phi_inj + post_2_soi;
                
            end
            
            phi_inj = phi_inj + soi;
            m_inj = m_inj * 10^6;
            
            
            cla(app.UIAxes9)
            plot(app.UIAxes9,phi_inj, m_inj,'DisplayName','Injectia principala','lineWidth',1);
            
            title(app.UIAxes9,'Debitul masic injectat')
            xlabel(app.UIAxes9,'Unghiul de rotatie al arborelui cotit [°RAC]')
            ylabel(app.UIAxes9,'Debitul masic injectat [mg/°RAC]')
            hold (app.UIAxes9,"on")
            
            
            if pre_1_m_f ~= 0
                pre_1_m_inj = pre_1_m_inj * 10^6;
                pre_1_m_inj = abs(pre_1_m_inj);
            end
            
            if pre_2_m_f ~= 0
                pre_2_m_inj = pre_2_m_inj * 10^6;
                pre_2_m_inj = abs(pre_2_m_inj);
            end
            
            if post_1_m_f ~= 0
                post_1_m_inj = post_1_m_inj * 10^6;
                post_1_m_inj = abs(post_1_m_inj);
            end
            
            if post_2_m_f ~= 0
                post_2_m_inj = post_2_m_inj * 10^6;
                post_2_m_inj = abs(post_2_m_inj);
            end
            
            if pre_1_m_f ~= 0
                plot(app.UIAxes9,pre_1_phi_inj, pre_1_m_inj,'DisplayName','Pre-injectie 1','lineWidth',1)
            end
            
            if pre_2_m_f ~= 0
                plot(app.UIAxes9,pre_2_phi_inj, pre_2_m_inj,'DisplayName','Pre-injectie 2','lineWidth',1)
            end
            
            if post_1_m_f ~= 0
                plot(app.UIAxes9,post_1_phi_inj, post_1_m_inj,'DisplayName','Post-injectie 1','lineWidth',1)
            end
            
            if post_2_m_f  ~= 0
                plot(app.UIAxes9,post_2_phi_inj, post_2_m_inj,'DisplayName','Post-injectie 2','lineWidth',1)
            end
            
            xline(app.UIAxes9,0,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9,'Displayname','PMS')
            
            hold (app.UIAxes9,"off")
            legend(app.UIAxes9)
        end

        % Button pushed function: AfisarerezultateButton
        function AfisarerezultateButtonPushed(app, event)
            f = figure('Name','Date rezultate in urma calculelor','NumberTitle','off');
            set(f,'Position',[300 150 416 152]);
            dat =  {'Aria de injectie [m^2]', app.A_inj;...
                'Viteza de injectie [m/s]', app.w_inj_max;...
                'Debitul volumic injectat [m^3/s]', app.Vol_inj;...
                'Debitul masic injectat [kg/s]', app.m_inj_max;
                'Debitul masic injectat [kg/°RAC]', app.m_inj_deg;
                'Debitul normalizat [1/°RAC]', app.m_inj_norm;};
            columnname = {'Parametru', 'Valoare'};
            columnformat = {'char', 'numeric', 'char'};
            t = uitable('Units','normalized','Position',...
                [0 0.14 12 0.87], 'Data', dat,...
                'ColumnName', columnname,...
                'ColumnFormat', columnformat,...
                'RowName',[],'ColumnWidth',...
                {316,100});
        end

        % Button pushed function: CalculmodelardereButton
        function CalculmodelardereButtonPushed(app, event)
            
            %initializaere parametrii din fereastra comuna si calcule
            %necesare
            
            D = app.DcmEditField.Value;
            S = app.EditField_3.Value;
            conrod_length = app.EditField_4.Value;
            eps = app.EditField_5.Value;
            n = app.EditField_7.Value;
            lambda = app.EditField_8.Value;
            IVC = app.EditField_9.Value;
            EVO = app.EditField_10.Value;
            LHV = app.EditField_11.Value;
            piston_head_surface = app.EditField_14.Value;
            cyl_wall_surface_TDC = app.EditField_15.Value;
            cyl_head_surface = app.EditField_16.Value;
            R = app.EditField_20.Value;
            Coef_Swirl = app.EditField_21.Value;
            piston_offset = app.EditField_6.Value;
            piston_temp_coef = app.EditField_17.Value;
            cyl_head_temp_coef = app.EditField_18.Value;
            cyl_temp_coef = app.EditField_19.Value;
            
            neg_numb = length(app.crank_angle(app.crank_angle<0));
            
            app.crankshaft_rad = S/2;
            app.lambda_raport = app.crankshaft_rad/conrod_length;
            app.V_s = pi*D^2/4*S  ;
            app.vol_ca = app.V_s/(eps -1);
            
            if piston_offset == 0
                s_phi = app.crankshaft_rad.*(1-cosd(app.crank_angle)+...
                    (app.lambda_raport/4.*(1-cosd(2.*app.crank_angle))));
                app.vol_phi = s_phi*((pi*(D^2))/4)+app.vol_ca;
            end
            
            
            if piston_offset ~= 0
                piston_offset = piston_offset * (10^-1);
                beta = asind(piston_offset/(app.crankshaft_rad + conrod_length));
                s_phi = sqrt((app.crankshaft_rad+conrod_length)^2 - (piston_offset^2)) - ...
                    sqrt((conrod_length^2) - ((piston_offset + app.crankshaft_rad .* ...
                    sind(app.crank_angle - beta)).^2)) - app.crankshaft_rad .* ...
                    cosd(app.crank_angle - beta);
                app.vol_phi = s_phi*((pi*(D^2))/4)+app.vol_ca;
            end
            
            % Calculul vitezelor pistonului
            
            app.piston_vel = 2*(S/100)*n/60               ;
            app.piston_circ_vel = ((D/100/2)*Coef_Swirl*n)/60 ;
            
            evalin('base', 'clc')
            %Calcul model wiebe
            %initializare parametrii ardere
            cla (app.UIAxes10)                               %resetarea graficului MDF
            
            app.soc = app.EditField_88.Value;                %inceputul arderii
            app.durata_arderii=app.EditField_90.Value;       %durata arderii
            
            app.eoc=app.soc+app.durata_arderii;              %calculul sfarsitului arderii
            app.EditField_106.Value=app.eoc;                 %sfarsitul arderii
            
            %Initializare parametrii funciei Wiebe
            app.Wlambda=app.EditField_91.Value;             %Fracțiunea de amestec care arde în faza da ardere rapidă
            app.Wa1=app.a1EditField.Value;                  %Primul parametru de eficienta
            app.Wm1=app.m1EditField.Value;                  %Primul parametru de forma
            app.Wa2=app.a2EditField.Value;                  %Al doilea parametru de eficienta
            app.theta02=app.o2RACEditField.Value;           %Unghiul in grade rac dupa PMS la care incepe arderea lenta
            app.Wm2=app.m2EditField.Value;                  %Al doilea parametru de forma
            theta_o2=app.theta02;                           %Unghiul in grade rac la care incepe arderea rapida
            
            app.Wdelta_theta02=app.RACEditField_2.Value;    %Durata arderii lente
            app.Wdelta_theta01=app.RACEditField.Value;      %Durata arderii rapide
            
            app.theta=zeros;
            app.theta = app.soc:0.1:app.eoc;        %initializare vector unghi arbore cotit soc-->eoc
            
            
            %    Initializare/resetare functii wiebe
            app.MFB= zeros([1 length(app.theta)]);
            app.Xb1= zeros([1 length(app.theta)]);
            app.Xb2= zeros([1 length(app.theta)]);
            
            
            %Calculul functiilor Wiebe in mod separat in functie de metoda
            %selectata
            if(app.Switch.Value=="Double Wiebe")
                
                for i=1:numel(app.theta)
                    %                 disp(app.theta(i))
                    app.Xb1(i) = app.Wlambda *  (1- exp(-app.Wa1 *( ((app.theta(i)-app.soc)/app.Wdelta_theta01) ^ (app.Wm1+1) )) );
                    
                    if(app.theta(i)>theta_o2)
                        app.Xb2(i) = (1-app.Wlambda) * ((1)) * ( 1-exp(-app.Wa2 * ((app.theta(i)-app.theta02)/app.Wdelta_theta02) ^ (app.Wm2+1)) );
                    else
                        app.Xb2(i)=0;
                    end
                end
            else
                
                for i=1:numel(app.theta)
                    %                 disp(app.theta(i))
                    app.Xb1(i) = (1- exp(-app.Wa1 *( ((app.theta(i)-app.soc)/app.Wdelta_theta01) ^ (app.Wm1+1) )) );
                end
            end
            
            
            %Initializare limite de calcul
            %Limite de calcul pentru procesul de comprimare
            lim_comprimare1=find(app.crank_angle==app.crank_angle( (app.crank_angle>(app.EditField_9.Value-0.01)) & (app.crank_angle<(app.EditField_9.Value+0.01)) ),1);
            lim_comprimare2=find(app.crank_angle==app.crank_angle( (app.crank_angle>(360+app.soc-0.01)) & (app.crank_angle<(360+app.soc+0.01)) ),1);
            %Limite de calcul pentru procesul de ardere
            lim_ardere1=find(app.crank_angle==app.crank_angle( (app.crank_angle>(360+app.soc-0.01)) & (app.crank_angle<(360+app.soc+0.01)) ),1);
            lim_ardere2=find(app.crank_angle==app.crank_angle( (app.crank_angle>(360+app.eoc-0.01)) & (app.crank_angle<(360+app.eoc+0.01)) ),1);
            %Limita destindere
            lim_destindere=find(app.crank_angle==app.crank_angle( (app.crank_angle>(app.EditField_10.Value-0.01)) & (app.crank_angle<(app.EditField_10.Value+0.01)) ),1);
                     
            %Insumarea celor doua functii
            app.MFB=app.Xb1 + app.Xb2;  %Xb(theta)
            
            %calcul mfb dan
            app.Wiebe_dan=zeros;
            app.Wiebe_dan_test=zeros;
            app.Wiebe_dan=cumtrapz( app.crank_angle,(app.vit_bruta/(app.rand_ardere*((max(app.cyl_mass) - app.cycle_air_mass_flow) * LHV * 10^3))) );
            app.Wiebe_dan_test=app.Wiebe_dan(lim_ardere1:lim_ardere2);
            kek_test=app.Wiebe_dan_test;
            
            
            %%Calculare si afisare date in tabel%%
            [app.Wiebe_dan_test, index] = unique(app.Wiebe_dan_test);
            combustion_start_value = (app.EditField_107.Value/100) * max(app.Wiebe_dan_test);
            combustion_end_value = (app.EditField_108.Value/100) * max(app.Wiebe_dan_test);
            
            
            Wiebe_combustion_start_angle = interp1(app.Wiebe_dan_test, app.theta(index), combustion_start_value);
            Wiebe_combustion_end_angle = interp1(app.Wiebe_dan_test, app.theta(index), combustion_end_value);
            Wiebe_combustion_duration = Wiebe_combustion_end_angle - Wiebe_combustion_start_angle;
            
            app.Wiebe_dan=zeros;
            app.Wiebe_dan_test=zeros;
            app.Wiebe_dan=cumtrapz( app.crank_angle,(app.vit_bruta/(app.rand_ardere*((max(app.cyl_mass) - app.cycle_air_mass_flow) * LHV * 10^3))) );
            app.Wiebe_dan_test=app.Wiebe_dan(lim_ardere1:lim_ardere2);


            val_lim_start=[ strcat(num2str(app.EditField_107.Value)) '% MFB'];
            val_lim_end=[ strcat(num2str(app.EditField_108.Value)) '% MFB'];
            dat =  {'Durata procesului de ardere', Wiebe_combustion_duration;...
                val_lim_start, Wiebe_combustion_start_angle;...
                val_lim_end,  Wiebe_combustion_end_angle;
                };
            app.UITable2.Data=dat;
            
            %Calcul caracteristica analiza principiul I pt wiebe
            app.validare_caracteristica=app.cum_gross_heat_release(lim_ardere1:lim_ardere2);
            
            %%Export in workspace valori pentru unghi si mfb dan
            evalin('base', 'clear Theta')
            evalin('base', 'clear MFB_Dan')
            evalin('base', 'clear lambda')
            evalin('base', 'clear a1')
            evalin('base', 'clear a2')
            evalin('base', 'clear m1')
            evalin('base', 'clear m2')
            evalin('base', 'clear t1')
            evalin('base', 'clear t2')
            evalin('base', 'clear dt1')
            evalin('base', 'clear dt2')
            evalin('base', 'clear MFBWiebe')
            evalin('base', 'clear MFBWiebe1')
            evalin('base', 'clear MFBWiebe2')
            
            
            assignin('base','Theta',app.theta')
            assignin('base','MFB_Dan',app.Wiebe_dan_test')
            assignin('base','lambda',app.Wlambda)
            assignin('base','a1',app.Wa1)
            assignin('base','a2',app.Wa2)
            assignin('base','m1',app.Wm1)
            assignin('base','m2',app.Wm2)
            assignin('base','t1',app.soc)
            assignin('base','t2',app.theta02)
            assignin('base','dt1',app.Wdelta_theta01)
            assignin('base','MFB',kek_test')
            assignin('base','MFBWiebe',app.MFB')
            assignin('base','MFBWiebe1',app.Xb1')
            assignin('base','MFBWiebe2',app.Xb2')
            
            %derivare folosind functia gradient - o derivare mai precisa
            dtheta=app.theta(2)-app.theta(1);
            
            if(app.Switch.Value=="Double Wiebe")
                app.dMFB=zeros([1 length(app.theta)]); %initializare vector
                app.dMFB=gradient(app.MFB,dtheta);     %Derivata MFB/theta
                
                app.dXb1=zeros([1 length(app.theta)]);
                app.dXb2=zeros([1 length(app.theta)]);
                
                app.dXb1=gradient(app.Xb1,dtheta);     %Derivata primei functii wiebe
                app.dXb2=gradient(app.Xb2,dtheta);     %Derivata xb2
            else
                app.dMFB=zeros([1 length(app.theta)]); %initializare vector
                app.dMFB=gradient(app.MFB,dtheta);     %Derivata MFB/theta
            end
            
            %Calculul randamentului arderii
            app.rand_ardereW=0.95*min([1 1.2*lambda-0.2]);
            app.rand_ardereW=0.8494; %randament provizoriu
            
            rap_echiv=1/lambda; %raportul de echivalenta
            afr=app.EditField_109.Value;
            
            %calculul caldurii ...mf*hi heat input
            
            masa_combustibil= app.DcmEditField_3.Value + ...
                app.EditField_33.Value + ...
                app.EditField_36.Value + ...
                app.EditField_39.Value + ...
                app.EditField_42.Value; %[mg]
            
            masa_amestec=masa_combustibil*(lambda*afr+1)*10^-6; %masa de amestec in kg
            
            app.mf_qlhv=zeros;
            app.mf_qlhv= (LHV*10^3) *(masa_combustibil*10^-6);
            
            if(app.Switch.Value=="Double Wiebe")
                %derivata Qn in fct de unghiul arborelui cotit
                app.DQbrut=zeros([1 length(app.theta)]);
                app.DQbrut1=zeros([1 length(app.theta)]);
                app.DQbrut2=zeros([1 length(app.theta)]);
                
                %viteza bruta de degajare a caldurii
                app.DQbrut1=app.dXb1 .*((app.mf_qlhv)*app.rand_ardereW);
                app.DQbrut2=app.dXb2 .*((app.mf_qlhv)*app.rand_ardereW);
                % app.DQbrut=app.dXb1+app.dXb2;
                app.DQbrut=app.dMFB .*((app.mf_qlhv)*app.rand_ardereW);
                
                %Qn-caracteristica bruta de degajare a caldurii
                app.Qbrut1=cumtrapz(app.theta,app.DQbrut1);
                app.Qbrut2=cumtrapz(app.theta,app.DQbrut2);
                app.Qbrut=app.Qbrut1+app.Qbrut2;
                %             app.Qbrut=cumtrapz(app.theta,app.DQbrut);
            else
                app.DQbrut=zeros([1 length(app.theta)]);
                app.DQbrut=app.dMFB .*((app.mf_qlhv)*app.rand_ardereW);
                app.Qbrut=cumtrapz(app.theta,app.DQbrut);
            end

            
            gamma_comprimare=(log10( app.filtered_pressure(lim_comprimare2)/app.filtered_pressure(lim_comprimare1) ))...
                /(log10(app.vol_phi(lim_comprimare1)/app.vol_phi(lim_comprimare2)));
            gamma_destindere=(log10( app.filtered_pressure(lim_destindere)/app.filtered_pressure(lim_ardere2+200) ))...
                /(log10(app.vol_phi(lim_ardere2+200)/app.vol_phi(lim_destindere)));
            gamma_ardere=(gamma_comprimare+gamma_destindere)/2;
            display(gamma_comprimare)
            display(gamma_destindere)
            display(gamma_ardere)
            %calcul temperaturi piston,cilindru,chiulasa
            piston_temp_degC = (200 + 0.045*n) * piston_temp_coef;
            cyl_head_temp_degC = (200 + 0.045*n - 40) * cyl_head_temp_coef;
            cyl_wall_temp_TDC_degC = (200 + 0.045*n - 80) * cyl_temp_coef;
            cyl_wall_temp_BDC_degC = (200 + 0.045*n - 160) * cyl_temp_coef;
            
            app.piston_temp = 273.15 + piston_temp_degC;
            app.cyl_head_temp = 273.15 + cyl_head_temp_degC;
            app.cyl_wall_temp_TDC = 273.15 + cyl_wall_temp_TDC_degC;
            app.cyl_wall_temp_BDC = 273.15 + cyl_wall_temp_BDC_degC;


            %Calculul p(i)-presiunea la *Spark timimg*
            app.Wpress = zeros([1 length(app.crank_angle)]);            %initializare vector presiune-fired
            
            
            app.Wpress_motored = zeros([1 length(app.crank_angle)]);    %initializare vector presiune-motored
            app.Wpress_motored(1)=app.EditField_13.Value*10^-5;
            
            app.Wtemp=zeros([1 length(app.crank_angle)]);               %initializare vector temperatura


            dV=gradient(app.vol_phi,dtheta);  %dV/d(theta)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%De aici se lucreaza cu unghiul arborelui cotit 0->719-720%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            %Calculul presiunii, a presiunii motored,a temperaturii, si a
            %schimbului de caldura
            %initializare vectori
            heat_trans_coef_rad=zeros;
            if(app.Switch.Value=="Double Wiebe")
                t1_Woschni=zeros([1 length(app.theta)]);
                t2_Woschni=zeros([1 length(app.theta)]);
                app.heat_trans_coef=zeros([1 length(app.theta)]);
                app.Wiebe_piston_heat_transfer=zeros([1 length(app.theta)]);
                app.Wiebe_cyl_heat_transfer=zeros([1 length(app.theta)]);
                app.Wiebe_cyl_head_heat_transfer=zeros([1 length(app.theta)]);
                app.flux_termic_Wiebe=zeros([1 length(app.theta)]);
                app.dqnet=zeros([1 length(app.theta)]);
                de_scazut=zeros([1 length(app.theta)]);
                app.dqnet1=zeros([1 length(app.theta)]);
                app.dqnet2=zeros([1 length(app.theta)]);
            else
                t1_Woschni=zeros([1 length(app.theta)]);
                t2_Woschni=zeros([1 length(app.theta)]);
                app.heat_trans_coef=zeros([1 length(app.theta)]);
                app.Wiebe_piston_heat_transfer=zeros([1 length(app.theta)]);
                app.Wiebe_cyl_heat_transfer=zeros([1 length(app.theta)]);
                app.Wiebe_cyl_head_heat_transfer=zeros([1 length(app.theta)]);
                app.flux_termic_Wiebe=zeros([1 length(app.theta)]);
                app.dqnet=zeros([1 length(app.theta)]);
            end
            gamma_u=zeros([1 length(app.theta)]);
            gamma_b=zeros([1 length(app.theta)]);
            gammaW=zeros([1 length(app.theta)]);
            gammaW_const=app.EditField_105.Value; %specific heat ratio
            
            %Calculul coeficientilor Woschni
            C1_Woschni = 2.28 + 0.308 * (app.piston_circ_vel / app.piston_vel) ;
            C2_Woschni = 0.00324;
            
            for i=1:numel(app.crank_angle)
                
                if(i<lim_comprimare1)  %if admisie
                    
                    app.Wpress(i)=app.filtered_pressure(i); %presiune fired
                    
                    app.Wpress_motored(i)=app.pressure_motored(i); %Presiune motored
                    
                    app.Wtemp(i)= app.IVC_temp;
                    
                    %end if admisie
                    
                elseif(i>=lim_comprimare1 && i<lim_comprimare2)    %if comprimare

                    app. Wpress(i)=app.filtered_pressure(i);
                    
                    app.Wpress_motored(i)=app.Wpress_motored(i-1) * (app.vol_phi(i-1)/app.vol_phi(i))^gamma_comprimare; %presiune motored
                    
                    app.Wtemp(i)= (app.Wpress(i)*10^5*app.vol_phi(i)*10^-6 )/(R*masa_amestec);
                    
                    if (i==lim_comprimare1)  %presiunea,temperatura si volumul in momentul inchiderii supapelor de admisie
                        app.ivc_pres=app.Wpress(i);
                        app.ivc_temp=app.Wtemp(i);
                        app.ivc_vol=app.vol_phi(i);
                        display(app.ivc_vol);
                        display(app.ivc_temp);
                        display(app.ivc_pres);
                    end
                    %end if comprimare
                elseif(i>= lim_ardere1 && i<= lim_ardere2)   %if ardere
                    
                    app.Wpress_motored(i)=app.Wpress_motored(i-1) * (app.vol_phi(i-1)/app.vol_phi(i))^gammaW_const ;
                    
                    %initializarea presiunii pentru pasul de calcul
                    app. Wpress(i)=app.filtered_pressure(i);
                    
                    %calculul temperaturii pentru pasul de calcul
                    app.Wtemp(i)= (app.Wpress(i)*10^5*app.vol_phi(i)*10^-6 )/(R*masa_amestec);
                    %Calculul temperaturii pentru pasul de calcul
                    
                    %Calculul specific heat ratio
                    %                      gamma_u(i-lim_ardere1+1)=1.464202464...%a1
                    %                              +(-0.000150666*app.Wtemp(i))... %a2*T
                    %                              +(-7.34852e-08*app.Wtemp(i)^2)...%a3*T^2
                    %                              +(1.55726E-10*app.Wtemp(i)^3)...%a4*T^3
                    %                              +(-7.6951E-14*app.Wtemp(i)^4)...%a5*T^4
                    %                              +(1.19535E-17*app.Wtemp(i)^5)...%a6*T^5
                    %                              +(-0.063115275/lambda);%a7*lambda
                    %                      gamma_b(i-lim_ardere1+1)=1.498119965...%b1
                    %                              +(-0.00011303*app.Wtemp(i))... %b2*T
                    %                              +(-0.26688898/lambda)...%b3/lambda
                    %                              +(4.03642E-08*app.Wtemp(i)^2)...%b4*T^2
                    %                              +(0.273428364/(lambda^2))...%b5/Lambda^2
                    %                              +((5.7462E-05*app.Wtemp(i))/lambda)...%(b6*T)/Lambda
                    %                              +(-7.2026E-12*app.Wtemp(i)^3)...%b7*T^3
                    %                              +(-0.08218813/(lambda^3))...%b8/Lambda^3
                    %                              +((1.3029E-05*app.Wtemp(i))/(lambda^2))...%(b9*T)/Lambda^2
                    %                              +((2.35732E-08*app.Wtemp(i)^2)/lambda);%b10*T^2/Lambda
                    %                      gammaW(i-lim_ardere1+1)= (app.MFB(i-lim_ardere1+1)*gamma_b(i-lim_ardere1+1)) +...
                    %                                 (1-app.MFB(i-lim_ardere1+1))*gamma_u(i-lim_ardere1+1);
                    
                    %calculul specific heat ratio
                    %calcul coeficient transfer de caldura
                    if(strcmp(app.Transfer.Value,'Woschni 1978'))
                        %Coeficientul de transfer termic Woschni 1978 (rupt in 2 termeni)
                        %calculul Coeficientului Woschni
                        t1_Woschni(i-lim_ardere1+1) = 130 * ((D/100)^(-0.2)) * (app.Wpress(i) .^ 0.8) * ...
                            (app.Wtemp(i) ^ (-0.53));
                        
                        t2_Woschni(i-lim_ardere1+1) = (C1_Woschni * app.piston_vel + C2_Woschni * ((max(app.vol_phi)...
                            * app.ivc_temp) / (app.ivc_pres * app.ivc_vol) .* (app.Wpress(i) ...
                            - app.Wpress_motored(i)))) .^ 0.8;
                        
                        
                        app.heat_trans_coef(i-lim_ardere1+1) = t1_Woschni(i-lim_ardere1+1) * t2_Woschni(i-lim_ardere1+1);
                        %calculul Coeficientului Woschni
                    elseif(strcmp(app.Transfer.Value,'Annand'))
                        %calcul coef cu Annand
                        %!!!!componenta radiatie
                        densitate(i-lim_ardere1+1)=masa_amestec/(app.vol_phi(i)*10^-6);
                        Re=(densitate(i-lim_ardere1+1)*app.piston_vel*(D/100))/(100*10^-6); %100*10^-6      7*10^5
                        app.heat_trans_coef(i-lim_ardere1+1)=app.EditField_110.Value*(0.15/(D/100))*(Re^0.7);
                        heat_trans_coef_rad=33*10^-8;
                    elseif(strcmp(app.Transfer.Value,'Hohenberg'))
                        %calcul coef cu Hohenberg
                        app.heat_trans_coef(i-lim_ardere1+1) = 130 * ((app.vol_phi(i)*10^-6)^-0.06) * ( (app.Wpress(i)).^0.8)...
                            * (app.Wtemp(i).^-0.4)...
                            * (1.4+app.piston_vel)^0.8;
                    elseif(strcmp(app.Transfer.Value,'Eichelberg'))
                        %calcul coef cu Eichelberg
                        app.heat_trans_coef(i-lim_ardere1+1) = (7.67*10^-3) * (app.piston_vel^(1/3))...
                            *(((app.Wpress(i)*10^5)*app.Wtemp(i)).^(1/2));
                    elseif(strcmp(app.Transfer.Value,'Fara schimb de caldura'))
                        app.heat_trans_coef(i-lim_ardere1+1)=0;
                        
                    end
                    
                    
                    %calculul schimbului de caldura cu peretii
                    % Pierderile de caldura prin piston, [W]
                    
                    app.Wiebe_piston_heat_transfer(i-lim_ardere1+1) = -(piston_head_surface / 10^6) * app.heat_trans_coef(i-lim_ardere1+1) * ...
                        (app.Wtemp(i) - app.piston_temp);
                    app.Wiebe_piston_heat_transfer(i-lim_ardere1+1)=app.Wiebe_piston_heat_transfer(i-lim_ardere1+1)-...
                        (piston_head_surface / 10^6) * heat_trans_coef_rad * ...
                        (app.Wtemp(i)^4 - app.piston_temp^4);
                    % Pierderile de caldura prin cilindru, [W]
                    
                    app.Wiebe_cyl_heat_transfer(i-lim_ardere1+1) = -(cyl_wall_surface_TDC + pi * D / 100 * ...
                        (app.crankshaft_rad + conrod_length - app.crankshaft_rad ...
                        * cosd(app.crank_angle(i)) - conrod_length * sqrt ...
                        ( 1 - (app.crankshaft_rad / conrod_length * ...
                        sind(app.crank_angle(i))) ^ 2) /  10^6 *...
                        app.heat_trans_coef(i-lim_ardere1+1) * (app.Wtemp(i) - (app.cyl_wall_temp_TDC +...
                        app.cyl_wall_temp_BDC)/2)));
                    
                    app.Wiebe_cyl_heat_transfer(i-lim_ardere1+1)=app.Wiebe_cyl_heat_transfer(i-lim_ardere1+1)-...
                        (cyl_wall_surface_TDC + pi * D / 100 * ...
                        (app.crankshaft_rad + conrod_length - app.crankshaft_rad ...
                        * cosd(app.crank_angle(i)) - conrod_length * sqrt ...
                        ( 1 - (app.crankshaft_rad / conrod_length * ...
                        sind(app.crank_angle(i))) ^ 2) /  10^6 *...
                        heat_trans_coef_rad * (app.Wtemp(i)^4 - ((app.cyl_wall_temp_TDC +...
                        app.cyl_wall_temp_BDC)^4)/2)));
                    % Pierderile de caldura prin chiulasa, [W]
                    
                    app.Wiebe_cyl_head_heat_transfer(i-lim_ardere1+1) = -(cyl_head_surface)/10^6*app.heat_trans_coef(i-lim_ardere1+1)...
                        *(app.Wtemp(i) - app.cyl_head_temp);
                    
                    app.Wiebe_cyl_head_heat_transfer(i-lim_ardere1+1)=app.Wiebe_cyl_head_heat_transfer(i-lim_ardere1+1)-...
                        (cyl_head_surface)/10^6*heat_trans_coef_rad...
                        *(app.Wtemp(i)^4 - app.cyl_head_temp^4);
                    % Fluxul termic prin peretii cilindrului, [kW]
                    
                    app.flux_termic_Wiebe(i-lim_ardere1+1) = -(app.Wiebe_piston_heat_transfer(i-lim_ardere1+1) +...
                        app.Wiebe_cyl_heat_transfer(i-lim_ardere1+1) + ...
                        app.Wiebe_cyl_head_heat_transfer(i-lim_ardere1+1) )/1000;


                    if(app.Switch.Value=="Double Wiebe")
                        %dqnet
                        app.dqnet(i-lim_ardere1+1)=(app.DQbrut(i-lim_ardere1+1)- (app.flux_termic_Wiebe(i-lim_ardere1+1)*1000) / (6*n) );
                        %calcule pt dqnet pt fiecare functie in parte
                        de_scazut(i-lim_ardere1+1)=app.DQbrut1(i-lim_ardere1+1)/app.DQbrut(i-lim_ardere1+1);
                        app.dqnet1(i-lim_ardere1+1)=app.DQbrut1(i-lim_ardere1+1)-de_scazut(i-lim_ardere1+1)*((app.flux_termic_Wiebe(i-lim_ardere1+1)*1000) / (6*n));
                        app.dqnet2(i-lim_ardere1+1)=app.DQbrut2(i-lim_ardere1+1)-(1-de_scazut(i-lim_ardere1+1))*((app.flux_termic_Wiebe(i-lim_ardere1+1)*1000) / (6*n));
                    else
                        app.dqnet(i-lim_ardere1+1)=(app.DQbrut(i-lim_ardere1+1)- (app.flux_termic_Wiebe(i-lim_ardere1+1)*1000) / (6*n) );
                    end
                    %calculul presiunii dupa pierderile de caldura
                    app.Wpress(i)= ( app.Wpress(i-1) + ...
                        ( ( ( (gammaW_const-1)/(app.vol_phi(i)*10^-6)) * app.dqnet(i-lim_ardere1+1) ) - ...
                        ( gammaW_const * (app.Wpress(i-1)*10^5)/(app.vol_phi(i)*10^-6)) *(dV(i)*10^-6) ) *(dtheta/10^5) );
                    
                    %calculul temperaturii dupa pierderile de caldura
                    app.Wtemp(i)= (app.Wpress(i)*10^5*app.vol_phi(i)*10^-6 )/(R*masa_amestec);


                    %end if ardere
                elseif (i>lim_ardere2 && i < lim_destindere) %de la end of combustion pana la deschiderea supapelor de evacuare
                    
                    app.Wpress_motored(i)=app.Wpress_motored(i-1) * (app.vol_phi(i-1)/app.vol_phi(i))^gamma_destindere ; %presiune motored
                    
                    app.Wpress(i)=app.Wpress(i-1) * (app.vol_phi(i-1)/app.vol_phi(i))^gamma_destindere;
                    
                    app.Wtemp(i)= (app.Wpress(i)*10^5*app.vol_phi(i)*10^-6 )/(R*masa_amestec);
                    %end if deschiderea supapelor de evacuare
                elseif (i>=lim_destindere)   %de la DSE pana la rest
                    app.Wpress_motored(i)=app.pressure_motored(i); %presiune motored;
                    
                    app.Wpress(i)=app.filtered_pressure(i);
                    
                    app.Wtemp(i)= (app.Wpress(i)*10^5*app.vol_phi(i)*10^-6 )/(R*masa_amestec);
                    %end if final
                end%end if procese
                
            end %end for procese
            
            %gradientul de presiune
            app.Wpress_gradient=gradient(app.Wpress,dtheta);
            %caldura neta
            qnet=cumtrapz(app.theta,app.dqnet);
            %calculul logv logp
            app.Wlogp = log10(app.Wpress);
            Wlogvol = log10(app.vol_phi);
            app.Wlogv_vmax=log10(app.vol_phi/max(app.vol_phi));
            
            
            app.ahhrW=zeros([1 length(app.theta)]);
            dQht=gradient(app.flux_termic_Wiebe,dtheta);
            %caracteristica neta de degajare a caldurii
            Qht=cumtrapz(app.theta,-app.flux_termic_Wiebe);
            %app.ahhrW=app.Qbrut + flux_termic_Wiebe;
            app.ahhrW=qnet;
            
            %viteza neta de degajare a caldurii
            app.viteza_neta=zeros([1 length(app.theta)]);
            %   app.viteza_neta=gradient(app.ahhrW,dtheta);
            app.viteza_neta=app.dqnet;
            
            %%%%%%%%%%%%%GRAFICE!
            %grafic presiune,flux,coef
            cla(app.UIAxes17,'reset')
            yyaxis(app.UIAxes17,'left')
            app.UIAxes17.YColor="k";
            plot(app.UIAxes17,app.theta,-app.flux_termic_Wiebe,'DisplayName','Flux termic','lineWidth',1)
            app.UIAxes17.YColor="k";
            hold(app.UIAxes17,'on')
            ylabel(app.UIAxes17,'Flux termic  [kW], Presiune [bar]')
            xlabel(app.UIAxes17,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            app.press_test=app.Wpress(lim_ardere1:lim_ardere2);
            plot(app.UIAxes17,app.theta,app.press_test,'DisplayName','Presiunea','lineWidth',1,'Color','b',"LineStyle","-")
            hold(app.UIAxes17,'off')
            
            
            yyaxis(app.UIAxes17,'right')
            app.UIAxes17.YColor="k";
            plot(app.UIAxes17,app.theta,app.heat_trans_coef,'DisplayName','Coeficientul de transfer termic','lineWidth',1)
            ylabel(app.UIAxes17,'Coeficientul de tranfer termic, [W/m^2*K]')
            lgnd=legend(app.UIAxes17,'Location','northeast');
            set(lgnd,'Color','none','Box',"off")
            hold(app.UIAxes17,"off")
            
            %Grafice
            %generarea graficului MDF [%]
            cla(app.UIAxes10,'reset')
            if(app.Switch.Value=="Double Wiebe")
                plot(app.UIAxes10,app.theta,app.Xb1,'DisplayName','Prima funcție Wiebe','lineWidth',1,'Color',[0 0.4470 0.7410])
                hold (app.UIAxes10,'on')
                plot(app.UIAxes10,app.theta,app.Xb2,'DisplayName','A doua funcție Wiebe','lineWidth',1,'Color',[0.8500 0.3250 0.0980])
                plot(app.UIAxes10,app.theta, app.MFB,'DisplayName','Fracțiunea de amestec arsă','lineWidth',1,'Color',[0.6350 0.0780 0.1840])
            else
                plot(app.UIAxes10,app.theta, app.MFB,'DisplayName','Fracțiunea de amestec arsă','lineWidth',1,'Color',[0.6350 0.0780 0.1840])
                hold (app.UIAxes10,'on')
            end
            if(app.Wmarimi.Value==1)
                plot(app.UIAxes10,app.theta, app.Wiebe_dan_test,'DisplayName','Fracțiunea de amestec arsă măsurata','lineWidth',1,'Color',[0.4940 0.1840 0.5560])
                %test wiebe dan
            end
            xlabel(app.UIAxes10,'Unghiul de rotatie al arborelui cotit dupa PMS, [°RAC]')
            ylabel(app.UIAxes10,'Fractiunea de amestec arsă, [%]')
            xlim (app.UIAxes10,[min(app.theta)-5 max(app.theta)])
            ylim(app.UIAxes10,[0 1])
            xline(app.UIAxes10,0,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9,'DisplayName','PMS')
            %             xticks(app.UIAxes10,app.soc:5:max(app.theta))
            hold (app.UIAxes10,'off')
            lgnd=legend(app.UIAxes10,'Location','northwest');
            set(lgnd,'Color','none','Box',"off")
            hold (app.UIAxes10,'off')
            
            %generarea graficului AHHR [j]
            cla(app.UIAxes11,"reset")
            
            %daca nu eok schimb Qn cu app.ahhr
            app.umplere= [app.theta, fliplr(app.theta)];
            app.inBetwen=[app.Qbrut fliplr(app.ahhrW)];
            
            %pierderile de caldura
            fill(app.UIAxes11,app.umplere,app.inBetwen,'r','DisplayName','Pierderi','FaceAlpha',0.3)
            %pierderile de caldura
            
            hold (app.UIAxes11,'on')
            plot(app.UIAxes11,app.theta,app.Qbrut,'DisplayName','Caracteristica bruta de degajare a caldurii','lineWidth',1)
            plot(app.UIAxes11,app.theta,app.ahhrW,'DisplayName','Caracteristica neta de degajare a caldurii','lineWidth',2)
            %linia ineficienta
            plot(app.UIAxes11,[max(app.theta)-10 max(app.theta)-10],[app.Qbrut(app.theta==max(app.theta)-10) app.mf_qlhv],'DisplayName','Ineficienta','lineWidth',3)
            %linia ineficienta
            xlabel(app.UIAxes11,'Unghiul de rotatie al arborelui cotit dupa PMS, [°RAC]')
            ylabel(app.UIAxes11,'Degajare de caldura , [j]')
            xlim (app.UIAxes11,[min(app.theta)-10 max(app.theta)])
            ylim(app.UIAxes11,[-10 app.mf_qlhv+200])
            xline(app.UIAxes11,0,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9,'DisplayName','PMS')
            yline(app.UIAxes11,app.mf_qlhv,'-','Color','b','lineWidth', 0.9,'DisplayName','Cantitatea de caldura introdusa in cilindru')
            %             xticks(app.UIAxes11,app.soc:10:max(app.theta))
            %%caract validare
            if(app.Wmarimi.Value==1)
                plot(app.UIAxes11,app.theta,app.validare_caracteristica,'DisplayName','Caracteristica masurata','lineWidth',1)
            end
            lgnd=legend(app.UIAxes11,'Location','southeast');
            set(lgnd,'Color','none','Box',"off")
            hold (app.UIAxes11,'off')
            
            %Generarea graficului  presiune-presiune motored-temperatura
            cla(app.UIAxes13,"reset")
            yyaxis(app.UIAxes13,'left')
            app.UIAxes13.YColor="k";
            plot(app.UIAxes13,app.crank_angle,app.Wpress,'DisplayName','Presiunea calculata','lineWidth',1,'Color','r')
            hold(app.UIAxes13,"on")
            %pt validare mai usoara
            if(app.Wmarimi.Value==1)
                plot(app.UIAxes13,app.crank_angle,app.filtered_pressure,'DisplayName','Presiunea masurata','lineWidth',1,'Color','r',"LineStyle",":")
                plot(app.UIAxes13,app.crank_angle,app.pressure_motored,"Color",[0 0.4470 0.7410],'LineStyle',"-.",'DisplayName',"Presiunea motor antrenat masurata")
            end
            %pt validare mai usoara
            plot(app.UIAxes13,app.crank_angle,app.Wpress_motored,'DisplayName','Presiunea motor antrenat','lineWidth',1,'LineStyle','--',"Color","b")
            xlabel(app.UIAxes13,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylabel(app.UIAxes13,'Presiune, [bar]')
            ylim(app.UIAxes13,[0 max([app.Wpress app.filtered_pressure])+20])
            xlim(app.UIAxes13,[360+app.soc-50 360+app.eoc])
            xline(app.UIAxes13,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9,'DisplayName','PMS')
            hold(app.UIAxes13,"off")
            
            
            yyaxis(app.UIAxes13,'right')
            app.UIAxes13.YColor="k";
            plot(app.UIAxes13,app.crank_angle,app.Wtemp,'DisplayName','Temperatura','lineWidth',1,"Color",[0.8500 0.3250 0.0980])
            ylabel(app.UIAxes13,'Temperatura, [K]')
            ylim(app.UIAxes13,[0 max(app.Wtemp)+300])
            lgnd=legend(app.UIAxes13,'Location','northwest');
            set(lgnd,'Color','none','Box',"off")
            hold(app.UIAxes13,"off")
            yyaxis(app.UIAxes13,'left')
            
            
            %Generarea graficului %RATE OF HEAT RELEASE%
            cla(app.UIAxes14,"reset")
            app.vitnet_validare=app.vit_neta(lim_ardere1:lim_ardere2);
            if(app.Wmarimi.Value==1)
                plot(app.UIAxes14,app.theta,app.vitnet_validare,'DisplayName','Viteza net de degajare a caldurii măsurata','lineWidth',1,'LineStyle','-','Color',[0.4940 0.1840 0.5560])
            end
            hold(app.UIAxes14,"on")
            plot(app.UIAxes14,app.theta,app.viteza_neta,'DisplayName','Viteza net de degajare a caldurii','lineWidth',1,'Color',[0.6350 0.0780 0.1840])
            if(app.Switch.Value=="Double Wiebe")
                plot(app.UIAxes14,app.theta,app.dqnet1,'DisplayName','Viteza net de degajare a caldurii W1','lineWidth',1,'LineStyle','-.','Color',[0 0.4470 0.7410])
                plot(app.UIAxes14,app.theta,app.dqnet2,'DisplayName','Viteza net de degajare a caldurii W2','lineWidth',1,'LineStyle','-.','Color',[0.8500 0.3250 0.0980])
            end
            xlabel(app.UIAxes14,'Unghiul de rotatie al arborelui cotit dupa PMS, [°RAC]')
            ylabel(app.UIAxes14,'Viteza de degajare a caldurii, [j/grad]')
            xline(app.UIAxes14,0,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9,'DisplayName','PMS')
            lgnd=legend(app.UIAxes14);
            set(lgnd,'Color','none','Box',"off")
            ylim(app.UIAxes14,[-10 max([app.vitnet_validare app.viteza_neta])+30])
            %ylim(app.UIAxes14,[0 max(app.vitnet_validare)+0.2])
            hold(app.UIAxes14,"off")
            %ylim de la -10 la max+catva


            %grafic gradient presiune
            cla(app.UIAxes18,"reset")
            yyaxis(app.UIAxes18,'left')
            app.UIAxes18.YColor="k";
            plot(app.UIAxes18,app.crank_angle,app.Wpress,'DisplayName','Presiunea','lineWidth',1,"Color","b")
            hold(app.UIAxes18,"on")
            %%%%%%%%Validare%%%%%%%%
            if(app.Wmarimi.Value==1)
                plot(app.UIAxes18,app.crank_angle,app.filtered_pressure,'DisplayName','Presiunea măsurata','lineWidth',1,"LineStyle","--","Color",[0 0.4470 0.7410])
            end
            %%%%%%%%Validare%%%%%%%%
            ylabel(app.UIAxes18,'Presiunea, [bar]')
            xlabel(app.UIAxes18,'Unghiul de rotatie al arborelui cotit, [°RAC]')
            ylim(app.UIAxes18,[0 max([app.Wpress app.filtered_pressure])+15])
            app.UIAxes18.YColor="k";
            hold(app.UIAxes18,"off")
            
            yyaxis(app.UIAxes18,'right')
            app.UIAxes13.YColor="k";
            plot(app.UIAxes18,app.crank_angle,app.Wpress_gradient,'DisplayName','Gradientul de presiune','lineWidth',1,'Color',[0.9290 0.6940 0.1250],'LineStyle',"-")
            hold(app.UIAxes18,"on")
            if(app.Wmarimi.Value==1)
                plot(app.UIAxes18,app.crank_angle,app.dP,'DisplayName','Gradientul de presiune masurat','lineWidth',1,'Color',[0.8500 0.3250 0.0980],'LineStyle',"--")
            end
            ylabel(app.UIAxes18,'Gradientul de presiune, [bar/rac]')
            ylim(app.UIAxes18,[min([app.Wpress_gradient app.dP])-3 max([app.Wpress_gradient app.dP])+3])
            legend(app.UIAxes18,'Location','northwest')
            hold(app.UIAxes18,"off")
            xlim(app.UIAxes18,[360-30 360+40])
            app.UIAxes18.YColor="k";
            hold(app.UIAxes18,"off")
            lgnd=legend(app.UIAxes18);
            set(lgnd,'Color','none','Box',"off")
            
            %grafic p-v
            cla(app.UIAxes19,"reset")
            plot(app.UIAxes19,app.vol_phi,app.Wpress,'DisplayName','Presiunea','lineWidth',1,"Color",'b')
            hold(app.UIAxes19,"on")
            if(app.Wmarimi.Value==1)
                plot(app.UIAxes19,app.vol_phi,app.filtered_pressure,'DisplayName','Presiunea măsurata','lineWidth',1,"Color",[0 0.4470 0.7410],"LineStyle","--")
            end
            plot(app.UIAxes19,app.vol_phi,app.Wpress_motored,'DisplayName','Presiunea pe ciclul fara ardere','lineWidth',1,'LineStyle',"--")
            ylabel(app.UIAxes19,'Presiunea, [bar]');
            xlabel(app.UIAxes19,'Volumul cilindrului, [cm3]')
            xticks(app.UIAxes19,0:50:max(app.vol_phi))
            ylim(app.UIAxes19,[0 max([app.Wpress app.filtered_pressure])+5])
            hold(app.UIAxes19,'off');
            lgnd=legend(app.UIAxes19);
            set(lgnd,'Color','none','Box',"off")
            
            %grafic logp
            cla(app.UIAxes20,"reset")
            plot(app.UIAxes20,app.Wlogv_vmax,app.Wlogp,'DisplayName','logP','lineWidth',1)
            hold(app.UIAxes20,"on")
            if(app.Wmarimi.Value==1)
                plot(app.UIAxes20,app.logvvmax,app.logp,'DisplayName','logP masurat','lineWidth',1,"Color",'k',"LineStyle","--")
            end
            ylabel(app.UIAxes20,'logp, [bar]');
            xlabel(app.UIAxes20,'log(V/Vmax), [-]')
            %             xticks(app.UIAxes20,min(Wlogv_vmax)-0.10:0.05:max(Wlogv_vmax)+0.10)
            ylim(app.UIAxes20,[min(app.Wlogp)-0.2 max(app.Wlogp)+0.3])
            xlim(app.UIAxes20,[min(app.Wlogv_vmax)-0.10 max(app.Wlogv_vmax)+0.10])
            hold(app.UIAxes20,'off');
            lgnd=legend(app.UIAxes20);
            set(lgnd,'Color','none','Box',"off")
            
            
            %%%%%%%%%%%%%initializare valori validare
            app.presiune_validare=zeros;
            app.gradient_validare=zeros;
            app.temp_validare=zeros;
            app.presiune_validare=app.filtered_pressure(lim_ardere1:lim_ardere2);
            app.gradient_validare=app.dP(lim_ardere1:lim_ardere2);
            app.temp_validare=app.temp(lim_ardere1:lim_ardere2);
            
            %temperatura wiebe domeniu soc-eoc
            app.TVW=zeros;
            app.TVW=app.Wtemp(lim_ardere1:lim_ardere2);
            %presiune wiebe domeniu soc-eoc
            app.PVW=zeros;
            app.PVW=app.Wpress(lim_ardere1:lim_ardere2);
            %gradient presiune wiebe domeniu soc-eoc
            app.GPVW=zeros;
            app.GPVW=app.Wpress_gradient(lim_ardere1:lim_ardere2);

        end

        % Callback function
        function EditField_79ValueChanged(app, event)
            app.o1RACEditField.Value=app.EditField_79.Value;
        end

        % Value changed function: EditField_88, EditField_90
        function EditField_88ValueChanged(app, event)
            app.EditField_106.Value=app.EditField_88.Value+app.EditField_90.Value;
            app.o1RACEditField.Value=app.EditField_88.Value;
            
        end

        % Button pushed function: CalculmodelanaliticButton
        function CalculmodelanaliticButtonPushed(app, event)
            %initializare parametrii din fereastra comuna si calcule
            %necesare
            
            D = app.DcmEditField.Value;
            S = app.EditField_3.Value;
            conrod_length = app.EditField_4.Value;
            eps = app.EditField_5.Value;
            lambda = app.EditField_8.Value;
            IVO=app.EditField_112.Value;
            IVC = app.EditField_9.Value;
            EVO = app.EditField_10.Value;
            EVC=app.EditField2.Value;
            LHV = app.EditField_11.Value;
            R = app.EditField_20.Value;
            piston_offset = app.EditField_6.Value;
            
            
            app.crankshaft_rad = S/2;
            app.lambda_raport = app.crankshaft_rad/conrod_length;
            app.V_s = pi*D^2/4*S  ;
            app.vol_ca = app.V_s/(eps -1);
            
            if piston_offset == 0
                s_phi = app.crankshaft_rad.*(1-cosd(app.crank_angle)+...
                    (app.lambda_raport/4.*(1-cosd(2.*app.crank_angle))));
                app.vol_phi = s_phi*((pi*(D^2))/4)+app.vol_ca;
            end
            
            if piston_offset ~= 0
                piston_offset = piston_offset * (10^-1);
                beta = asind(piston_offset/(app.crankshaft_rad + conrod_length));
                s_phi = sqrt((app.crankshaft_rad+conrod_length)^2 - (piston_offset^2)) - ...
                    sqrt((conrod_length^2) - ((piston_offset + app.crankshaft_rad .* ...
                    sind(app.crank_angle - beta)).^2)) - app.crankshaft_rad .* ...
                    cosd(app.crank_angle - beta);
                app.vol_phi = s_phi*((pi*(D^2))/4)+app.vol_ca;
            end
            
            %initializare vectori
            PR=zeros([1 length(app.crank_angle)]);
            
            app.p=zeros([1 length(app.crank_angle)]);
            app.t=zeros([1 length(app.crank_angle)]);
            T_c=zeros([1 length(app.crank_angle)]);  %initializare T_c
            app.p_c=zeros([1 length(app.crank_angle)]);
            T_e=zeros([1 length(app.crank_angle)]);  %initializare T_e
            app.p_e=zeros([1 length(app.crank_angle)]);
            xi=zeros([1 length(app.crank_angle)]);
            %tabele interpolare
            temp_interp=250:50:900;
            cp_interp=[1.003 1.005 1.008 1.013...
                1.020 1.029 1.040 1.051...
                1.063 1.075 1.087 1.099...
                1.11 1.121];
            
            
            evalin('base', 'clc')
            %Initializare parametrii
            P_im=app.EditField_13.Value*10^-5;        %Presiunea in galeria de admisie kPa bar
            T_im=app.EditField_12.Value;              %Temperatura in galeria de admisie K
            theta_d=app.EditField_94.Value;           %Unghiul de dezvoltare al flacarii
            theta_b=app.EditField_95.Value;           %Durata arderii pentru procesul de ardere rapida
            theta_soc=app.EditField_96.Value;         %Unghiul  in grade rac la care incepe arderea
            Llambda=app.EditField_8.Value;            %Raport normalizat aer/combustibil
            p_em=app.EditField_113.Value*10^-5;
            %Initializare parametrii de tuning
            c_p=app.EditField_98.Value;         %Căldura specifică la ardere sau caldura specifica la presiune constanta
            k_c=app.EditField_99.Value;         %Coeficientul poltropic pentru presiunea de compresie
            k_e=app.EditField_100.Value;        %Coeficientul poltropic pentru presiunea de expansiune
            q_HV=LHV;                           %Heating value kj/kg
            T_r=app.EditField_102.Value;        %Temperatura gazelor reziduale k
            x_r=app.EditField_103.Value;        %Fractiunea gazelor reziduale %
            
            delta_theta=2*theta_d+theta_b; %Durata arderii    delta_theta=2*theta_d+theta_b;
            eeoc=theta_soc+delta_theta; %sfarsitul arderii


            %Initializare limite de calcul
            
            %Limite de calcul pentru procesul de comprimare
            
            %Initializare limite de calcul
            %limite calcul pentru procesul de admisie
            lim_admisie1=find(app.crank_angle==app.crank_angle( (app.crank_angle>(EVC-0.01)) & (app.crank_angle<(EVC+0.01)) ),1);
            %Limite de calcul pentru procesul de comprimare
            lim_comprimare1=find(app.crank_angle==app.crank_angle( (app.crank_angle>(IVC-0.01)) & (app.crank_angle<(IVC+0.01)) ),1);
            lim_comprimare2=find(app.crank_angle==app.crank_angle( (app.crank_angle>(theta_soc-0.01)) & (app.crank_angle<(theta_soc+0.01)) ),1);
            %Limite de calcul pentru procesul de ardere
            lim_ardere1=find(app.crank_angle==app.crank_angle( (app.crank_angle>(theta_soc-0.01)) & (app.crank_angle<(theta_soc+0.01)) ),1);
            
            %Limita destindere?
            lim_EVO=find(app.crank_angle==app.crank_angle( (app.crank_angle>(EVO-0.01)) & (app.crank_angle<(EVO+0.01)) ),1);
            
            lim_ivo=find(app.crank_angle==app.crank_angle( (app.crank_angle>(IVO-0.01)) & (app.crank_angle<(IVO+0.01)) ),1);
            lim_540=find(app.crank_angle==app.crank_angle( (app.crank_angle>(540-0.01)) & (app.crank_angle<(540+0.01)) ),1);
            
            %calculul cantitate combustibil in cilindru
            masa_combustibil= app.DcmEditField_3.Value + ...
                app.EditField_33.Value + ...
                app.EditField_36.Value + ...
                app.EditField_39.Value + ...
                app.EditField_42.Value; %[mg]
            
            masa_amestec=masa_combustibil*(lambda*app.EditField_109.Value+1)*10^-6; %Masa de amestec in kg
            
            %Calcul presiune initiala
            V_ivc=app.vol_phi(find(app.crank_angle==IVC,1));          %Calculul volumului in momentul inchiderii supapelor de admisie
            p_ivc=P_im;                                               %Calculul presiunii in momentul inchiderii supapelor de admisie
            
            
            %Calcul temperatura initiala
            T_af=T_im;                                                %Temperatura initiala a amestecului = temperatura initiala
            T_ivc=T_af*(1-x_r)+x_r*T_r;                               %Temperatura in momentul inchiderii sup de adimisie


            %calcul parametrii Wiebe
            m=(log(log(1-0.1)/log(1-0.85)))...
                /...
                ((log(theta_d)-log(theta_d+theta_b)))...
                -1;
            
            a=-log(1-0.1)* (delta_theta/theta_d)^(m+1);
            display(m);
            display(a);
            
            %calcul theta_c
            mfb_50=round(theta_d+0.5*theta_b,1);
            MFB_50opt=app.EditField_111.Value;
            if(mfb_50==MFB_50opt)
                theta_c=0;
            else
                theta_c=mfb_50-MFB_50opt;
            end
            theta_c=360+theta_c;
            
            V3=app.vol_phi(find(app.crank_angle==app.crank_angle( (app.crank_angle>(theta_c-0.01)) & (app.crank_angle<(theta_c+0.01)) ),1));
            V2=V3;
            
            
            for i=1:numel(app.crank_angle)
                if(i<lim_admisie1)
                    xi(i)=(0.5 .* (1-cosd(pi .* ((app.crank_angle(i)-0)) /(EVC-0))));
                    
                    app.p(i)=p_em*(xi(i)) + P_im*(1-xi(i));
                elseif(i==lim_admisie1)
                    app.p(i)=P_im;
                    app.t(i)=T_ivc;
                elseif(i>=lim_admisie1 && i<lim_comprimare1)  %if admisie
                    
                    app.p_c(i)=p_ivc*(V_ivc / app.vol_phi(i)) ^(k_c);
                    T_c(i)=T_ivc;
                    
                    xi(i)= 0.5 .* (1-cosd(3.14 .*(app.crank_angle(i)-EVC)/(IVC-EVC)));
                    
                    app.p(i)=P_im*(1-xi(i))+app.p_c(i)*xi(i);
                    app.t(i)=T_c(i);
                    
                elseif(i>=lim_comprimare1 && i<lim_comprimare2)    %if comprimare
                    
                    app.p_c(i)=p_ivc*(V_ivc / app.vol_phi(i)) ^(k_c);  %presiune comprimare
                    T_c(i)=T_ivc*(V_ivc / app.vol_phi(i)) ^(k_c-1);%temperatura comprimare
                    
                    app.p(i)=app.p_c(i); %presiune
                    app.t(i)= (app.p(i)*10^5*app.vol_phi(i)*10^-6 )/(R*masa_amestec);


                end %end if
            end %end for
            
            p2=p_ivc*(V_ivc / V2) ^(k_c);
            T2=T_ivc*(V_ivc / V2) ^(k_c-1);
                       
            %Calcule asymptotic final pressure
            n_f=0.95*min([1 1.2*Llambda-0.2]);                        %calculul fuel conversion efficency
            
            c_v = c_p-R;                                              %calculul caldurii specifice la volum constant!!!!! nush daca e bine
            %              c_p=(interp1(temp_interp,cp_interp,T2)*1000);
            %              c_v = c_p-R;
            delta_Tcomb = ((1-x_r)*q_HV*1000*n_f) / ( ( Llambda*app.EditField_109.Value+1 )*c_v ); %calculul delta T diferenta de temperatura dintre starea 2 si 3
            T3=T2+delta_Tcomb;
            p3=p2*(T3/T2);
            
            for i=1:numel(app.crank_angle)
                if(i>=lim_comprimare1 && i<lim_comprimare2) %IVC-ardere
                    
                    app.p_e(i) = p3*(V3/app.vol_phi(i)) ^k_e;
                    T_e(i) = T3*(V3/app.vol_phi(i)) ^(k_e-1);
                    
                elseif(i>=lim_ardere1 && i<lim_EVO) %if ardere pana la EVO
                    
                    
                    app.p_c(i)=p_ivc*(V_ivc / app.vol_phi(i)) ^(k_c);  %
                    T_c(i)=T_ivc*(V_ivc / app.vol_phi(i)) ^(k_c-1);
                    
                    app.p_e(i) = p3*(V3/app.vol_phi(i)) ^k_e;
                    T_e(i) = T3*(V3/app.vol_phi(i)) ^(k_e-1);
                    
                    
                    %calculul funcției Wiebe
                    PR(i) = (1- exp(-a *( (((app.crank_angle(i)-360)-(theta_soc-360))/delta_theta) ^ (m+1) )) );
                                        
                    app.p(i)=app.p_c(i)*(1-PR(i)) + app.p_e(i)*PR(i);
                    
                    app.t(i)= (app.p(i)*10^5*app.vol_phi(i)*10^-6 )/(R*masa_amestec);
                                    
                elseif(i>=lim_EVO && i<lim_540) %if dupa EVO pana la 540
                    
                    app.p_e(i) = p3*(V3/app.vol_phi(i)) ^k_e;
                    T_e(i) = T3*(V3/app.vol_phi(i)) ^(k_e-1);
                    
                    app.p_c(i) = p_ivc*(V_ivc / app.vol_phi(i)) ^(k_c);  %
                    T_c(i) = T_ivc*(V_ivc / app.vol_phi(i)) ^(k_c-1);
                    
                    %                     xi(i)=0.5 .* (1-cosd(3.14 .*(app.crank_angle(i)-EVO) /(IVO-EVO)));
                    xi(i)=0.5 .* (1-cosd(pi .*(app.crank_angle(i)-EVO) /(539.9-EVO)));
                    
                    app.p(i)= app.p_e(i)*(1-xi(i))+p_em*(xi(i));
                    
                elseif(i>=lim_540 && i<lim_ivo) %540-IVO-0.1(705.9)
                    
                    xi(i)=(0.5 .* (1-cos((pi .* ((app.crank_angle(i)-540) /((IVO)-540))))));
                    
                    app.p(i) = (p_em.*(1-xi(i))) + (P_im.*(xi(i))); %ori aici nu e bine, ori la functia de interpolare
                    
                elseif(i>=lim_ivo && i<=numel(app.crank_angle))  %IVO-720
                    
                    xi(i)=(0.5 .* (1-cosd(3.14 .* ((app.crank_angle(i)-IVO)) /(719.9-IVO))));
                    
                    app.p(i)=p_em*(xi(i)) + P_im*(1-xi(i));
                    
                end
            end
            
            app.vit_cresterepresiune=gradient(app.p,0.1);
            
            evalin('base', 'clear crank_angle')
            evalin('base', 'clear Presiune_masurata')
            evalin('base', 'clear Presiune_calculata')
            
            assignin('base','crank_angle',app.crank_angle')
            assignin('base','Presiune_masurata',app.filtered_pressure')
            assignin('base','Presiune_calculata',app.p')
            
            %Generarea graficului  presiune-temperatura
            cla(app.UIAxes12,'reset')
            if(app.TemperaturaCheckBox.Value==1 && (app.PresiuneamasurataCheckBox.Value==0 && app.CompressionasymptoteCheckBox.Value==0 && app.ExpansionasymptoteCheckBox.Value==0 && app.PresiuneacalculataCheckBox.Value==0))
                cla(app.UIAxes12,'reset')
                
                app.UIAxes12.YColor="k";
                plot(app.UIAxes12,app.crank_angle,app.t,'DisplayName','Temperatura','lineWidth',1,"Color",[0.8500 0.3250 0.0980])
                xlabel(app.UIAxes12,'Unghiul de rotatie al arborelui cotit, [°RAC]')
                ylabel(app.UIAxes12,'Temperatura, [K]')
                ylim(app.UIAxes12,[0 max(app.t)+300])
                xlim(app.UIAxes12,[330 430])
                xline(app.UIAxes12,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9,'DisplayName','PMS')
                lgnd=legend(app.UIAxes12,'Location','northeast');
                set(lgnd,'Color','none','Box',"off")
            elseif(app.TemperaturaCheckBox.Value==0 && app.PresiuneamasurataCheckBox.Value==0 && app.CompressionasymptoteCheckBox.Value==0 && app.ExpansionasymptoteCheckBox.Value==0 && app.PresiuneacalculataCheckBox.Value==0)
                cla(app.UIAxes12,'reset')
            else
                cla(app.UIAxes12,'reset')
                if(app.TemperaturaCheckBox.Value==1)
                    yyaxis(app.UIAxes12,'left')
                    cla(app.UIAxes12,'reset')
                    yyaxis(app.UIAxes12,'left')
                end
                
                app.UIAxes12.YColor="k";
                
                if(app.PresiuneacalculataCheckBox.Value==1)
                    plot(app.UIAxes12,app.crank_angle,app.p,'DisplayName','Presiunea calculata','lineWidth',1,'Color','r')
                end
                
                hold(app.UIAxes12,"on")
                
                if(app.CompressionasymptoteCheckBox.Value==1)
                    app.p_c(app.p_c==0)=nan;
                    plot(app.UIAxes12,app.crank_angle,app.p_c,'DisplayName','Compression asymptote','lineWidth',1,'Color',[0 0.4470 0.7410],'LineStyle',"--")
                end
                
                if(app.ExpansionasymptoteCheckBox.Value==1)
                    app.p_e(app.p_e==0)=nan;
                    plot(app.UIAxes12,app.crank_angle,app.p_e,'DisplayName','Expansion asymptote','lineWidth',1,'Color',[0.6350 0.0780 0.1840],"LineStyle","--")
                end
                %pt validare mai usoara
                if(app.PresiuneamasurataCheckBox.Value==1)
                    plot(app.UIAxes12,app.crank_angle,app.filtered_pressure,'DisplayName','Presiunea masurata','lineWidth',1,'Color','r',"LineStyle",":")
                end
                %pt validare mai usoara
                xlabel(app.UIAxes12,'Unghiul de rotatie al arborelui cotit, [°RAC]')
                ylabel(app.UIAxes12,'Presiune, [bar]')
                ylim(app.UIAxes12,[0 max(app.p)+20])
                if(app.TemperaturaCheckBox.Value==1 || app.PresiuneamasurataCheckBox.Value==1 || app.CompressionasymptoteCheckBox.Value==1 || app.ExpansionasymptoteCheckBox.Value==1 || app.PresiuneacalculataCheckBox.Value==1)
                    xline(app.UIAxes12,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9,'DisplayName','PMS')
                end
                xlim(app.UIAxes12,[330 430])
                hold(app.UIAxes12,"off")
                
                if(app.TemperaturaCheckBox.Value==1)
                    yyaxis(app.UIAxes12,'right')
                    app.UIAxes12.YColor="k";
                    app.t(app.t==0)=nan;
                    plot(app.UIAxes12,app.crank_angle,app.t,'DisplayName','Temperatura','lineWidth',1,"Color",[0.8500 0.3250 0.0980])
                    ylabel(app.UIAxes12,'Temperatura, [K]')
                    ylim(app.UIAxes12,[0 max(app.t)+300])
                    yyaxis(app.UIAxes12,'left')
                end
                lgnd=legend(app.UIAxes12,'Location','northeast');
                set(lgnd,'Color','none','Box',"off")
                hold(app.UIAxes12,"off")
                
            end
            
            
        end

        % Value changed function: o2RACEditField
        function o2RACEditFieldValueChanged(app, event)
            value = app.o2RACEditField.Value;
            app.RACEditField_2.Value=app.EditField_106.Value-value;
        end

        % Button pushed function: ExportdateExcelButton
        function ExportdateExcelButtonPushed(app, event)
            %Date Analiza model ardere pe unghiul theta
            col_header2 = {'Unghiul de rotatie al arborelui cotit dupa PMS [°RAC]','Fractiunea de amestec arsa [%]',...
                'Viteza bruta de degajare a caldurii [J/°RAC]','Viteza neta de degajare a caldurii [J/°RAC]',...
                'Caracteristica bruta de degajare a caldurii [J]','Caracteristica neta de degajare a caldurii [J]',...
                'Coeficientul de transfer termic [W/m^2/K]', 'Pierderile de caldura prin piston [W]',...
                'Pierderile de caldura prin cilindru [W]', 'Pierderile de caldura prin chiulasa [W]',...
                'Fluxul termic [kW]'
                };
            
            col_data2 = [app.theta', app.MFB', app.DQbrut', app.dqnet', app.Qbrut',...
                app.ahhrW', app.heat_trans_coef', app.Wiebe_piston_heat_transfer',...
                app.Wiebe_cyl_heat_transfer', app.Wiebe_cyl_head_heat_transfer'...
                -app.flux_termic_Wiebe'
                ];
            
            %Date analiza model ardere pe tot vectorul unghiului de rotatie
            %al arborelui cotit
            
            col_pt = {'Unghiul de rotatie al arborelui cotit [°RAC]',...
                'Presiunea Calculata Wiebe [bar]','Gradientul de presiune [bar/RAC]','Temperatura Calculata Wiebe [K]'};
            
            col_datpt=[app.crank_angle',app.Wpress', app.Wpress_gradient', app.Wtemp'];
            
            %Date validare din programul de analiza principiul I pe
            %domeniul soc-eoc
            col_val = {'Unghiul de rotatie al arborelui cotit [°RAC]',...
                'Fractiunea de amestec arsa [%]','Viteza neta',...
                'Caracteristica de degajare a caldurii',...
                'Presiune', 'Gradient', 'Temperatura [K]'
                };
            
            col_val2=[app.theta', app.Wiebe_dan_test', app.vitnet_validare',...
                app.validare_caracteristica', app.presiune_validare',...
                app.gradient_validare', app.temp_validare'
                ];
            
            %date program analiza ardere presiune-gradient-temp convertite
            %pe domeniul soc-eoc
            col_se={'Unghi','Presiunea [bar]', 'Gradientul de presiune [bar/rac]',...
                'Temperatura [k]'
                };
            col_se2=[app.theta', app.PVW', app.GPVW', app.TVW' ];
            %Scriere date in format Excel
            folder=fullfile(cd,'Date Excel');
            
            if (isfolder(folder))
                
            else
                mkdir(folder);
                
            end
            
            isfolder(folder)
            
            
            baseFileName = ['Wiebe-' strcat(num2str(app.EditField_7.Value)) '-' strcat(num2str(app.EditField_114.Value*100)) '-' date '.xlsx'];
            fullFileName = fullfile(folder,baseFileName);
            
            xlswrite(fullFileName,col_header2,'Analiza Ardere','A1');
            xlswrite(fullFileName,col_data2,'Analiza Ardere','A2');
            
            xlswrite(fullFileName,col_pt,'Analiza Ardere','L1');
            xlswrite(fullFileName,col_datpt,'Analiza Ardere','L2');
            
            xlswrite(fullFileName,col_val,'Principiul I domeniu ardere','A1');
            xlswrite(fullFileName,col_val2,'Principiul I domeniu ardere','A2');
            
            xlswrite(fullFileName,col_se,'PGPT (soc-eoc) Analiza Ardere','A1');
            xlswrite(fullFileName,col_se2,'PGPT (soc-eoc) Analiza Ardere','A2');
            display(fullFileName)
            %Deschidere Excel
            winopen(fullFileName);
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if(app.Switch.Value=="Single Wiebe")
                app.EditField_91.Enable='off';
                app.a2EditField.Enable='off';
                app.o2RACEditField.Enable='off';
                app.RACEditField_2.Enable='off';
                app.m2EditField.Enable='off';
                
                app.a1Label.Text="a [-]";
                app.m1Label.Text='m [-]';
                app.o1RACLabel.Text='Ѳo [°RAC]';
                app.RACLabel_5.Text="ΔѲ [°RAC]";
                drawnow()
            else
                app.EditField_91.Enable='on';
                app.a2EditField.Enable='on';
                app.o2RACEditField.Enable='on';
                app.RACEditField_2.Enable='on';
                app.m2EditField.Enable='on';
                
                app.a1Label.Text="a1 [-]";
                app.m1Label.Text='m1 [-]';
                app.o1RACLabel.Text='Ѳo1 [°RAC]';
                app.RACLabel_5.Text="ΔѲ1 [°RAC]";
                drawnow()
                
            end
        end

        % Button pushed function: NormalizarevitezeButton
        function NormalizarevitezeButtonPushed(app, event)
            cla(app.UIAxes14,"reset")
            %Generarea graficului %RATE OF HEAT RELEASE%
            
            
            app.vitnet_validare=(app.vitnet_validare-min(app.vitnet_validare))/(max(app.vitnet_validare)-min(app.vitnet_validare)); %normalizare viteza neta de validat
            
            
            de_scazut=app.dqnet1./app.viteza_neta;
            app.viteza_neta=(app.viteza_neta-min(app.viteza_neta))/(max(app.viteza_neta)-min(app.viteza_neta)); %normalizare vitnet
            
            if(app.Switch.Value=="Double Wiebe")


                app.dqnet1=(app.dqnet1-min(app.dqnet1))/(max(app.dqnet1)-min(app.dqnet1));%normalizare vitnet1
                app.dqnet2=(app.dqnet2-min(app.dqnet2))/(max(app.dqnet2)-min(app.dqnet2));%normalizare vitnet2
                app.dqnet1=(1-de_scazut).*app.viteza_neta;
                app.dqnet2=(de_scazut).*app.viteza_neta;
            end
            
            if(app.Wmarimi.Value==1)
                plot(app.UIAxes14,app.theta,app.vitnet_validare,'DisplayName','Viteza net de degajare a caldurii măsurata','lineWidth',1,'LineStyle','-','Color',[0.4940 0.1840 0.5560])
            end
            hold(app.UIAxes14,"on")
            plot(app.UIAxes14,app.theta,app.viteza_neta,'DisplayName','Viteza net de degajare a caldurii','lineWidth',1,'Color',[0.6350 0.0780 0.1840])
            if(app.Switch.Value=="Double Wiebe")
                plot(app.UIAxes14,app.theta,app.dqnet1,'DisplayName','Viteza net de degajare a caldurii W1','lineWidth',1,'LineStyle','-.','Color',[0 0.4470 0.7410])
                plot(app.UIAxes14,app.theta,app.dqnet2,'DisplayName','Viteza net de degajare a caldurii W2','lineWidth',1,'LineStyle','-.','Color',[0.8500 0.3250 0.0980])
            end
            xlabel(app.UIAxes14,'Unghiul de rotatie al arborelui cotit dupa PMS, [°RAC]')
            ylabel(app.UIAxes14,'Viteza de degajare a caldurii, [1/grad]')
            lgnd=legend(app.UIAxes14);
            set(lgnd,'Color','none','Box',"off")
            %ylim(app.UIAxes14,[-10 max([vitnet_validare app.viteza_neta])+30])
            if(app.Wmarimi.Value==1)
                ylim(app.UIAxes14,[0 max([(app.vitnet_validare)+0.2 (app.viteza_neta)+0.2])])
            else
                ylim(app.UIAxes14,[0 max((app.viteza_neta)+0.2)])
            end
            hold(app.UIAxes14,"off")
        end

        % Button pushed function: ImportarepresiunemodelWiebeButton
        function ImportarepresiunemodelWiebeButtonPushed(app, event)
            %initializare presiunea model Analiza principiul I=Wpress
            app.filtered_pressure=app.Wpress;
            
            cla(app.UIAxes2)
            yyaxis(app.UIAxes2,'left')
            plot(app.UIAxes2,app.crank_angle_inj,app.inj_vector_plot,'DisplayName','Debitul masic injectat','lineWidth',1);
            title(app.UIAxes2,'Presiunea si debitul masic injectat')
            xlabel(app.UIAxes2,'Unghiul de rotatie al arborelui cotit [°RAC]')
            ylabel(app.UIAxes2,'Debitul masic injectat [mg/°RAC]')
            hold(app.UIAxes2, 'on')
            ylim(app.UIAxes2,[0 3*max(app.m_inj_2)])
            xline(app.UIAxes2,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9,'Displayname','PMS')
            yyaxis(app.UIAxes2, 'right')
            plot(app.UIAxes2,app.crank_angle,app.filtered_pressure,'r','DisplayName','Presiunea - calculata model Wiebe','lineWidth',0.75)
            plot(app.UIAxes2,app.crank_angle,app.pressure_motored,'-k','DisplayName','Presiunea - motor antrenat','lineWidth',0.75)
            ylabel(app.UIAxes2,'Presiunea, [bar]')
            xlim (app.UIAxes2,[340 420])
            xticks(app.UIAxes2,0:10:max(app.crank_angle))
            hold (app.UIAxes2,'off')
            legend(app.UIAxes2)
            
        end

        % Button pushed function: ExportdateExcelButton_2
        function ExportdateExcelButton_2Pushed(app, event)
            
            
            col_pt = {'Unghiul de rotatie al arborelui cotit [°RAC]',...
                'Presiunea Calculata [bar]','Viteza de crestere a presiunii [bar/RAC]','Temperatura Calculata [K]',"Volum [cm3]","pc_phi"};
            
            col_datpt=[app.crank_angle',app.p', app.vit_cresterepresiune', app.t', app.vol_phi', app.p_c'];
            
            %Scriere date in Excel
            %Scriere date in format Excel
            folder=fullfile(cd,'Date Excel');
            
            if (isfolder(folder))
                
            else
                mkdir(folder);
                
            end
            
            isfolder(folder)
            
            baseFileName = ['Analitic-' strcat(num2str(app.EditField_7.Value)) '-' strcat(num2str(app.EditField_114.Value*100)) '-' date '.xlsx'];
            fullFileName = fullfile(folder,baseFileName);


            xlswrite(fullFileName,col_pt,'Analitic','A1');
            xlswrite(fullFileName,col_datpt,'Analitic','A2');
            
            %Deschidere Excel
            winopen(fullFileName);
        end

        % Value changed function: Transfer
        function TransferValueChanged(app, event)
            value = app.Transfer.Value;
            if(strcmp(value,'Annand'))
                app.EditField_110.Visible="on";
                app.CoeficientAnnandLabel.Visible="on";
            else
                app.EditField_110.Visible="off";
                app.CoeficientAnnandLabel.Visible="off";
            end
        end

        % Value changed function: CompressionasymptoteCheckBox, 
        % ExpansionasymptoteCheckBox, PresiuneacalculataCheckBox, 
        % PresiuneamasurataCheckBox, TemperaturaCheckBox
        function PresiuneamasurataCheckBoxValueChanged(app, event)
            %Generarea graficului  presiune-temperatura
            cla(app.UIAxes12,'reset')
            if(app.p~=0)
                if(app.TemperaturaCheckBox.Value==1 && (app.PresiuneamasurataCheckBox.Value==0 && app.CompressionasymptoteCheckBox.Value==0 && app.ExpansionasymptoteCheckBox.Value==0 && app.PresiuneacalculataCheckBox.Value==0))
                    cla(app.UIAxes12,'reset')
                    
                    app.UIAxes12.YColor="k";
                    plot(app.UIAxes12,app.crank_angle,app.t,'DisplayName','Temperatura','lineWidth',1,"Color",[0.8500 0.3250 0.0980])
                    xlabel(app.UIAxes12,'Unghiul de rotatie al arborelui cotit, [°RAC]')
                    ylabel(app.UIAxes12,'Temperatura, [K]')
                    ylim(app.UIAxes12,[0 max(app.t)+300])
                    xlim(app.UIAxes12,[330 430])
                    xline(app.UIAxes12,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9,'DisplayName','PMS')
                    lgnd=legend(app.UIAxes12,'Location','northeast');
                    set(lgnd,'Color','none','Box',"off")
                elseif(app.TemperaturaCheckBox.Value==0 && app.PresiuneamasurataCheckBox.Value==0 && app.CompressionasymptoteCheckBox.Value==0 && app.ExpansionasymptoteCheckBox.Value==0 && app.PresiuneacalculataCheckBox.Value==0)
                    cla(app.UIAxes12,'reset')
                else
                    cla(app.UIAxes12,'reset')
                    if(app.TemperaturaCheckBox.Value==1)
                        yyaxis(app.UIAxes12,'left')
                        cla(app.UIAxes12,'reset')
                        yyaxis(app.UIAxes12,'left')
                    end
                    
                    app.UIAxes12.YColor="k";
                    
                    if(app.PresiuneacalculataCheckBox.Value==1)
                        plot(app.UIAxes12,app.crank_angle,app.p,'DisplayName','Presiunea calculata','lineWidth',1,'Color','r')
                    end
                    
                    hold(app.UIAxes12,"on")
                    
                    if(app.CompressionasymptoteCheckBox.Value==1)
                        app.p_c(app.p_c==0)=nan;
                        plot(app.UIAxes12,app.crank_angle,app.p_c,'DisplayName','Compression asymptote','lineWidth',1,'Color',[0 0.4470 0.7410],'LineStyle',"--")
                    end
                    
                    if(app.ExpansionasymptoteCheckBox.Value==1)
                        app.p_e(app.p_e==0)=nan;
                        plot(app.UIAxes12,app.crank_angle,app.p_e,'DisplayName','Expansion asymptote','lineWidth',1,'Color',[0.6350 0.0780 0.1840],"LineStyle","--")
                    end
                    %pt validare mai usoara
                    if(app.PresiuneamasurataCheckBox.Value==1)
                        plot(app.UIAxes12,app.crank_angle,app.filtered_pressure,'DisplayName','Presiunea masurata','lineWidth',1,'Color','r',"LineStyle",":")
                    end
                    %pt validare mai usoara
                    xlabel(app.UIAxes12,'Unghiul de rotatie al arborelui cotit, [°RAC]')
                    ylabel(app.UIAxes12,'Presiune, [bar]')
                    ylim(app.UIAxes12,[0 max(app.p)+20])
                    if(app.TemperaturaCheckBox.Value==1 || app.PresiuneamasurataCheckBox.Value==1 || app.CompressionasymptoteCheckBox.Value==1 || app.ExpansionasymptoteCheckBox.Value==1 || app.PresiuneacalculataCheckBox.Value==1)
                        xline(app.UIAxes12,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9,'DisplayName','PMS')
                    end
                    xlim(app.UIAxes12,[330 430])
                    hold(app.UIAxes12,"off")
                    
                    if(app.TemperaturaCheckBox.Value==1)
                        yyaxis(app.UIAxes12,'right')
                        app.UIAxes12.YColor="k";
                        app.t(app.t==0)=nan;
                        plot(app.UIAxes12,app.crank_angle,app.t,'DisplayName','Temperatura','lineWidth',1,"Color",[0.8500 0.3250 0.0980])
                        ylabel(app.UIAxes12,'Temperatura, [K]')
                        ylim(app.UIAxes12,[0 max(app.t)+300])
                        yyaxis(app.UIAxes12,'left')
                    end
                    lgnd=legend(app.UIAxes12,'Location','northeast');
                    set(lgnd,'Color','none','Box',"off")
                    hold(app.UIAxes12,"off")
                    
                end
            end
        end

        % Value changed function: Wmarimi
        function WmarimiValueChanged(app, event)
            %%%%%%%%%%%%%GRAFICE!
            if(app.mf_qlhv~=0)
                %grafic presiune,flux,coef
                cla(app.UIAxes17,'reset')
                yyaxis(app.UIAxes17,'left')
                app.UIAxes17.YColor="k";
                plot(app.UIAxes17,app.theta,-app.flux_termic_Wiebe,'DisplayName','Flux termic','lineWidth',1)
                app.UIAxes17.YColor="k";
                hold(app.UIAxes17,'on')
                ylabel(app.UIAxes17,'Flux termic  [kW], Presiune [bar]')
                xlabel(app.UIAxes17,'Unghiul de rotatie al arborelui cotit, [°RAC]')
                
                plot(app.UIAxes17,app.theta,app.press_test,'DisplayName','Presiunea','lineWidth',1,'Color','b',"LineStyle","-")
                hold(app.UIAxes17,'off')
                
                
                yyaxis(app.UIAxes17,'right')
                app.UIAxes17.YColor="k";
                plot(app.UIAxes17,app.theta,app.heat_trans_coef,'DisplayName','Coeficientul de transfer termic','lineWidth',1)
                ylabel(app.UIAxes17,'Coeficientul de tranfer termic, [W/m^2*K]')
                lgnd=legend(app.UIAxes17,'Location','northeast');
                set(lgnd,'Color','none','Box',"off")
                hold(app.UIAxes17,"off")
                
                %Grafice
                %generarea graficului MDF [%]
                cla(app.UIAxes10,'reset')
                if(app.Switch.Value=="Double Wiebe")
                    plot(app.UIAxes10,app.theta,app.Xb1,'DisplayName','Prima funcție Wiebe','lineWidth',1,'Color',[0 0.4470 0.7410])
                    hold (app.UIAxes10,'on')
                    plot(app.UIAxes10,app.theta,app.Xb2,'DisplayName','A doua funcție Wiebe','lineWidth',1,'Color',[0.8500 0.3250 0.0980])
                    plot(app.UIAxes10,app.theta, app.MFB,'DisplayName','Fracțiunea de amestec arsă','lineWidth',1,'Color',[0.6350 0.0780 0.1840])
                else
                    plot(app.UIAxes10,app.theta, app.MFB,'DisplayName','Fracțiunea de amestec arsă','lineWidth',1,'Color',[0.6350 0.0780 0.1840])
                    hold (app.UIAxes10,'on')
                end
                if(app.Wmarimi.Value==1)
                    plot(app.UIAxes10,app.theta, app.Wiebe_dan_test,'DisplayName','Fracțiunea de amestec arsă măsurata','lineWidth',1,'Color',[0.4940 0.1840 0.5560])
                    %test wiebe dan
                end
                xlabel(app.UIAxes10,'Unghiul de rotatie al arborelui cotit dupa PMS, [°RAC]')
                ylabel(app.UIAxes10,'Fractiunea de amestec arsă, [%]')
                xlim (app.UIAxes10,[min(app.theta)-5 max(app.theta)])
                ylim(app.UIAxes10,[0 1])
                xline(app.UIAxes10,0,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9,'DisplayName','PMS')
                %             xticks(app.UIAxes10,app.soc:5:max(app.theta))
                hold (app.UIAxes10,'off')
                lgnd=legend(app.UIAxes10,'Location','northwest');
                set(lgnd,'Color','none','Box',"off")
                hold (app.UIAxes10,'off')
                
                %generarea graficului AHHR [j]
                cla(app.UIAxes11,"reset")
                
                %daca nu eok schimb Qn cu app.ahhr
                
                %pierderile de caldura
                fill(app.UIAxes11,app.umplere,app.inBetwen,'r','DisplayName','Pierderi','FaceAlpha',0.3)
                %pierderile de caldura
                
                hold (app.UIAxes11,'on')
                plot(app.UIAxes11,app.theta,app.Qbrut,'DisplayName','Caracteristica bruta de degajare a caldurii','lineWidth',1)
                plot(app.UIAxes11,app.theta,app.ahhrW,'DisplayName','Caracteristica neta de degajare a caldurii','lineWidth',2)
                %linia ineficienta
                plot(app.UIAxes11,[max(app.theta)-10 max(app.theta)-10],[app.Qbrut(app.theta==max(app.theta)-10) app.mf_qlhv],'DisplayName','Ineficienta','lineWidth',3)
                %linia ineficienta
                xlabel(app.UIAxes11,'Unghiul de rotatie al arborelui cotit dupa PMS, [°RAC]')
                ylabel(app.UIAxes11,'Degajare de caldura , [j]')
                xlim (app.UIAxes11,[min(app.theta)-10 max(app.theta)])
                ylim(app.UIAxes11,[-10 app.mf_qlhv+200])
                xline(app.UIAxes11,0,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9,'DisplayName','PMS')
                yline(app.UIAxes11,app.mf_qlhv,'-','Color','b','lineWidth', 0.9,'DisplayName','Cantitatea de caldura introdusa in cilindru')
                %             xticks(app.UIAxes11,app.soc:10:max(app.theta))
                %%caract validare
                if(app.Wmarimi.Value==1)
                    plot(app.UIAxes11,app.theta,app.validare_caracteristica,'DisplayName','Caracteristica masurata','lineWidth',1)
                end
                lgnd=legend(app.UIAxes11,'Location','southeast');
                set(lgnd,'Color','none','Box',"off")
                hold (app.UIAxes11,'off')
                
                %Generarea graficului  presiune-presiune motored-temperatura
                cla(app.UIAxes13,"reset")
                yyaxis(app.UIAxes13,'left')
                app.UIAxes13.YColor="k";
                plot(app.UIAxes13,app.crank_angle,app.Wpress,'DisplayName','Presiunea calculata','lineWidth',1,'Color','r')
                hold(app.UIAxes13,"on")
                %pt validare mai usoara
                if(app.Wmarimi.Value==1)
                    plot(app.UIAxes13,app.crank_angle,app.filtered_pressure,'DisplayName','Presiunea masurata','lineWidth',1,'Color','r',"LineStyle",":")
                    plot(app.UIAxes13,app.crank_angle,app.pressure_motored,"Color",[0 0.4470 0.7410],'LineStyle',"-.",'DisplayName',"Presiunea motor antrenat masurata")
                end
                %pt validare mai usoara
                plot(app.UIAxes13,app.crank_angle,app.Wpress_motored,'DisplayName','Presiunea motor antrenat','lineWidth',1,'LineStyle','--',"Color","b")
                xlabel(app.UIAxes13,'Unghiul de rotatie al arborelui cotit, [°RAC]')
                ylabel(app.UIAxes13,'Presiune, [bar]')
                ylim(app.UIAxes13,[0 max([app.Wpress app.filtered_pressure])+20])
                xlim(app.UIAxes13,[360+app.soc-50 360+app.eoc])
                xline(app.UIAxes13,360,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9,'DisplayName','PMS')
                hold(app.UIAxes13,"off")
                
                
                yyaxis(app.UIAxes13,'right')
                app.UIAxes13.YColor="k";
                plot(app.UIAxes13,app.crank_angle,app.Wtemp,'DisplayName','Temperatura','lineWidth',1,"Color",[0.8500 0.3250 0.0980])
                ylabel(app.UIAxes13,'Temperatura, [K]')
                ylim(app.UIAxes13,[0 max(app.Wtemp)+300])
                lgnd=legend(app.UIAxes13,'Location','northwest');
                set(lgnd,'Color','none','Box',"off")
                hold(app.UIAxes13,"off")
                yyaxis(app.UIAxes13,'left')
                
                
                %Generarea graficului %RATE OF HEAT RELEASE%
                cla(app.UIAxes14,"reset")
                
                
                if(app.Wmarimi.Value==1)
                    plot(app.UIAxes14,app.theta,app.vitnet_validare,'DisplayName','Viteza net de degajare a caldurii măsurata','lineWidth',1,'LineStyle','-','Color',[0.4940 0.1840 0.5560])
                end
                hold(app.UIAxes14,"on")
                plot(app.UIAxes14,app.theta,app.viteza_neta,'DisplayName','Viteza net de degajare a caldurii','lineWidth',1,'Color',[0.6350 0.0780 0.1840])
                if(app.Switch.Value=="Double Wiebe")
                    plot(app.UIAxes14,app.theta,app.dqnet1,'DisplayName','Viteza net de degajare a caldurii W1','lineWidth',1,'LineStyle','-.','Color',[0 0.4470 0.7410])
                    plot(app.UIAxes14,app.theta,app.dqnet2,'DisplayName','Viteza net de degajare a caldurii W2','lineWidth',1,'LineStyle','-.','Color',[0.8500 0.3250 0.0980])
                end
                xlabel(app.UIAxes14,'Unghiul de rotatie al arborelui cotit dupa PMS, [°RAC]')
                ylabel(app.UIAxes14,'Viteza de degajare a caldurii, [j/grad]')
                xline(app.UIAxes14,0,'--','Color',[0.8500 0.3250 0.0980],'lineWidth', 0.9,'DisplayName','PMS')
                lgnd=legend(app.UIAxes14);
                set(lgnd,'Color','none','Box',"off")
                if(max(app.viteza_neta)<2)
                    ylim(app.UIAxes14,[0 max([app.vitnet_validare app.viteza_neta])+0.2])
                elseif(max(app.viteza_neta)>2)
                    ylim(app.UIAxes14,[-10 max([app.vitnet_validare app.viteza_neta])+30])
                end
                
                %ylim(app.UIAxes14,[0 max(app.vitnet_validare)+0.2])
                hold(app.UIAxes14,"off")
                %ylim de la -10 la max+catva


                %grafic gradient presiune
                cla(app.UIAxes18,"reset")
                yyaxis(app.UIAxes18,'left')
                app.UIAxes18.YColor="k";
                plot(app.UIAxes18,app.crank_angle,app.Wpress,'DisplayName','Presiunea','lineWidth',1,"Color","b")
                hold(app.UIAxes18,"on")
                %%%%%%%%Validare%%%%%%%%
                if(app.Wmarimi.Value==1)
                    plot(app.UIAxes18,app.crank_angle,app.filtered_pressure,'DisplayName','Presiunea măsurata','lineWidth',1,"LineStyle","--","Color",[0 0.4470 0.7410])
                end
                %%%%%%%%Validare%%%%%%%%
                ylabel(app.UIAxes18,'Presiunea, [bar]')
                xlabel(app.UIAxes18,'Unghiul de rotatie al arborelui cotit, [°RAC]')
                ylim(app.UIAxes18,[0 max([app.Wpress app.filtered_pressure])+15])
                app.UIAxes18.YColor="k";
                hold(app.UIAxes18,"off")
                
                yyaxis(app.UIAxes18,'right')
                app.UIAxes13.YColor="k";
                plot(app.UIAxes18,app.crank_angle,app.Wpress_gradient,'DisplayName','Gradientul de presiune','lineWidth',1,'Color',[0.9290 0.6940 0.1250],'LineStyle',"-")
                hold(app.UIAxes18,"on")
                if(app.Wmarimi.Value==1)
                    plot(app.UIAxes18,app.crank_angle,app.dP,'DisplayName','Gradientul de presiune masurat','lineWidth',1,'Color',[0.8500 0.3250 0.0980],'LineStyle',"--")
                end
                ylabel(app.UIAxes18,'Gradientul de presiune, [bar/rac]')
                ylim(app.UIAxes18,[min([app.Wpress_gradient app.dP])-3 max([app.Wpress_gradient app.dP])+3])
                legend(app.UIAxes18,'Location','northwest')
                hold(app.UIAxes18,"off")
                xlim(app.UIAxes18,[360-30 360+40])
                app.UIAxes18.YColor="k";
                hold(app.UIAxes18,"off")
                lgnd=legend(app.UIAxes18);
                set(lgnd,'Color','none','Box',"off")
                
                %grafic p-v
                cla(app.UIAxes19,"reset")
                plot(app.UIAxes19,app.vol_phi,app.Wpress,'DisplayName','Presiunea','lineWidth',1,"Color",'b')
                hold(app.UIAxes19,"on")
                if(app.Wmarimi.Value==1)
                    plot(app.UIAxes19,app.vol_phi,app.filtered_pressure,'DisplayName','Presiunea măsurata','lineWidth',1,"Color",[0 0.4470 0.7410],"LineStyle","--")
                end
                plot(app.UIAxes19,app.vol_phi,app.Wpress_motored,'DisplayName','Presiunea pe ciclul fara ardere','lineWidth',1,'LineStyle',"--")
                ylabel(app.UIAxes19,'Presiunea, [bar]');
                xlabel(app.UIAxes19,'Volumul cilindrului, [cm3]')
                xticks(app.UIAxes19,0:50:max(app.vol_phi))
                ylim(app.UIAxes19,[0 max([app.Wpress app.filtered_pressure])+5])
                hold(app.UIAxes19,'off');
                lgnd=legend(app.UIAxes19);
                set(lgnd,'Color','none','Box',"off")
                
                %grafic logp
                cla(app.UIAxes20,"reset")
                plot(app.UIAxes20,app.Wlogv_vmax,app.Wlogp,'DisplayName','logP','lineWidth',1)
                hold(app.UIAxes20,"on")
                if(app.Wmarimi.Value==1)
                    plot(app.UIAxes20,app.logvvmax,app.logp,'DisplayName','logP masurat','lineWidth',1,"Color",'k',"LineStyle","--")
                end
                ylabel(app.UIAxes20,'logp, [bar]');
                xlabel(app.UIAxes20,'log(V/Vmax), [-]')
                %             xticks(app.UIAxes20,min(Wlogv_vmax)-0.10:0.05:max(Wlogv_vmax)+0.10)
                ylim(app.UIAxes20,[min(app.Wlogp)-0.2 max(app.Wlogp)+0.3])
                xlim(app.UIAxes20,[min(app.Wlogv_vmax)-0.10 max(app.Wlogv_vmax)+0.10])
                hold(app.UIAxes20,'off');
                lgnd=legend(app.UIAxes20);
                set(lgnd,'Color','none','Box',"off")
            end
        end

        % Value changed function: AlegererapidaDropDown
        function AlegererapidaDropDownValueChanged(app, event)
            value = app.AlegererapidaDropDown.Value;
            if(strcmp(value,'AVL 5402')) %daca utilizatorul alege AVL
                %Panou "Dimeniuni motor"
                app.DcmEditField.Value=8.5;
                app.EditField_3.Value=9;
                app.EditField_4.Value=13.8;
                app.EditField_5.Value=17.1;
                app.EditField_6.Value=0.55;
                %Panou "Date regim functionare motor"
                app.EditField_7.Value=2000;
                app.EditField_8.Value=1.4;
                app.EditField_9.Value=226.5;
                app.EditField_112.Value=706;
                app.EditField2.Value=16.5;
                app.EditField_10.Value=488.5;
                %Panou "Aria supr schimb de caldura"
                app.EditField_14.Value=7600;
                app.EditField_15.Value=242;
                app.EditField_16.Value=5675;
                %Panou "Date fluid motor proaspat"
                app.DcmEditField_2.Value=826;
                app.EditField_11.Value=43000;
                app.EditField_12.Value=301.15;
                app.EditField_13.Value=98100;
                app.EditField_113.Value=287752;
                app.EditField_22.Value=0.93;
                app.EditField_109.Value=14.7;
                %Panou "Alte date"
                app.EditField_20.Value=287.12;
                app.EditField_21.Value=1.68;
                %Panou date proces injectie
                app.EditField_23.Value=8;
                app.EditField_24.Value=0.12;
                app.EditField_25.Value=0.9;
                app.EditField_26.Value=700;
                app.EditField_27.Value=0.001;
                app.EditField_28.Value=0;
                %Panou Injectia principala
                app.DcmEditField_3.Value=27.4;
                app.EditField_29.Value=-11.6;
                app.EditField_30.Value=11.6;
                app.EditField_31.Value=2;
                app.EditField_32.Value=2;
                %Panouri pre si post injectii
                app.EditField_33.Value=0;
                app.EditField_34.Value=0;
                app.EditField_35.Value=0;
                app.EditField_36.Value=0;
                app.EditField_37.Value=0;
                app.EditField_38.Value=0;
                app.EditField_39.Value=0;
                app.EditField_40.Value=0;
                app.EditField_41.Value=0;
                app.EditField_42.Value=0;
                app.EditField_43.Value=0;
                app.EditField_44.Value=0;
                %sarcina
                app.EditField_114.Value=1;
                
            elseif(strcmp(value,'Ford Duratec 1.6')) %daca utilizatorul alege Ford
                %Panou "Dimeniuni motor"
                app.DcmEditField.Value=7.9;
                app.EditField_3.Value=8.14;
                app.EditField_4.Value=13.9;
                app.EditField_5.Value=11;
                app.EditField_6.Value=0;
                %Panou "Date regim functionare motor"
                app.EditField_7.Value=2000;
                app.EditField_8.Value=0.98;
                app.EditField_9.Value=222;
                app.EditField_112.Value=714;
                app.EditField2.Value=6;
                app.EditField_10.Value=505;
                %Panou "Aria supr schimb de caldura"
                app.EditField_14.Value=4901;
                app.EditField_15.Value=270;
                app.EditField_16.Value=7550;
                %Panou "Date fluid motor proaspat"
                app.DcmEditField_2.Value=760;
                app.EditField_11.Value=44000;
                app.EditField_12.Value=298.15;
                app.EditField_13.Value=71800;
                app.EditField_113.Value=117752;
                app.EditField_22.Value=0.93;
                app.EditField_109.Value=14.8;
                %Panou "Alte date"
                app.EditField_20.Value=287.12;
                app.EditField_21.Value=0;
                %Panou date proces injectie
                app.EditField_23.Value=0;
                app.EditField_24.Value=0;
                app.EditField_25.Value=0.95;
                app.EditField_26.Value=0;
                app.EditField_27.Value=0;
                app.EditField_28.Value=0;
                %Panou Injectia principala
                app.DcmEditField_3.Value=18.5;
                app.EditField_29.Value=0;
                app.EditField_30.Value=0;
                app.EditField_31.Value=0;
                app.EditField_32.Value=0;
                %Panouri pre si post injectii
                app.EditField_33.Value=0;
                app.EditField_34.Value=0;
                app.EditField_35.Value=0;
                app.EditField_36.Value=0;
                app.EditField_37.Value=0;
                app.EditField_38.Value=0;
                app.EditField_39.Value=0;
                app.EditField_40.Value=0;
                app.EditField_41.Value=0;
                app.EditField_42.Value=0;
                app.EditField_43.Value=0;
                app.EditField_44.Value=0;
                %sarcina
                app.EditField_114.Value=0.6;
            end
            
        end

        % Value changed function: EditField_113
        function EditField_113ValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CombustionDARTUIFigure = uifigure('Visible', 'off');
            app.CombustionDARTUIFigure.Name = 'Combustion™ DART';
            app.CombustionDARTUIFigure.Icon = 'fire.gif';
            app.TabGroup = uitabgroup(app.CombustionDARTUIFigure);
            app.IntroduceredatemotorTab = uitab(app.TabGroup);
            app.IntroduceredatemotorTab.Title = 'Introducere date motor';
            app.CoeficientiitemperaturiipieselorPanel = uipanel(app.IntroduceredatemotorTab);
            app.CoeficientiitemperaturiipieselorPanel.Title = 'Coeficientii temperaturii pieselor';
            app.EditField_17 = uieditfield(app.CoeficientiitemperaturiipieselorPanel, 'numeric');
            app.EditField_17.Value = 1;
            app.EditField_18 = uieditfield(app.CoeficientiitemperaturiipieselorPanel, 'numeric');
            app.EditField_18.Value = 1;
            app.EditField_19 = uieditfield(app.CoeficientiitemperaturiipieselorPanel, 'numeric');
            app.EditField_19.Value = 1;
            app.CotemppistLabel = uilabel(app.CoeficientiitemperaturiipieselorPanel);
            app.CotemppistLabel.Tooltip = {'Coeficientul temperaturii pistonului'};
            app.CotemppistLabel.Text = 'Co. temp. pist.';
            app.CotempchiulasaLabel = uilabel(app.CoeficientiitemperaturiipieselorPanel);
            app.CotempchiulasaLabel.Tooltip = {'Coeficientul temperaturii chiulasei'};
            app.CotempchiulasaLabel.Text = 'Co. temp. chiulasa';
            app.CotempcilLabel = uilabel(app.CoeficientiitemperaturiipieselorPanel);
            app.CotempcilLabel.Tooltip = {'Coeficientul temperaturii peretelui cilindrului'};
            app.CotempcilLabel.Text = 'Co. temp. cil.';
            app.AriasuprafetelorschimbdecalduraPanel = uipanel(app.IntroduceredatemotorTab);
            app.AriasuprafetelorschimbdecalduraPanel.Tooltip = {'Aria suprafetelor - schimb de caldura'};
            app.AriasuprafetelorschimbdecalduraPanel.Title = 'Aria suprafetelor-schimb de caldura';
            app.EditField_14 = uieditfield(app.AriasuprafetelorschimbdecalduraPanel, 'numeric');
            app.EditField_14.Value = 7600;
            app.EditField_15 = uieditfield(app.AriasuprafetelorschimbdecalduraPanel, 'numeric');
            app.EditField_15.Value = 242;
            app.EditField_16 = uieditfield(app.AriasuprafetelorschimbdecalduraPanel, 'numeric');
            app.EditField_16.Value = 5675;
            app.Apistonmm2Label = uilabel(app.AriasuprafetelorschimbdecalduraPanel);
            app.Apistonmm2Label.Tooltip = {'Aria capului pistonului'};
            app.Apistonmm2Label.Text = 'A. piston [mm^2]';
            app.AcilPMSmm2Label = uilabel(app.AriasuprafetelorschimbdecalduraPanel);
            app.AcilPMSmm2Label.Tooltip = {'Aria suprafetei peretelui cilindrului cand pistonul se afla in PMS'};
            app.AcilPMSmm2Label.Text = 'A. cil. PMS [mm^2]';
            app.Achiulasamm2Label = uilabel(app.AriasuprafetelorschimbdecalduraPanel);
            app.Achiulasamm2Label.Tooltip = {'Aria suprafetei chiulasei'};
            app.Achiulasamm2Label.Text = 'A. chiulasa [mm^2]';
            app.Preinjectie1Panel = uipanel(app.IntroduceredatemotorTab);
            app.Preinjectie1Panel.Title = 'Pre-injectie 1';
            app.EditField_33 = uieditfield(app.Preinjectie1Panel, 'numeric');
            app.MasainjmgLabel_2 = uilabel(app.Preinjectie1Panel);
            app.MasainjmgLabel_2.Tooltip = {'Masa de combustibil injectata'};
            app.MasainjmgLabel_2.Text = 'Masa inj. [mg]';
            app.EditField_34 = uieditfield(app.Preinjectie1Panel, 'numeric');
            app.EditField_35 = uieditfield(app.Preinjectie1Panel, 'numeric');
            app.InceputulinjRACLabel_2 = uilabel(app.Preinjectie1Panel);
            app.InceputulinjRACLabel_2.Text = 'Inceputul inj. [°RAC]';
            app.DurataRACLabel_2 = uilabel(app.Preinjectie1Panel);
            app.DurataRACLabel_2.Tooltip = {'Durata injectiei'};
            app.DurataRACLabel_2.Text = 'Durata [°RAC]';
            app.Preinjectie2Panel = uipanel(app.IntroduceredatemotorTab);
            app.Preinjectie2Panel.Title = 'Pre-injectie 2';
            app.EditField_36 = uieditfield(app.Preinjectie2Panel, 'numeric');
            app.MasainjmgLabel_3 = uilabel(app.Preinjectie2Panel);
            app.MasainjmgLabel_3.Tooltip = {'Masa de combustibil injectata'};
            app.MasainjmgLabel_3.Text = 'Masa inj. [mg]';
            app.EditField_37 = uieditfield(app.Preinjectie2Panel, 'numeric');
            app.EditField_38 = uieditfield(app.Preinjectie2Panel, 'numeric');
            app.InceputulinjRACLabel_3 = uilabel(app.Preinjectie2Panel);
            app.InceputulinjRACLabel_3.Text = 'Inceputul inj. [°RAC]';
            app.DurataRACLabel_3 = uilabel(app.Preinjectie2Panel);
            app.DurataRACLabel_3.Tooltip = {'Durata injectiei'};
            app.DurataRACLabel_3.Text = 'Durata [°RAC]';
            app.Postinjectie1Panel_2 = uipanel(app.IntroduceredatemotorTab);
            app.Postinjectie1Panel_2.Title = 'Post-injectie 1';
            app.EditField_39 = uieditfield(app.Postinjectie1Panel_2, 'numeric');
            app.MasainjmgLabel_4 = uilabel(app.Postinjectie1Panel_2);
            app.MasainjmgLabel_4.Tooltip = {'Masa de combustibil injectata'};
            app.MasainjmgLabel_4.Text = 'Masa inj. [mg]';
            app.EditField_40 = uieditfield(app.Postinjectie1Panel_2, 'numeric');
            app.EditField_41 = uieditfield(app.Postinjectie1Panel_2, 'numeric');
            app.InceputulinjRACLabel_4 = uilabel(app.Postinjectie1Panel_2);
            app.InceputulinjRACLabel_4.Tooltip = {'Inceputul procesului de injectie (0=PMS)'};
            app.InceputulinjRACLabel_4.Text = 'Inceputul inj. [°RAC]';
            app.DurataRACLabel_4 = uilabel(app.Postinjectie1Panel_2);
            app.DurataRACLabel_4.Tooltip = {'Durata injectiei'};
            app.DurataRACLabel_4.Text = 'Durata [°RAC]';
            app.Postinjectie2Panel = uipanel(app.IntroduceredatemotorTab);
            app.Postinjectie2Panel.Title = 'Post-injectie 2';
            app.EditField_42 = uieditfield(app.Postinjectie2Panel, 'numeric');
            app.MasainjmgLabel_5 = uilabel(app.Postinjectie2Panel);
            app.MasainjmgLabel_5.Tooltip = {'Masa de combustibil injectata'};
            app.MasainjmgLabel_5.Text = 'Masa inj. [mg]';
            app.EditField_43 = uieditfield(app.Postinjectie2Panel, 'numeric');
            app.EditField_44 = uieditfield(app.Postinjectie2Panel, 'numeric');
            app.InceputulinjRACLabel_5 = uilabel(app.Postinjectie2Panel);
            app.InceputulinjRACLabel_5.Tooltip = {'Inceputul procesului de injectie (0=PMS)'};
            app.InceputulinjRACLabel_5.Text = 'Inceputul inj. [°RAC]';
            app.DurataRACLabel_5 = uilabel(app.Postinjectie2Panel);
            app.DurataRACLabel_5.Tooltip = {'Durata injectiei'};
            app.DurataRACLabel_5.Text = 'Durata [°RAC]';
            app.DimensiunimotorPanel = uipanel(app.IntroduceredatemotorTab);
            app.DimensiunimotorPanel.Title = 'Dimensiuni motor';
            app.DcmEditField = uieditfield(app.DimensiunimotorPanel, 'numeric');
            app.DcmEditField.Value = 8.5;
            app.EditField_3 = uieditfield(app.DimensiunimotorPanel, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%.1f';
            app.EditField_3.Value = 9;
            app.DcmLabel = uilabel(app.DimensiunimotorPanel);
            app.DcmLabel.Tooltip = {'Alezajul'};
            app.DcmLabel.Text = 'D [cm]';
            app.ScmLabel = uilabel(app.DimensiunimotorPanel);
            app.ScmLabel.Tooltip = {'Cursa pistonului'};
            app.ScmLabel.Text = 'S [cm]';
            app.LbcmLabel = uilabel(app.DimensiunimotorPanel);
            app.LbcmLabel.Tooltip = {'Lungimea bielei'};
            app.LbcmLabel.Text = 'Lb [cm]';
            app.Label_4 = uilabel(app.DimensiunimotorPanel);
            app.Label_4.Tooltip = {'Raportul de comprimare'};
            app.Label_4.Text = 'ε [-]';
            app.emmLabel = uilabel(app.DimensiunimotorPanel);
            app.emmLabel.Tooltip = {'Excentricitatea boltului pistonului'};
            app.emmLabel.Text = 'e [mm]';
            app.EditField_4 = uieditfield(app.DimensiunimotorPanel, 'numeric');
            app.EditField_4.Value = 13.8;
            app.EditField_5 = uieditfield(app.DimensiunimotorPanel, 'numeric');
            app.EditField_5.Value = 17.1;
            app.EditField_6 = uieditfield(app.DimensiunimotorPanel, 'numeric');
            app.EditField_6.Value = 0.55;
            app.DateregimfunctionaremotorPanel = uipanel(app.IntroduceredatemotorTab);
            app.DateregimfunctionaremotorPanel.Title = 'Date regim functionare motor';
            app.EditField_7 = uieditfield(app.DateregimfunctionaremotorPanel, 'numeric');
            app.EditField_7.Value = 2000;
            app.EditField_8 = uieditfield(app.DateregimfunctionaremotorPanel, 'numeric');
            app.EditField_8.Value = 1.4;
            app.EditField_9 = uieditfield(app.DateregimfunctionaremotorPanel, 'numeric');
            app.EditField_9.Value = 226.5;
            app.nrotminLabel = uilabel(app.DateregimfunctionaremotorPanel);
            app.nrotminLabel.Tooltip = {'Turatia'};
            app.nrotminLabel.Text = 'n [rot/min';
            app.Label_5 = uilabel(app.DateregimfunctionaremotorPanel);
            app.Label_5.Tooltip = {'Coeficientul de exces de aer'};
            app.Label_5.Text = 'λ [-]';
            app.ISARACLabel = uilabel(app.DateregimfunctionaremotorPanel);
            app.ISARACLabel.Tooltip = {'Inchiderea supapelor de admisie'};
            app.ISARACLabel.Text = 'ISA  [°RAC]';
            app.DSERACLabel = uilabel(app.DateregimfunctionaremotorPanel);
            app.DSERACLabel.Tooltip = {'Deschiderea supapelor de evacuare'};
            app.DSERACLabel.Text = 'DSE [°RAC]';
            app.EditField_112 = uieditfield(app.DateregimfunctionaremotorPanel, 'numeric');
            app.EditField_112.Value = 706;
            app.DSARACLabel = uilabel(app.DateregimfunctionaremotorPanel);
            app.DSARACLabel.Tooltip = {'Deschiderea supapelor de admisie'};
            app.DSARACLabel.Text = 'DSA  [°RAC] ';
            app.EditField2 = uieditfield(app.DateregimfunctionaremotorPanel, 'numeric');
            app.EditField2.Value = 16.5;
            app.ISERACLabel = uilabel(app.DateregimfunctionaremotorPanel);
            app.ISERACLabel.Tooltip = {'Inchiderea supapelor de evacuare'};
            app.ISERACLabel.Text = 'ISE  [°RAC]';
            app.EditField_10 = uieditfield(app.DateregimfunctionaremotorPanel, 'numeric');
            app.EditField_10.Value = 488.5;
            app.EditField_114 = uieditfield(app.DateregimfunctionaremotorPanel, 'numeric');
            app.EditField_114.Value = 1;
            app.CoefsarcinaLabel = uilabel(app.DateregimfunctionaremotorPanel);
            app.CoefsarcinaLabel.Tooltip = {'Coeficientul de sarcină. Sarcina = 100*Coeficientul'};
            app.CoefsarcinaLabel.Text = 'Coef. sarcina [-]';
            app.Label_10 = uilabel(app.IntroduceredatemotorTab);
            app.Label_10.Text = '';
            app.DatefluidmotorproaspatPanel = uipanel(app.IntroduceredatemotorTab);
            app.DatefluidmotorproaspatPanel.Title = 'Date fluid motor proaspat';
            app.DcmEditField_2 = uieditfield(app.DatefluidmotorproaspatPanel, 'numeric');
            app.DcmEditField_2.Value = 826;
            app.EditField_11 = uieditfield(app.DatefluidmotorproaspatPanel, 'numeric');
            app.EditField_11.ValueDisplayFormat = '%.0f';
            app.EditField_11.Value = 43000;
            app.combkgm3Label = uilabel(app.DatefluidmotorproaspatPanel);
            app.combkgm3Label.Tooltip = {'Densitatea combustibilului'};
            app.combkgm3Label.Text = ' ρ comb. [kg/m^3]';
            app.HikJkgLabel = uilabel(app.DatefluidmotorproaspatPanel);
            app.HikJkgLabel.Tooltip = {'Puterea calorica inferioara a combustibilului'};
            app.HikJkgLabel.Text = 'Hi [kJ/kg]';
            app.TaerKLabel = uilabel(app.DatefluidmotorproaspatPanel);
            app.TaerKLabel.Tooltip = {'Temperatura aerului  atmosferic'};
            app.TaerKLabel.Text = 'T. aer [K]';
            app.PaeratmosfericPaLabel = uilabel(app.DatefluidmotorproaspatPanel);
            app.PaeratmosfericPaLabel.Tooltip = {'Presiunea aerului atmosferic'};
            app.PaeratmosfericPaLabel.Text = 'P. aer atmosferic [Pa]';
            app.EditField_12 = uieditfield(app.DatefluidmotorproaspatPanel, 'numeric');
            app.EditField_12.ValueDisplayFormat = '%.2f';
            app.EditField_12.Value = 301.15;
            app.EditField_13 = uieditfield(app.DatefluidmotorproaspatPanel, 'numeric');
            app.EditField_13.ValueDisplayFormat = '%.0f';
            app.EditField_13.Value = 98100;
            app.EditField_22 = uieditfield(app.DatefluidmotorproaspatPanel, 'numeric');
            app.EditField_22.Value = 0.93;
            app.uLabel_2 = uilabel(app.DatefluidmotorproaspatPanel);
            app.uLabel_2.Tooltip = {'Coeficientul de umplere'};
            app.uLabel_2.Text = 'ηu [-]';
            app.EditField_109 = uieditfield(app.DatefluidmotorproaspatPanel, 'numeric');
            app.EditField_109.Value = 14.7;
            app.AfrLabel = uilabel(app.DatefluidmotorproaspatPanel);
            app.AfrLabel.Tooltip = {'Raport normalizat stoechiometric aer/combustibil'};
            app.AfrLabel.Text = 'Afr';
            app.EditField_113 = uieditfield(app.DatefluidmotorproaspatPanel, 'numeric');
            app.EditField_113.ValueDisplayFormat = '%.0f';
            app.EditField_113.ValueChangedFcn = createCallbackFcn(app, @EditField_113ValueChanged, true);
            app.EditField_113.Value = 287752;
            app.PgalerieevacPaLabel = uilabel(app.DatefluidmotorproaspatPanel);
            app.PgalerieevacPaLabel.Tooltip = {'Presiunea din galeria de evacuare'};
            app.PgalerieevacPaLabel.Text = 'P. galerie evac. [Pa]';
            app.AltedateutilizateincalculePanel = uipanel(app.IntroduceredatemotorTab);
            app.AltedateutilizateincalculePanel.Title = 'Alte date utilizate in calcule';
            app.EditField_20 = uieditfield(app.AltedateutilizateincalculePanel, 'numeric');
            app.EditField_20.ValueDisplayFormat = '%.2f';
            app.EditField_20.Value = 287.12;
            app.EditField_21 = uieditfield(app.AltedateutilizateincalculePanel, 'numeric');
            app.EditField_21.Value = 1.68;
            app.RJkgKLabel = uilabel(app.AltedateutilizateincalculePanel);
            app.RJkgKLabel.Tooltip = {'Constanta gazului ideal'};
            app.RJkgKLabel.Text = 'R [J/kg*K]';
            app.CifraSwirlLabel = uilabel(app.AltedateutilizateincalculePanel);
            app.CifraSwirlLabel.Tooltip = {'Cifra Swirl'};
            app.CifraSwirlLabel.Text = 'Cifra Swirl [-]';
            app.InjectiaprincipalaPanel_2 = uipanel(app.IntroduceredatemotorTab);
            app.InjectiaprincipalaPanel_2.Title = 'Injectia principala';
            app.DcmEditField_3 = uieditfield(app.InjectiaprincipalaPanel_2, 'numeric');
            app.DcmEditField_3.Value = 27.4;
            app.EditField_29 = uieditfield(app.InjectiaprincipalaPanel_2, 'numeric');
            app.EditField_29.Value = -11.6;
            app.MasainjmgLabel = uilabel(app.InjectiaprincipalaPanel_2);
            app.MasainjmgLabel.Tooltip = {'Masa de combustibil injectata'};
            app.MasainjmgLabel.Text = 'Masa inj. [mg]';
            app.InceputulinjRACLabel = uilabel(app.InjectiaprincipalaPanel_2);
            app.InceputulinjRACLabel.Tooltip = {'Inceputul procesului de injectie (0=PMS)'};
            app.InceputulinjRACLabel.Text = 'Inceputul inj. [°RAC]';
            app.DurataRACLabel = uilabel(app.InjectiaprincipalaPanel_2);
            app.DurataRACLabel.Tooltip = {'Durata procesului de injectie'};
            app.DurataRACLabel.Text = 'Durata [°RAC]';
            app.DurrampeiRACLabel = uilabel(app.InjectiaprincipalaPanel_2);
            app.DurrampeiRACLabel.Tooltip = {'Durata rampei'};
            app.DurrampeiRACLabel.Text = 'Dur.  rampei [°RAC]';
            app.DurpanteiRACLabel = uilabel(app.InjectiaprincipalaPanel_2);
            app.DurpanteiRACLabel.Tooltip = {'Durata pantei'};
            app.DurpanteiRACLabel.Text = 'Dur. pantei [°RAC]';
            app.EditField_30 = uieditfield(app.InjectiaprincipalaPanel_2, 'numeric');
            app.EditField_30.Value = 11.6;
            app.EditField_31 = uieditfield(app.InjectiaprincipalaPanel_2, 'numeric');
            app.EditField_31.Value = 2;
            app.EditField_32 = uieditfield(app.InjectiaprincipalaPanel_2, 'numeric');
            app.EditField_32.Value = 2;
            app.DateprocesinjectiePanel = uipanel(app.IntroduceredatemotorTab);
            app.DateprocesinjectiePanel.Title = 'Date proces injectie';
            app.EditField_23 = uieditfield(app.DateprocesinjectiePanel, 'numeric');
            app.EditField_23.Value = 8;
            app.EditField_24 = uieditfield(app.DateprocesinjectiePanel, 'numeric');
            app.EditField_24.Value = 0.12;
            app.EditField_25 = uieditfield(app.DateprocesinjectiePanel, 'numeric');
            app.EditField_25.Value = 0.9;
            app.EditField_26 = uieditfield(app.DateprocesinjectiePanel, 'numeric');
            app.EditField_26.Value = 700;
            app.EditField_27 = uieditfield(app.DateprocesinjectiePanel, 'numeric');
            app.EditField_27.Value = 0.001;
            app.NrorificiiLabel = uilabel(app.DateprocesinjectiePanel);
            app.NrorificiiLabel.Tooltip = {'Numarul de orificii ale injectorului'};
            app.NrorificiiLabel.Text = 'Nr. orificii [-]';
            app.DiamorificiummLabel = uilabel(app.DateprocesinjectiePanel);
            app.DiamorificiummLabel.Tooltip = {'Diametrul unui orificiu'};
            app.DiamorificiummLabel.Text = 'Diam. orificiu [mm]';
            app.CoefdebitLabel = uilabel(app.DateprocesinjectiePanel);
            app.CoefdebitLabel.Tooltip = {'Coeficientul de debit'};
            app.CoefdebitLabel.Text = 'Coef. debit [-]';
            app.PinjbarLabel = uilabel(app.DateprocesinjectiePanel);
            app.PinjbarLabel.Tooltip = {'Presiunea de injectie'};
            app.PinjbarLabel.Text = 'P. inj [bar]';
            app.PasulRACLabel = uilabel(app.DateprocesinjectiePanel);
            app.PasulRACLabel.Text = 'Pasul [°RAC]';
            app.EditField_28 = uieditfield(app.DateprocesinjectiePanel, 'numeric');
            app.ParamcorectatLabel = uilabel(app.DateprocesinjectiePanel);
            app.ParamcorectatLabel.Tooltip = {'Parametrul de corectat al procesului de injectie '; ''; '(0-viteza)'; '(1-durata)'};
            app.ParamcorectatLabel.Text = 'Param. corectat';
            app.AlegererapidaDropDownLabel = uilabel(app.IntroduceredatemotorTab);
            app.AlegererapidaDropDownLabel.Text = 'Alegere rapida';
            app.AlegererapidaDropDown = uidropdown(app.IntroduceredatemotorTab);
            app.AlegererapidaDropDown.Items = {'AVL 5402', 'Ford Duratec 1.6'};
            app.AlegererapidaDropDown.ValueChangedFcn = createCallbackFcn(app, @AlegererapidaDropDownValueChanged, true);
            app.AlegererapidaDropDown.Value = 'AVL 5402';
            app.AnalizaprincipiulITab = uitab(app.TabGroup);
            app.AnalizaprincipiulITab.Title = 'Analiza principiul I';
            app.TabGroup2 = uitabgroup(app.AnalizaprincipiulITab);
            app.FiltrareCurba = uitab(app.TabGroup2);
            app.FiltrareCurba.Title = 'Importare si filtrare date';
            app.UIAxes = uiaxes(app.FiltrareCurba);
            app.FiltraredatePanel = uipanel(app.FiltrareCurba);
            app.FiltraredatePanel.Title = 'Filtrare date';
            app.FiltrareButton = uibutton(app.FiltraredatePanel, 'push');
            app.FiltrareButton.ButtonPushedFcn = createCallbackFcn(app, @FiltrareButtonPushed, true);
            app.FiltrareButton.Text = 'Filtrare';
            app.EditField_84 = uieditfield(app.FiltraredatePanel, 'numeric');
            app.EditField_84.Value = 0.0001;
            app.EditField3 = uieditfield(app.FiltraredatePanel, 'numeric');
            app.EditField3.Value = 0.87;
            app.EditField_85 = uieditfield(app.FiltraredatePanel, 'numeric');
            app.EditField_85.Value = 100;
            app.FrecventanormalizatabandadetrecereLabel = uilabel(app.FiltraredatePanel);
            app.FrecventanormalizatabandadetrecereLabel.Text = 'Frecventa normalizata banda de trecere';
            app.AtenuareabenziideoprireLabel = uilabel(app.FiltraredatePanel);
            app.AtenuareabenziideoprireLabel.Text = 'Atenuarea benzii de oprire';
            app.CoeficientulSteepnessLabel = uilabel(app.FiltraredatePanel);
            app.CoeficientulSteepnessLabel.Text = 'Coeficientul Steepness';
            app.DefinireaparametrilorfiltruluitrecejosLabel = uilabel(app.FiltraredatePanel);
            app.DefinireaparametrilorfiltruluitrecejosLabel.Text = 'Definirea parametrilor filtrului trece-jos';
            app.AvansareaintarziereacurbeidepresiuneLabel = uilabel(app.FiltrareCurba);
            app.AvansareaintarziereacurbeidepresiuneLabel.Text = 'Avansarea/ intarzierea curbei de presiune';
            app.Spinner = uispinner(app.FiltrareCurba);
            app.Spinner.Step = 0.1;
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.RACLabel_3 = uilabel(app.FiltrareCurba);
            app.RACLabel_3.Text = '[RAC]';
            app.ImportaredatePanel = uipanel(app.FiltrareCurba);
            app.ImportaredatePanel.Title = 'Importare date';
            app.ImportarecurbapresiunemotorinfunctionareButton = uibutton(app.ImportaredatePanel, 'push');
            app.ImportarecurbapresiunemotorinfunctionareButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarecurbapresiunemotorinfunctionareButtonPushed, true);
            app.ImportarecurbapresiunemotorinfunctionareButton.Text = 'Importare curba presiune - motor in functionare';
            app.ImportarecurbapresiunemotorantrenatButton = uibutton(app.ImportaredatePanel, 'push');
            app.ImportarecurbapresiunemotorantrenatButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarecurbapresiunemotorantrenatButtonPushed, true);
            app.ImportarecurbapresiunemotorantrenatButton.Text = 'Importare curba presiune - motor antrenat';
            app.CalculparametriiTab = uitab(app.TabGroup2);
            app.CalculparametriiTab.Title = 'Calcul parametrii';
            app.UIAxes2 = uiaxes(app.CalculparametriiTab);
            zlabel(app.UIAxes2, 'Z')
            app.CalculparametriiButtonDan = uibutton(app.CalculparametriiTab, 'push');
            app.CalculparametriiButtonDan.ButtonPushedFcn = createCallbackFcn(app, @CalculparametriiButtonDanPushed, true);
            app.CalculparametriiButtonDan.Text = 'Calcul parametrii';
            app.UITable = uitable(app.CalculparametriiTab);
            app.UITable.ColumnName = {'Parametru'; 'Valoare'};
            app.UITable.RowName = {};
            app.UITable.Tooltip = {'Date rezultate in urma calculelor'};
            app.ImportarepresiunemodelWiebeButton = uibutton(app.CalculparametriiTab, 'push');
            app.ImportarepresiunemodelWiebeButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarepresiunemodelWiebeButtonPushed, true);
            app.ImportarepresiunemodelWiebeButton.Text = 'Importare presiune model Wiebe';
            app.ExpuneregraficarezultateTab = uitab(app.TabGroup2);
            app.ExpuneregraficarezultateTab.Title = 'Expunere grafica rezultate';
            app.UIAxes3 = uiaxes(app.ExpuneregraficarezultateTab);
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.ExpuneregraficarezultateTab);
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.ExpuneregraficarezultateTab);
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.ExpuneregraficarezultateTab);
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.ExpuneregraficarezultateTab);
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.ExpuneregraficarezultateTab);
            zlabel(app.UIAxes8, 'Z')
            app.AfisarerezultateinintervalulButton = uibutton(app.ExpuneregraficarezultateTab, 'push');
            app.AfisarerezultateinintervalulButton.ButtonPushedFcn = createCallbackFcn(app, @AfisarerezultateinintervalulButtonPushed, true);
            app.AfisarerezultateinintervalulButton.Text = 'Afisare rezultate in intervalul:';
            app.EditField_86 = uieditfield(app.ExpuneregraficarezultateTab, 'numeric');
            app.EditField_86.Value = 340;
            app.EditField_87 = uieditfield(app.ExpuneregraficarezultateTab, 'numeric');
            app.EditField_87.Value = 420;
            app.Label_8 = uilabel(app.ExpuneregraficarezultateTab);
            app.Label_8.Text = '-';
            app.RACLabel_4 = uilabel(app.ExpuneregraficarezultateTab);
            app.RACLabel_4.Text = '[°RAC]';
            app.AfisarevalorimaximeButton = uibutton(app.ExpuneregraficarezultateTab, 'push');
            app.AfisarevalorimaximeButton.ButtonPushedFcn = createCallbackFcn(app, @AfisarevalorimaximeButtonPushed, true);
            app.AfisarevalorimaximeButton.Text = 'Afisare valori maxime';
            app.AfisaredurataprocesuluideardereButton = uibutton(app.ExpuneregraficarezultateTab, 'push');
            app.AfisaredurataprocesuluideardereButton.ButtonPushedFcn = createCallbackFcn(app, @AfisaredurataprocesuluideardereButtonPushed, true);
            app.AfisaredurataprocesuluideardereButton.Text = 'Afisare durata procesului de ardere';
            app.ExpuneredateExcelButton = uibutton(app.ExpuneregraficarezultateTab, 'push');
            app.ExpuneredateExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExpuneredateExcelButtonPushed, true);
            app.ExpuneredateExcelButton.Text = 'Expunere date Excel';
            app.AnalizamodelardereTab = uitab(app.TabGroup);
            app.AnalizamodelardereTab.Title = 'Analiza model ardere';
            app.TabGroup3 = uitabgroup(app.AnalizamodelardereTab);
            app.WiebeDateTab = uitab(app.TabGroup3);
            app.WiebeDateTab.Title = 'Wiebe (Date)';
            app.UIAxes10 = uiaxes(app.WiebeDateTab);
            app.UIAxes14 = uiaxes(app.WiebeDateTab);
            app.UIAxes13 = uiaxes(app.WiebeDateTab);
            app.UIAxes11 = uiaxes(app.WiebeDateTab);
            app.CalcultransfertermicPanel = uipanel(app.WiebeDateTab);
            app.CalcultransfertermicPanel.Title = 'Calcul transfer termic';
            app.MetodadecalculpentrucoeficientuldetransfertermicLabel = uilabel(app.CalcultransfertermicPanel);
            app.MetodadecalculpentrucoeficientuldetransfertermicLabel.Text = {'Metoda de calcul pentru coeficientul'; 'de transfer termic'};
            app.Transfer = uidropdown(app.CalcultransfertermicPanel);
            app.Transfer.Items = {'Woschni 1978', 'Annand', 'Hohenberg', 'Eichelberg', 'Fara transfer termic'};
            app.Transfer.ValueChangedFcn = createCallbackFcn(app, @TransferValueChanged, true);
            app.Transfer.Value = 'Woschni 1978';
            app.CoeficientAnnandLabel = uilabel(app.CalcultransfertermicPanel);
            app.CoeficientAnnandLabel.Visible = 'off';
            app.CoeficientAnnandLabel.Text = 'Coeficient Annand';
            app.EditField_110 = uieditfield(app.CalcultransfertermicPanel, 'numeric');
            app.EditField_110.Limits = [0.35 0.8];
            app.EditField_110.Visible = 'off';
            app.EditField_110.Value = 0.5;
            app.NormalizarevitezeButton = uibutton(app.WiebeDateTab, 'push');
            app.NormalizarevitezeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizarevitezeButtonPushed, true);
            app.NormalizarevitezeButton.Text = 'Normalizare viteze';
            app.ExportdateExcelButton = uibutton(app.WiebeDateTab, 'push');
            app.ExportdateExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdateExcelButtonPushed, true);
            app.ExportdateExcelButton.Text = 'Export date Excel';
            app.RaportulcaldurilorspecificeLabel = uilabel(app.WiebeDateTab);
            app.RaportulcaldurilorspecificeLabel.Text = 'Raportul caldurilor specifice';
            app.EditField_105 = uieditfield(app.WiebeDateTab, 'numeric');
            app.EditField_105.Value = 1.265;
            app.DatecalculatearderePanel = uipanel(app.WiebeDateTab);
            app.DatecalculatearderePanel.Title = 'Date calculate ardere';
            app.UITable2 = uitable(app.DatecalculatearderePanel);
            app.UITable2.ColumnName = {'Parametru'; 'Unghi'};
            app.UITable2.RowName = {};
            app.EditField_108 = uieditfield(app.DatecalculatearderePanel, 'numeric');
            app.EditField_108.Limits = [80 99];
            app.EditField_108.Value = 95;
            app.EditField_107 = uieditfield(app.DatecalculatearderePanel, 'numeric');
            app.EditField_107.Limits = [0 10];
            app.EditField_107.Value = 5;
            app.Lim2Label = uilabel(app.DatecalculatearderePanel);
            app.Lim2Label.Text = 'Lim2 [%]';
            app.Lim1Label = uilabel(app.DatecalculatearderePanel);
            app.Lim1Label.Text = 'Lim1 [%]';
            app.ParametriifunctieiWiebePanel = uipanel(app.WiebeDateTab);
            app.ParametriifunctieiWiebePanel.Title = 'Parametrii functiei Wiebe';
            app.EditField_91 = uieditfield(app.ParametriifunctieiWiebePanel, 'numeric');
            app.EditField_91.Limits = [0 1];
            app.EditField_91.Value = 0.5573;
            app.a1EditField = uieditfield(app.ParametriifunctieiWiebePanel, 'numeric');
            app.a1EditField.Limits = [2.3026 6.9078];
            app.a1EditField.Tooltip = {'Primul parametru de eficienta'};
            app.a1EditField.Value = 4.252;
            app.o1RACEditField = uieditfield(app.ParametriifunctieiWiebePanel, 'numeric');
            app.o1RACEditField.Editable = 'off';
            app.o1RACEditField.Enable = 'off';
            app.o1RACEditField.Tooltip = {'Inceputul arderii'};
            app.o1RACEditField.Value = -10;
            app.RACEditField = uieditfield(app.ParametriifunctieiWiebePanel, 'numeric');
            app.RACEditField.Tooltip = {'Durata arderii pentru procesul de ardere rapida'};
            app.RACEditField.Value = 6.608;
            app.m1EditField = uieditfield(app.ParametriifunctieiWiebePanel, 'numeric');
            app.m1EditField.Limits = [0 Inf];
            app.m1EditField.Tooltip = {'Primul parametru de forma'};
            app.m1EditField.Value = 3.458;
            app.o2RACEditField = uieditfield(app.ParametriifunctieiWiebePanel, 'numeric');
            app.o2RACEditField.ValueChangedFcn = createCallbackFcn(app, @o2RACEditFieldValueChanged, true);
            app.o2RACEditField.Tooltip = {'Inceputul arderii pentru procesul de ardere lenta'};
            app.o2RACEditField.Value = 4.299;
            app.RACEditField_2 = uieditfield(app.ParametriifunctieiWiebePanel, 'numeric');
            app.RACEditField_2.Tooltip = {'Durata arderii pentru procesul de ardere lenta'};
            app.RACEditField_2.Value = 30.88;
            app.m2EditField = uieditfield(app.ParametriifunctieiWiebePanel, 'numeric');
            app.m2EditField.Limits = [0 Inf];
            app.m2EditField.Tooltip = {'Al doilea parametru de forma'};
            app.m2EditField.Value = 0.1592;
            app.a2EditField = uieditfield(app.ParametriifunctieiWiebePanel, 'numeric');
            app.a2EditField.Limits = [2.3026 6.9078];
            app.a2EditField.Tooltip = {'Al doilea parametru de eficienta'};
            app.a2EditField.Value = 2.303;
            app.RACEditFieldLabel_2 = uilabel(app.ParametriifunctieiWiebePanel);
            app.RACEditFieldLabel_2.Tooltip = {'Durata arderii pentru procesul de ardere rapida'};
            app.RACEditFieldLabel_2.Text = '';
            app.Label_9 = uilabel(app.ParametriifunctieiWiebePanel);
            app.Label_9.Tooltip = {'Fractiunea amestecului care arde în faza de ardere rapida'};
            app.Label_9.Text = 'λ [-]';
            app.a1Label = uilabel(app.ParametriifunctieiWiebePanel);
            app.a1Label.Tooltip = {'Primul parametru de eficienta'};
            app.a1Label.Text = 'a1 [-]';
            app.o1RACLabel = uilabel(app.ParametriifunctieiWiebePanel);
            app.o1RACLabel.Tooltip = {'Inceputul arderii'};
            app.o1RACLabel.Text = 'Ѳo1 [°RAC]';
            app.RACLabel_5 = uilabel(app.ParametriifunctieiWiebePanel);
            app.RACLabel_5.Tooltip = {'Durata arderii pentru procesul de ardere rapida'};
            app.RACLabel_5.Text = 'ΔѲ1 [°RAC]';
            app.m1Label = uilabel(app.ParametriifunctieiWiebePanel);
            app.m1Label.Tooltip = {'Primul parametru de forma'};
            app.m1Label.Text = 'm1 [-]';
            app.a2Label = uilabel(app.ParametriifunctieiWiebePanel);
            app.a2Label.Tooltip = {'Al doilea parametru de eficienta'};
            app.a2Label.Text = 'a2 [-]';
            app.o2RACLabel = uilabel(app.ParametriifunctieiWiebePanel);
            app.o2RACLabel.Tooltip = {'Inceputul arderii pentru procesul de ardere lenta'};
            app.o2RACLabel.Text = 'Ѳo2 [°RAC]';
            app.RACLabel_6 = uilabel(app.ParametriifunctieiWiebePanel);
            app.RACLabel_6.Tooltip = {'Durata arderii pentru procesul de ardere lenta'};
            app.RACLabel_6.Text = 'ΔѲ2 [°RAC]';
            app.m2Label = uilabel(app.ParametriifunctieiWiebePanel);
            app.m2Label.Tooltip = {'Al doilea parametru de forma'};
            app.m2Label.Text = 'm2 [-]';
            app.Switch = uiswitch(app.ParametriifunctieiWiebePanel, 'slider');
            app.Switch.Items = {'Single Wiebe', 'Double Wiebe'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Double Wiebe';
            app.CalculmodelardereButton = uibutton(app.WiebeDateTab, 'push');
            app.CalculmodelardereButton.ButtonPushedFcn = createCallbackFcn(app, @CalculmodelardereButtonPushed, true);
            app.CalculmodelardereButton.Text = 'Calcul model ardere';
            app.DatearderePanel = uipanel(app.WiebeDateTab);
            app.DatearderePanel.Title = 'Date ardere';
            app.EditField_88 = uieditfield(app.DatearderePanel, 'numeric');
            app.EditField_88.ValueChangedFcn = createCallbackFcn(app, @EditField_88ValueChanged, true);
            app.EditField_88.Value = 0.8;
            app.InceputularderiiRACLabel = uilabel(app.DatearderePanel);
            app.InceputularderiiRACLabel.Tooltip = {'Unghiul in  °RAC dupa PMS'};
            app.InceputularderiiRACLabel.Text = 'Inceputul arderii  [°RAC]';
            app.DurataarderiiRACLabel = uilabel(app.DatearderePanel);
            app.DurataarderiiRACLabel.Text = 'Durata arderii  [°RAC]';
            app.EditField_90 = uieditfield(app.DatearderePanel, 'numeric');
            app.EditField_90.ValueChangedFcn = createCallbackFcn(app, @EditField_88ValueChanged, true);
            app.EditField_90.Value = 45;
            app.EditField_106 = uieditfield(app.DatearderePanel, 'numeric');
            app.EditField_106.Editable = 'off';
            app.EditField_106.Enable = 'off';
            app.SfarsitularderiiRACLabel = uilabel(app.DatearderePanel);
            app.SfarsitularderiiRACLabel.Text = 'Sfarsitul arderii  [°RAC]';
            app.Wmarimi = uicheckbox(app.WiebeDateTab);
            app.Wmarimi.ValueChangedFcn = createCallbackFcn(app, @WmarimiValueChanged, true);
            app.Wmarimi.Text = 'Afisare marimi masurate';
            app.Wmarimi.Value = true;
            app.WiebeGraficeTab = uitab(app.TabGroup3);
            app.WiebeGraficeTab.Title = 'Wiebe (Grafice)';
            app.UIAxes17 = uiaxes(app.WiebeGraficeTab);
            zlabel(app.UIAxes17, 'Z')
            app.UIAxes18 = uiaxes(app.WiebeGraficeTab);
            app.UIAxes19 = uiaxes(app.WiebeGraficeTab);
            app.UIAxes20 = uiaxes(app.WiebeGraficeTab);
            app.ErikssonMASanaliticTab = uitab(app.TabGroup3);
            app.ErikssonMASanaliticTab.Title = 'Eriksson (MAS analitic)';
            app.UIAxes12 = uiaxes(app.ErikssonMASanaliticTab);
            zlabel(app.UIAxes12, 'Z')
            app.DatedeintrarePanel_2 = uipanel(app.ErikssonMASanaliticTab);
            app.DatedeintrarePanel_2.Title = 'Date de intrare';
            app.EditField_94 = uieditfield(app.DatedeintrarePanel_2, 'numeric');
            app.EditField_94.Value = 29.5;
            app.dRACLabel = uilabel(app.DatedeintrarePanel_2);
            app.dRACLabel.Tooltip = {'Unghiul de dezvoltare al flacarii'};
            app.dRACLabel.Text = 'ΔѲd  [°RAC]';
            app.EditField_95 = uieditfield(app.DatedeintrarePanel_2, 'numeric');
            app.EditField_95.Value = 20.1;
            app.bRACLabel = uilabel(app.DatedeintrarePanel_2);
            app.bRACLabel.Tooltip = {'Durata arderii pentru procesul de ardere rapida'};
            app.bRACLabel.Text = 'ΔѲb  [°RAC]';
            app.oRACLabel = uilabel(app.DatedeintrarePanel_2);
            app.oRACLabel.Tooltip = {'Unghiul  in grade rac la care incepe arderea'};
            app.oRACLabel.Text = 'Ѳo [°RAC]';
            app.EditField_96 = uieditfield(app.DatedeintrarePanel_2, 'numeric');
            app.EditField_96.Value = 340;
            app.ParametriidereglajPanel = uipanel(app.ErikssonMASanaliticTab);
            app.ParametriidereglajPanel.Title = 'Parametrii de reglaj';
            app.CpJkgKLabel = uilabel(app.ParametriidereglajPanel);
            app.CpJkgKLabel.Tooltip = {'Căldura specifică la presiune constanta'};
            app.CpJkgKLabel.Text = 'Cp [J/kgK]';
            app.EditField_99 = uieditfield(app.ParametriidereglajPanel, 'numeric');
            app.EditField_99.Value = 1.38;
            app.CoeficientpolitropiccomprimareLabel = uilabel(app.ParametriidereglajPanel);
            app.CoeficientpolitropiccomprimareLabel.Tooltip = {'Coeficientul poltropic pentru comprimare'};
            app.CoeficientpolitropiccomprimareLabel.Text = 'Coeficient politropic comprimare [-]';
            app.EditField_100 = uieditfield(app.ParametriidereglajPanel, 'numeric');
            app.EditField_100.Value = 1.28;
            app.CoeficientpolitropicdestindereLabel = uilabel(app.ParametriidereglajPanel);
            app.CoeficientpolitropicdestindereLabel.Tooltip = {'Coeficientul poltropic pentru destindere'};
            app.CoeficientpolitropicdestindereLabel.Text = 'Coeficient politropic destindere [-]';
            app.EditField_102 = uieditfield(app.ParametriidereglajPanel, 'numeric');
            app.TemperaturagazelorrezidualeKLabel = uilabel(app.ParametriidereglajPanel);
            app.TemperaturagazelorrezidualeKLabel.Tooltip = {'Temperatura gazelor reziduale'};
            app.TemperaturagazelorrezidualeKLabel.Text = 'Temperatura gazelor reziduale [K]';
            app.EditField_103 = uieditfield(app.ParametriidereglajPanel, 'numeric');
            app.FractiuneagazelorrezidualeLabel = uilabel(app.ParametriidereglajPanel);
            app.FractiuneagazelorrezidualeLabel.Tooltip = {'Fractiunea gazelor reziduale'};
            app.FractiuneagazelorrezidualeLabel.Text = 'Fractiunea gazelor reziduale [-]';
            app.EditField_98 = uieditfield(app.ParametriidereglajPanel, 'numeric');
            app.EditField_98.Value = 1060;
            app.EditField_111 = uieditfield(app.ParametriidereglajPanel, 'numeric');
            app.MFB50optimRACdupPMSLabel = uilabel(app.ParametriidereglajPanel);
            app.MFB50optimRACdupPMSLabel.Tooltip = {'Unghiul optim corespunzator valorii de 50% masă de amestec arsă'};
            app.MFB50optimRACdupPMSLabel.Text = 'MFB50 optim [°RAC] după PMS';
            app.CalculmodelanaliticButton = uibutton(app.ErikssonMASanaliticTab, 'push');
            app.CalculmodelanaliticButton.ButtonPushedFcn = createCallbackFcn(app, @CalculmodelanaliticButtonPushed, true);
            app.CalculmodelanaliticButton.Text = 'Calcul model analitic';
            app.ExportdateExcelButton_2 = uibutton(app.ErikssonMASanaliticTab, 'push');
            app.ExportdateExcelButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportdateExcelButton_2Pushed, true);
            app.ExportdateExcelButton_2.Text = 'Export date Excel';
            app.ExpuneregraficaPanel = uipanel(app.ErikssonMASanaliticTab);
            app.ExpuneregraficaPanel.Title = 'Expunere grafica';
            app.PresiuneamasurataCheckBox = uicheckbox(app.ExpuneregraficaPanel);
            app.PresiuneamasurataCheckBox.ValueChangedFcn = createCallbackFcn(app, @PresiuneamasurataCheckBoxValueChanged, true);
            app.PresiuneamasurataCheckBox.Tooltip = {'Pentru functionarea programului cu aceasta optiune selectata este nevoie ca inainte de apasarea butonului Calcul model analitic sa importati si filtrati curba de presiune masurata'};
            app.PresiuneamasurataCheckBox.Text = 'Presiunea masurata';
            app.PresiuneamasurataCheckBox.Value = true;
            app.CompressionasymptoteCheckBox = uicheckbox(app.ExpuneregraficaPanel);
            app.CompressionasymptoteCheckBox.ValueChangedFcn = createCallbackFcn(app, @PresiuneamasurataCheckBoxValueChanged, true);
            app.CompressionasymptoteCheckBox.Text = 'Compression asymptote';
            app.ExpansionasymptoteCheckBox = uicheckbox(app.ExpuneregraficaPanel);
            app.ExpansionasymptoteCheckBox.ValueChangedFcn = createCallbackFcn(app, @PresiuneamasurataCheckBoxValueChanged, true);
            app.ExpansionasymptoteCheckBox.Text = 'Expansion asymptote';
            app.TemperaturaCheckBox = uicheckbox(app.ExpuneregraficaPanel);
            app.TemperaturaCheckBox.ValueChangedFcn = createCallbackFcn(app, @PresiuneamasurataCheckBoxValueChanged, true);
            app.TemperaturaCheckBox.Text = 'Temperatura';
            app.PresiuneacalculataCheckBox = uicheckbox(app.ExpuneregraficaPanel);
            app.PresiuneacalculataCheckBox.ValueChangedFcn = createCallbackFcn(app, @PresiuneamasurataCheckBoxValueChanged, true);
            app.PresiuneacalculataCheckBox.Text = 'Presiunea calculata';
            app.PresiuneacalculataCheckBox.Value = true;
            app.UtilitarParametriiinjectieiTab = uitab(app.TabGroup);
            app.UtilitarParametriiinjectieiTab.Title = 'Utilitar - Parametrii injectiei';
            app.UIAxes9 = uiaxes(app.UtilitarParametriiinjectieiTab);
            zlabel(app.UIAxes9, 'Z')
            app.DatedeintrarePanel = uipanel(app.UtilitarParametriiinjectieiTab);
            app.DatedeintrarePanel.Title = 'Date de intrare';
            app.EditField_47 = uieditfield(app.DatedeintrarePanel, 'numeric');
            app.EditField_47.Value = 4000;
            app.EditField_48 = uieditfield(app.DatedeintrarePanel, 'numeric');
            app.EditField_48.Value = 826;
            app.EditField_49 = uieditfield(app.DatedeintrarePanel, 'numeric');
            app.EditField_49.Value = 8;
            app.EditField_50 = uieditfield(app.DatedeintrarePanel, 'numeric');
            app.EditField_50.Value = 0.12;
            app.EditField_51 = uieditfield(app.DatedeintrarePanel, 'numeric');
            app.EditField_51.Value = 0.9;
            app.EditField_52 = uieditfield(app.DatedeintrarePanel, 'numeric');
            app.EditField_52.Value = 700;
            app.EditField_53 = uieditfield(app.DatedeintrarePanel, 'numeric');
            app.EditField_53.Value = 0.001;
            app.EditField_54 = uieditfield(app.DatedeintrarePanel, 'numeric');
            app.EditField_54.Value = 1;
            app.EditField_55 = uieditfield(app.DatedeintrarePanel, 'numeric');
            app.EditField_55.Value = 50;
            app.TuratiarotminLabel = uilabel(app.DatedeintrarePanel);
            app.TuratiarotminLabel.Text = 'Turatia [rot/min]';
            app.Densitateacombustibiluluikgm3Label = uilabel(app.DatedeintrarePanel);
            app.Densitateacombustibiluluikgm3Label.Text = 'Densitatea combustibilului [kg/m^3]';
            app.NumaruldeorificiialeinjectoruluiLabel = uilabel(app.DatedeintrarePanel);
            app.NumaruldeorificiialeinjectoruluiLabel.Text = 'Numarul de orificii ale injectorului [-]';
            app.DiametrulunuiorificiummLabel = uilabel(app.DatedeintrarePanel);
            app.DiametrulunuiorificiummLabel.Text = 'Diametrul unui orificiu [mm]';
            app.CoeficientdedebitLabel = uilabel(app.DatedeintrarePanel);
            app.CoeficientdedebitLabel.Text = 'Coeficient de debit [-]';
            app.PresuneadeinjecitebarLabel = uilabel(app.DatedeintrarePanel);
            app.PresuneadeinjecitebarLabel.Text = 'Presunea de injecite [bar]';
            app.PasulRACLabel_2 = uilabel(app.DatedeintrarePanel);
            app.PasulRACLabel_2.Text = 'Pasul [°RAC]';
            app.Parametruldecorectat0viteza1durataLabel = uilabel(app.DatedeintrarePanel);
            app.Parametruldecorectat0viteza1durataLabel.Text = 'Parametrul de corectat (0-viteza,1-durata)';
            app.PresiuneadincilindrubarLabel = uilabel(app.DatedeintrarePanel);
            app.PresiuneadincilindrubarLabel.Text = 'Presiunea din cilindru [bar]';
            app.InjectiaprincipalaPanel_3 = uipanel(app.UtilitarParametriiinjectieiTab);
            app.InjectiaprincipalaPanel_3.Title = 'Injectia principala';
            app.EditField_56 = uieditfield(app.InjectiaprincipalaPanel_3, 'numeric');
            app.EditField_56.Value = 20;
            app.MasadecombustibilinjectatamgLabel = uilabel(app.InjectiaprincipalaPanel_3);
            app.MasadecombustibilinjectatamgLabel.Text = 'Masa de combustibil injectata [mg]';
            app.InceputulprocesuluiRAC0PMSLabel = uilabel(app.InjectiaprincipalaPanel_3);
            app.InceputulprocesuluiRAC0PMSLabel.Text = 'Inceputul procesului [°RAC] 0=PMS';
            app.DuratatotalaRACLabel = uilabel(app.InjectiaprincipalaPanel_3);
            app.DuratatotalaRACLabel.Text = 'Durata totala [°RAC]';
            app.DuratarampeiRACLabel = uilabel(app.InjectiaprincipalaPanel_3);
            app.DuratarampeiRACLabel.Text = 'Durata rampei [°RAC]';
            app.DuratapanteiRACLabel = uilabel(app.InjectiaprincipalaPanel_3);
            app.DuratapanteiRACLabel.Text = 'Durata pantei [°RAC]';
            app.EditField_61 = uieditfield(app.InjectiaprincipalaPanel_3, 'numeric');
            app.EditField_61.Value = -20;
            app.EditField_62 = uieditfield(app.InjectiaprincipalaPanel_3, 'numeric');
            app.EditField_62.Value = 10;
            app.EditField_63 = uieditfield(app.InjectiaprincipalaPanel_3, 'numeric');
            app.EditField_63.Value = 2;
            app.EditField_64 = uieditfield(app.InjectiaprincipalaPanel_3, 'numeric');
            app.EditField_64.Value = 3;
            app.Preinjectie1Panel_2 = uipanel(app.UtilitarParametriiinjectieiTab);
            app.Preinjectie1Panel_2.Title = 'Pre-injectie 1';
            app.EditField_65 = uieditfield(app.Preinjectie1Panel_2, 'numeric');
            app.EditField_66 = uieditfield(app.Preinjectie1Panel_2, 'numeric');
            app.EditField_67 = uieditfield(app.Preinjectie1Panel_2, 'numeric');
            app.MasadecombustibilinjectatamgLabel_2 = uilabel(app.Preinjectie1Panel_2);
            app.MasadecombustibilinjectatamgLabel_2.Text = 'Masa de combustibil injectata [mg]';
            app.InceputulprocesuluiRAC0PMSLabel_2 = uilabel(app.Preinjectie1Panel_2);
            app.InceputulprocesuluiRAC0PMSLabel_2.Text = 'Inceputul procesului [°RAC] (0 = PMS)';
            app.DuratatotalaRACLabel_2 = uilabel(app.Preinjectie1Panel_2);
            app.DuratatotalaRACLabel_2.Text = 'Durata totala [°RAC]';
            app.Preinjectie2Panel_2 = uipanel(app.UtilitarParametriiinjectieiTab);
            app.Preinjectie2Panel_2.Title = 'Pre-injectie 2';
            app.EditField_68 = uieditfield(app.Preinjectie2Panel_2, 'numeric');
            app.EditField_69 = uieditfield(app.Preinjectie2Panel_2, 'numeric');
            app.EditField_70 = uieditfield(app.Preinjectie2Panel_2, 'numeric');
            app.MasadecombustibilinjectatamgLabel_3 = uilabel(app.Preinjectie2Panel_2);
            app.MasadecombustibilinjectatamgLabel_3.Text = 'Masa de combustibil injectata [mg]';
            app.InceputulprocesuluiRAC0PMSLabel_3 = uilabel(app.Preinjectie2Panel_2);
            app.InceputulprocesuluiRAC0PMSLabel_3.Text = 'Inceputul procesului [°RAC] (0 = PMS)';
            app.DuratatotalaRACLabel_3 = uilabel(app.Preinjectie2Panel_2);
            app.DuratatotalaRACLabel_3.Text = 'Durata totala [°RAC]';
            app.Postinjectie1Panel = uipanel(app.UtilitarParametriiinjectieiTab);
            app.Postinjectie1Panel.Title = 'Post-injectie 1';
            app.EditField_71 = uieditfield(app.Postinjectie1Panel, 'numeric');
            app.EditField_72 = uieditfield(app.Postinjectie1Panel, 'numeric');
            app.EditField_73 = uieditfield(app.Postinjectie1Panel, 'numeric');
            app.MasadecombustibilinjectatamgLabel_4 = uilabel(app.Postinjectie1Panel);
            app.MasadecombustibilinjectatamgLabel_4.Text = 'Masa de combustibil injectata [mg]';
            app.InceputulprocesuluiRAC0PMSLabel_4 = uilabel(app.Postinjectie1Panel);
            app.InceputulprocesuluiRAC0PMSLabel_4.Text = 'Inceputul procesului [°RAC] (0 = PMS)';
            app.DuratatotalaRACLabel_4 = uilabel(app.Postinjectie1Panel);
            app.DuratatotalaRACLabel_4.Text = 'Durata totala [°RAC]';
            app.Postinjectie2Panel_3 = uipanel(app.UtilitarParametriiinjectieiTab);
            app.Postinjectie2Panel_3.Title = 'Post-injectie 2';
            app.EditField_74 = uieditfield(app.Postinjectie2Panel_3, 'numeric');
            app.EditField_75 = uieditfield(app.Postinjectie2Panel_3, 'numeric');
            app.EditField_76 = uieditfield(app.Postinjectie2Panel_3, 'numeric');
            app.MasadecombustibilinjectatamgLabel_5 = uilabel(app.Postinjectie2Panel_3);
            app.MasadecombustibilinjectatamgLabel_5.Text = 'Masa de combustibil injectata [mg]';
            app.InceputulprocesuluiRAC0PMSLabel_5 = uilabel(app.Postinjectie2Panel_3);
            app.InceputulprocesuluiRAC0PMSLabel_5.Text = 'Inceputul procesului [°RAC] (0 = PMS)';
            app.DuratatotalaRACLabel_5 = uilabel(app.Postinjectie2Panel_3);
            app.DuratatotalaRACLabel_5.Text = 'Durata totala [°RAC]';
            app.CalculparametriiinjectieiButton = uibutton(app.UtilitarParametriiinjectieiTab, 'push');
            app.CalculparametriiinjectieiButton.ButtonPushedFcn = createCallbackFcn(app, @CalculparametriiinjectieiButtonPushed, true);
            app.CalculparametriiinjectieiButton.Text = 'Calcul parametrii injectiei';
            app.AfisarerezultateButton = uibutton(app.UtilitarParametriiinjectieiTab, 'push');
            app.AfisarerezultateButton.ButtonPushedFcn = createCallbackFcn(app, @AfisarerezultateButtonPushed, true);
            app.AfisarerezultateButton.Text = 'Afisare rezultate';
            % Show the figure after all components are created
            app.CombustionDARTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Licenta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CombustionDARTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CombustionDARTUIFigure)
        end
    end
end